classdef DBSCANFixer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DBSCANChecker       matlab.ui.Figure
        File                matlab.ui.container.Menu
        OpenMenu            matlab.ui.container.Menu
        SaveMenu            matlab.ui.container.Menu
        GridLayout          matlab.ui.container.GridLayout
        UIAxes1             matlab.ui.control.UIAxes
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes3             matlab.ui.control.UIAxes
        UIAxes4             matlab.ui.control.UIAxes
        AddROIButton        matlab.ui.control.Button
        SaveROIsButton      matlab.ui.control.Button
        NextClusterButton   matlab.ui.control.Button
        CurrentClusterInfo  matlab.ui.control.Label
        ClusterCountInfo    matlab.ui.control.Label
        ClearROIsButton     matlab.ui.control.Button
        UIAxes5             matlab.ui.control.UIAxes
        UIAxes6             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        sessions_all % Contains all of the session information for monkeys
        FinalUnitsFound % Contains all of the unit information that may be looked up
        
        channel % Which channel are we looking at
        sessionsInvolved % Range of sessions included for this
        monkey % monkey ID (3 characters)
        sessionChosen % Which session was specifically chosen
        DBSCANfiles % List all of the files that will be opened
        numberSessions % number of sessions
        durations % Session durations for each
        
        wavFiles; % Direct location of all of the nwav files
        
        
        % Drawing stuff
        timePoints % Has allt he poitns for msec timing
        scatter_hdl % Has the handles for scatter plots
        baseWaveform % Has the waveform handle for the base.
        chosenPoints % Which points are oging to be displayed
        chosenAxis % Deal with the current chosen axis for drawing stuff...
        numberROI % How many current ROIs
        colorOptions % Which colors will be chosen
        excludingPoints % Remove the points that do not need
        
        waveforms % All the waveforms for this set
        spikeTimes % All the spike times that occurred at the times found in original time
        correctedSpikeTimes % Corrected time with durations factored in
        sessionID % Same size as spikeTImes, shows which session the spike times belong to
        numPoints % Number of poitns for the current cluster
        
        % Loaded file information
        oldISI
        oldSNR
        numClusters
        pcaForms % All the waveforms in PCA space
        shownPoints % Logical showing which ones are being chosen
        
        % ROI creation and the like
        roiMade % Has each ROI and the relevant data for it...
        currentCluster; % which cluster that we're currently working on
        currentCorrectedSpikes % All the spike times and the corrected time that they are occurring
        
        location % Has all information on where things are ot be found
    end
    
    methods (Access = private)
        % Update all the axes with the cluster found in DBSCAN.
        function updateAxes(app)
            % Go through and put all of the points.
            gPoints = cell2mat(app.sessionID(:,app.currentCluster));
            % Figure out which poitns will be shown
            app.chosenPoints = false(app.numPoints,1);
            if app.numPoints > 10000
                app.chosenPoints(randsample(app.numPoints,10000,false)) = true;
            else
                app.chosenPoints = true(app.numPoints,1);
            end
            
            % Set up the excluding points too...
            app.excludingPoints = false(app.numPoints,1);
            for whichOne = 1:app.numberROI(app.currentCluster)
                % Find the poitns that were kept
                if ~app.roiMade{app.currentCluster}(whichOne).Keep
                    app.excludingPoints = app.excludingPoints | app.roiMade{app.currentCluster}(whichOne).WaveformPoints;
                end
            end
            excludedID = app.excludingPoints(app.chosenPoints)+1;
            sessionsID = gPoints(app.chosenPoints);
            
            % Now set up the colors and markerchosen for this..
            colorTypes = lines(app.numberSessions);
            colorTypes = colorTypes(app.numberSessions:-1:1,:);
            markerTypes = ['.';'x'];
            
            % Now have to figure out which ones actually happen...
            whichHappen = zeros(length(unique(excludedID)),length(unique(sessionsID)));
            finalColors = cell(size(whichHappen));
            finalMarkers = cell(size(whichHappen));
            for i = 1:size(whichHappen,1)
                for j = 1:size(whichHappen,2)
                    whichHappen(i,j) = sum(excludedID == i & sessionsID == j);
                    if whichHappen(i,j) > 0
                        finalColors{i,j} = colorTypes(j,:);
                        finalMarkers{i,j} = i;
                    end
                end
            end
            % Those at 0 do not happen and will mess up the printing of
            % it..
            colorChosen = cell2mat(finalColors(:));
            markerChosen = markerTypes(cell2mat(finalMarkers(:)));
            
            % Clear all the axes...
            cla(app.UIAxes1);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            cla(app.UIAxes6);
            
            % Now put all the poitns for each one...
            app.scatter_hdl = {};
            %app.scatter_hdl{1} = gscatter(app.UIAxes1,app.pcaForms{app.currentCluster}(app.chosenPoints,1),app.pcaForms{app.currentCluster}(app.chosenPoints,2),gPoints(app.chosenPoints),[],[],[],'off');
            %app.scatter_hdl{2} = gscatter(app.UIAxes2,app.pcaForms{app.currentCluster}(app.chosenPoints,1),app.pcaForms{app.currentCluster}(app.chosenPoints,3),gPoints(app.chosenPoints),[],[],[],'off');
            %app.scatter_hdl{3} = gscatter(app.UIAxes3,app.currentCorrectedSpikes(app.chosenPoints),app.pcaForms{app.currentCluster}(app.chosenPoints,1),gPoints(app.chosenPoints),[],[],[],'off');
            %app.scatter_hdl{4} = gscatter(app.UIAxes5,app.pcaForms{app.currentCluster}(app.chosenPoints,2),app.pcaForms{app.currentCluster}(app.chosenPoints,3),gPoints(app.chosenPoints),[],[],[],'off');
            %app.scatter_hdl{5} = gscatter(app.UIAxes6,app.currentCorrectedSpikes(app.chosenPoints),app.pcaForms{app.currentCluster}(app.chosenPoints,2),gPoints(app.chosenPoints),[],[],[],'off');
            
            app.scatter_hdl{1} = gscatter(app.UIAxes1,app.pcaForms{app.currentCluster}(app.chosenPoints,1),app.pcaForms{app.currentCluster}(app.chosenPoints,2),...
                {sessionsID,excludedID},colorChosen,markerChosen,[],'on');
            app.scatter_hdl{2} = gscatter(app.UIAxes2,app.pcaForms{app.currentCluster}(app.chosenPoints,1),app.pcaForms{app.currentCluster}(app.chosenPoints,3),...
                {sessionsID,excludedID},colorChosen,markerChosen,[],'off');
            app.scatter_hdl{3} = gscatter(app.UIAxes3,app.currentCorrectedSpikes(app.chosenPoints),app.pcaForms{app.currentCluster}(app.chosenPoints,1),...
                {sessionsID,excludedID},colorChosen,markerChosen,[],'off');
            app.scatter_hdl{4} = gscatter(app.UIAxes5,app.pcaForms{app.currentCluster}(app.chosenPoints,2),app.pcaForms{app.currentCluster}(app.chosenPoints,3),...
                {sessionsID,excludedID},colorChosen,markerChosen,[],'off');
            app.scatter_hdl{5} = gscatter(app.UIAxes6,app.currentCorrectedSpikes(app.chosenPoints),app.pcaForms{app.currentCluster}(app.chosenPoints,2),...
                {sessionsID,excludedID},colorChosen,markerChosen,[],'off');
            
            % Make the time thing set to max to make it easier to see...
            app.UIAxes3.XLim = [0,app.currentCorrectedSpikes(end)];
            app.UIAxes6.XLim = [0,app.currentCorrectedSpikes(end)];
            % Display the waveform of the first one in blakc
            toPlotPoints = createMeanWaveform(app,app.waveforms{app.currentCluster}(app.chosenPoints,:));
            app.baseWaveform = [];
            hold(app.UIAxes4,'on');
            app.baseWaveform(1) = plot(app.UIAxes4,app.timePoints,toPlotPoints(:,1),'Color','k','LineStyle','-','LineWidth',3);
            app.baseWaveform(2) = plot(app.UIAxes4,app.timePoints,toPlotPoints(:,1)+toPlotPoints(:,2),'Color','k','LineStyle','--','LineWidth',2);
            app.baseWaveform(3) = plot(app.UIAxes4,app.timePoints,toPlotPoints(:,1)-toPlotPoints(:,2),'Color','k','LineStyle','--','LineWidth',2);
            
            % Do the ROI ones now
            for whichOne = 1:app.numberROI(app.currentCluster)
                app.roiMade{app.currentCluster}(whichOne).ROI = [];
                app.roiMade{app.currentCluster}(whichOne).WaveformHandle = [];
                % If the ROI is empty, recreate it...
                if 1%isempty(app.roiMade{app.currentCluster}(whichOne).ROI)
                    %'ROI',[],'UIAxis',[],'WaveformHandle',[],'Points',[],'ISI',[],'SNR
                    app.roiMade{app.currentCluster}(whichOne).ROI = images.roi.Polygon(...
                        app.roiMade{app.currentCluster}(whichOne).UIAxis,...
                        'Color',app.colorOptions(whichOne,:),...
                        'Position',app.roiMade{app.currentCluster}(whichOne).Position,...
                        'UserData',whichOne);
                    % Add the listeners
                    addlistener(app.roiMade{app.currentCluster}(whichOne).ROI,'DeletingROI',@(src,evt) deleteROIHandler(app,src,evt));
                    addlistener(app.roiMade{app.currentCluster}(whichOne).ROI,'ROIMoved',@(src,evt) ROImovedHandler(app,src,evt));
                    if app.roiMade{app.currentCluster}(whichOne).Keep
                        textmenu = 'Keeping points';
                    else
                        textmenu = 'Removing points';
                    end
                    mu = uimenu(app.roiMade{app.currentCluster}(whichOne).ROI.ContextMenu);
                    mu.Text = textmenu;
                    mu.Tag = sprintf('ROIKeepSwitch%i_%i',whichOne,app.currentCluster);
                    mu.MenuSelectedFcn = @(~,~)flipKeeping(app,whichOne);
                end
                updateROILabel(app,whichOne);
                drawROIWaveform(app,whichOne);
            end
            
            % Update the label to give the old SNR and ISI
            app.CurrentClusterInfo.Text = sprintf('ISI: %0.3f, SNR: %0.3f',app.oldISI(app.currentCluster),app.oldSNR(app.currentCluster));
            app.ClusterCountInfo.Text = sprintf('Current: %i/%i, total ROIs everywhere: %i',app.currentCluster,app.numClusters,sum(app.numberROI));
            
            
        end
    
        % Update UIAxes4 to show the ROIs
        function drawROIWaveform(app,chosenROI)
            % Delete all of the ROI waveforms first...
            delete(app.roiMade{app.currentCluster}(chosenROI).WaveformHandle);
            % Go through this one and plot it in the fourth one...
            toPlotPoints = createMeanWaveform(app,app.waveforms{app.currentCluster}(app.roiMade{app.currentCluster}(chosenROI).WaveformPoints,:));
            app.roiMade{app.currentCluster}(chosenROI).WaveformHandle = [];
            if app.roiMade{app.currentCluster}(chosenROI).Keep
                hold(app.UIAxes4,'on');
                lineColor = app.roiMade{app.currentCluster}(chosenROI).ROI.Color;
                app.roiMade{app.currentCluster}(chosenROI).WaveformHandle(1) = plot(app.UIAxes4,app.timePoints,toPlotPoints(:,1),'Color',lineColor,'LineStyle','-','LineWidth',3);
                app.roiMade{app.currentCluster}(chosenROI).WaveformHandle(2) = plot(app.UIAxes4,app.timePoints,toPlotPoints(:,1)+toPlotPoints(:,2),'Color',lineColor,'LineStyle','--','LineWidth',2);
                app.roiMade{app.currentCluster}(chosenROI).WaveformHandle(3) = plot(app.UIAxes4,app.timePoints,toPlotPoints(:,1)-toPlotPoints(:,2),'Color',lineColor,'LineStyle','--','LineWidth',2);  
            end
        end
        % Takes in the given points and turns it into a waveform
        function results = createMeanWaveform(app,points)
            results = zeros(size(points,2),2);
            results(:,1) = mean(points,1);
            results(:,2) = normCI(app,points,0.05);
        end
        % Get the confidence interval
        function results = normCI(app,x,a)
            results = tinv(1-a/2,length(x)-1)*std(x)/sqrt(length(x));
        end
        
        function updateROILabel(app,chosenROI)
            % Figure out which points to choose...
            switch app.roiMade{app.currentCluster}(chosenROI).AxisNum
                case 1
                    x = app.pcaForms{app.currentCluster}(:,1);
                    y = app.pcaForms{app.currentCluster}(:,2);
                case 2
                    x = app.pcaForms{app.currentCluster}(:,1);
                    y = app.pcaForms{app.currentCluster}(:,3);
                case 3
                    x = app.currentCorrectedSpikes;
                    y = app.pcaForms{app.currentCluster}(:,1);
                case 4
                    x = app.pcaForms{app.currentCluster}(:,2);
                    y = app.pcaForms{app.currentCluster}(:,3);
                case 5
                    x = app.currentCorrectedSpikes;
                    y = app.pcaForms{app.currentCluster}(:,2);
            end
            % Figure out what points are there
            whichIn = inROI(app.roiMade{app.currentCluster}(chosenROI).ROI,x,y);
            clear x y;
            % Figure out which are the excluding points...
            app.excludingPoints = false(size(whichIn));
            for whichOne = 1:app.numberROI(app.currentCluster)
                % Find the poitns that were kept
                if ~app.roiMade{app.currentCluster}(whichOne).Keep
                    app.excludingPoints = app.excludingPoints | app.roiMade{app.currentCluster}(whichOne).WaveformPoints;
                end
            end
            
            % Save the points that would be used in display..
            if app.roiMade{app.currentCluster}(chosenROI).Keep
                app.roiMade{app.currentCluster}(chosenROI).WaveformPoints = ~app.excludingPoints & whichIn;
            else
                app.roiMade{app.currentCluster}(chosenROI).WaveformPoints = whichIn;
            end
            % Then get those points to figure ISI and SNR
            app.roiMade{app.currentCluster}(chosenROI).ISI = calculateISI(app,app.currentCorrectedSpikes(app.roiMade{app.currentCluster}(chosenROI).WaveformPoints));
            
            app.roiMade{app.currentCluster}(chosenROI).SNR = calculateSNR(app,app.waveforms{app.currentCluster}(app.roiMade{app.currentCluster}(chosenROI).WaveformPoints,:));
            % Now update it...
            if app.roiMade{app.currentCluster}(chosenROI).Keep
                app.roiMade{app.currentCluster}(chosenROI).ROI.Label = sprintf('ISI: %0.3f\nSNR: %0.3f',app.roiMade{app.currentCluster}(chosenROI).ISI,app.roiMade{app.currentCluster}(chosenROI).SNR);
            else
                app.roiMade{app.currentCluster}(chosenROI).ROI.Label = 'Removed';
            end
            
        end
        
        function deleteROIHandler(app,src,evt)
            %struct('ROI',[],'UIAxis',[],'AxisNum',[],'WaveformHandle',[],'Points',[],'ISI',[],'SNR',[])},...
            % Delete the waveformHandle
            delete(app.roiMade{app.currentCluster}(src.UserData).WaveformHandle);
            % Future version also show the points that are available to be
            % accessed again..
            % Lose the current ROI if toKeep is set to false
            app.roiMade{app.currentCluster}(src.UserData) = [];
            app.numberROI(app.currentCluster) = app.numberROI(app.currentCluster)-1;
            app.ClusterCountInfo.Text = sprintf('Current: %i/%i, total ROIs everywhere: %i',app.currentCluster,app.numClusters,sum(app.numberROI));
        end
        
        function ROImovedHandler(app,src,evt)
            % Redo the label and the waveform
            app.roiMade{app.currentCluster}(src.UserData).Position = evt.CurrentPosition;
            updateROILabel(app,src.UserData);
            drawROIWaveform(app,src.UserData);
        end
        
        % This function will go through and flip the Keep function for an
        % ROI. Depending on what it is set at, if it is false it will
        % remove all points in the ROI instead of keeping them. I twill
        % also remove the drawing of it.
        function flipKeeping(app, chosenROI)
            app.roiMade{app.currentCluster}(chosenROI).Keep = ~app.roiMade{app.currentCluster}(chosenROI).Keep;
            updateAxes(app);
            % Now go through and update the label and waveform drawing
%             if app.roiMade{app.currentCluster}(chosenROI).Keep
%                 textmenu = 'Keeping points';
%             else
%                 textmenu = 'Removing points';
%             end
%             app.roiMade{app.currentCluster}(chosenROI).ROI.ContextMenu.Children(1).Text = textmenu;
%             
%             for whichOne = 1:app.numberROI(app.currentCluster)
%                 updateROILabel(app,whichOne);
%                 drawROIWaveform(app,whichOne);
%             end
            
            
        end
        
        function SNR = calculateSNR(app,waveforms)
            % The SNR is defined as the average action potential 
            % peakto-peak height (maximum–minimum; The SNR is defined as 
            % the average action potential peakto-peak height 
            % (maximum–minimum; 'APheight') divided by the STD of the 
            % background noise over 0.3ms preceding all spikes 
            % (SNR = 20×log10[APheight/STD]). 
            % We know my data is in 20000 Hz....001*.3*Fs = 6
            % Also assuming that rows are the waveforms, columns are data
            % points.
            APheight = mean(max(waveforms,[],2)-min(waveforms,[],2));
            sigma = std(waveforms(:,1:6),0,'all');
            SNR = 20*log10(APheight/sigma);
        end
        
        function ISI = calculateISI(app,timings)
            ISI = sum(diff(timings) < 0.001)/(length(timings)-1);
        end
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('UnitsFoundData_v2.mat','FinalUnitsFound');
            app.FinalUnitsFound = FinalUnitsFound;
            load('stored_sessions.mat','sessions_all');
            app.sessions_all = sessions_all;
            % Settings for storage
            app.location.spikes = '\\cube.ucsd.edu\millerlab\Vlad_Analysis\SpikeHunters\Units Extracted\SpikeTimes';
            app.location.unitinfo = '\\cube.ucsd.edu\millerlab\Vlad_Analysis\SpikeHunters\Units Extracted\UnitInfo';
            app.location.unitfigs = '\\cube.ucsd.edu\millerlab\Vlad_Analysis\SpikeHunters\Units Extracted\UnitFigures';
            app.location.DBSCAN = '\\cube.ucsd.edu\millerlab\Vlad_Analysis\SpikeHunters\DBSCAN Units';
            % Locations of files for monkeys
            app.location.A01 = '\\pfc.ucsd.edu\millerlab\Vlad Files\Abu Data';
            app.location.E01 = '\\pfc.ucsd.edu\millerlab\Vlad Files\Eevee Data';
            app.location.H01 = '\\pfc.ucsd.edu\millerlab\Vlad Files\Hermes Data';
            app.location.H02 = '\\pfc.ucsd.edu\millerlab\Vlad Files\Han Data';
            app.location.M01 = '\\pfc.ucsd.edu\millerlab\Vlad Files\Marie Data';
            % Set up the time points for waveform
            app.timePoints = linspace(-0.5,0.5,21);
            
            app.location.NewDBSCAN = '\\cube.ucsd.edu\millerlab\Vlad_Analysis\SpikeHunters\Corrected DBSCAN Units';
            % Set up potential color scheme for ROI
            app.colorOptions = lines;
            
            % Axis changes
            app.UIAxes1.Toolbar.Visible = 'off';
            app.UIAxes2.Toolbar.Visible = 'off';
            app.UIAxes3.Toolbar.Visible = 'off';
            app.UIAxes4.Toolbar.Visible = 'off';
            app.UIAxes5.Toolbar.Visible = 'off';
            app.UIAxes6.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.UIAxes1);
            disableDefaultInteractivity(app.UIAxes2);
            disableDefaultInteractivity(app.UIAxes3);
            disableDefaultInteractivity(app.UIAxes4);
            disableDefaultInteractivity(app.UIAxes5);
            disableDefaultInteractivity(app.UIAxes6);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            % Get information from user for the session of interest and
            % data to pick up.
            rng;
            
            prompt = {'Enter monkey initials:','Enter session number:','Enter channel number:'};
            dlgtitle = 'Input';
            dims = [1 35];
            
            if isempty(app.monkey)
                definput = {'E01','5','18'};
            else
                definput = {app.monkey,num2str(app.sessionChosen),num2str(app.channel)};
            end
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if isempty(answer)
                return;
            end
            % Find the session to get the range...
            locs = strcmpi({app.FinalUnitsFound.Monkey},answer{1});
            rangesOfSessions = [[app.FinalUnitsFound(locs).First];[app.FinalUnitsFound(locs).Last]];
            ch = str2double(answer{3});
            foundRange = -1;
            recording = str2double(answer{2});
            for s_idx = 1:size(rangesOfSessions,2)
                if recording >= rangesOfSessions(1,s_idx) && recording <= rangesOfSessions(2,s_idx)
                    foundRange = s_idx;
                    break;
                end
            end
            if foundRange == -1
                uiconfirm(app.DBSCANChecker,'Did not find session for monkey.','ERROR: Not found','icon','error','options',{'OK'});
                return;
            else
                locIndeces = find(locs);
                if ch <= 0 || ch > app.FinalUnitsFound(locIndeces(foundRange)).NumChannels
                    uiconfirm(app.DBSCANChecker,'Channel does not exist for monkey.','ERROR: Not found','icon','error','options',{'OK'});
                    return;
                else
                    app.channel = ch;
                    app.monkey = answer{1};
                    app.sessionChosen = recording;
                    app.sessionsInvolved = rangesOfSessions(1,foundRange):rangesOfSessions(2,foundRange);
                    app.numberSessions = length(app.sessionsInvolved);
                end
            end
            % Load up the DBSCAN unit information...
            % Get the list of files that it should be and try to load each
            % one.
            listOfFiles = fullfile(app.location.DBSCAN,arrayfun(@(x) sprintf('voc_%s_%i_%i.mat',app.monkey,x,app.channel),app.sessionsInvolved,'UniformOutput',false));
            if sum(~isfile(listOfFiles)) > 0
                uiconfirm(app.DBSCANChecker,'DBSCAN files do not exist.','ERROR: Not found','icon','error','options',{'OK'});
                return;
            else
                app.DBSCANfiles = listOfFiles;
            end
            
            % Load up that particular channel's multiple clusters with the
            % spikeTImes and the waveforms.
            % Each one channel will have 1 to x more clusters (going from
            % first to last). Will fill out the spikeTimes, waveforms,
            % correctedSpikeTimes (includes duration for each..),sessionID (1:sessions)
            
            % Get the durations...
            app.wavFiles = fullfile(app.location.(app.monkey),...
                arrayfun(@(x) sprintf('voc_%s_%in.wav',app.monkey,x),app.sessionsInvolved,'UniformOutput',false));
            app.durations = zeros(app.numberSessions,1);
            for file_idx = 1:app.numberSessions
                wavInfo = audioinfo(app.wavFiles{file_idx});
                app.durations(file_idx) = wavInfo.Duration;
            end
            
            % Go through each set of DBSCAN data..
            cumDurations = cumsum([0;app.durations]);
            load(app.DBSCANfiles{1},'waveForm_storage');
            app.numClusters = length(waveForm_storage);
            app.oldISI = zeros(app.numberSessions,app.numClusters);
            app.oldSNR = zeros(app.numberSessions,app.numClusters);
            app.waveforms = waveForm_storage;
            clear waveForm_storage;
            [~,app.pcaForms] = cellfun(@(x) pca(x),app.waveforms,'UniformOutput',false);
            
            app.spikeTimes = cell(app.numberSessions,app.numClusters);
            app.correctedSpikeTimes = cell(app.numberSessions,app.numClusters);
            app.sessionID = cell(app.numberSessions,app.numClusters);
            
            for file_idx = 1:app.numberSessions
                dbfile = load(app.DBSCANfiles{file_idx},'spike_storage','ISIvalues','SNRvalues');
                app.oldISI(file_idx,:) = dbfile.ISIvalues;
                app.oldSNR(file_idx,:) = dbfile.SNRvalues;
                app.spikeTimes(file_idx,:) = dbfile.spike_storage;
                app.correctedSpikeTimes(file_idx,:) = cellfun(@(x) cumDurations(file_idx)+x,dbfile.spike_storage,'UniformOutput',false);
                app.sessionID(file_idx,:) = cellfun(@(x) repmat(file_idx,size(x)),dbfile.spike_storage,'UniformOutput',false);
            end
            
            % Set up the current ROI as blank.
            % Make roiMade to include for each cluster and their list of
            % points here so that they're saved
            app.roiMade = repmat({...
                struct('Keep',[],'ROI',[],'Position',[],'UIAxis',[],'AxisNum',[],'WaveformHandle',[],'WaveformPoints',[],'ISI',[],'SNR',[])},...
                1,app.numClusters);
            app.numberROI = zeros(1,app.numClusters);
            app.currentCluster = 1;
            app.currentCorrectedSpikes = cell2mat(app.correctedSpikeTimes(:,app.currentCluster));
            app.numPoints = size(app.waveforms{app.currentCluster},1);
            % Draw all the data with teh current ROI stuff
            updateAxes(app);
            
            % Enable the buttons
            app.AddROIButton.Enable = true;
            app.SaveROIsButton.Enable = true;
            if app.numClusters > 1
                app.NextClusterButton.Enable = true;
            else
                app.NextClusterButton.Enable = false;
            end
            app.NextClusterButton.Enable = true;
            app.ClearROIsButton.Enable = true;
            
            % Now set up the current cluster and all the other stuff to get
            % it going...such as current index and create the ROIs that
            % will contain information.
            % ROI will have the ROI object along with the current ISI and
            % SNR for that thing. And all of the points that are included
            % for that one from waveforms (PCA).
            % Should have a drawFunction that takes the current one and
            % draws it and also layers all the current 
            
            % Create information for each cluster including number of ROIs
            % Should be a struct of some sort probably
            % Should include for each cluster, original ISI and SNR. The
            % number of ROIs created, list of ROIs, associated ISI and SNR
            % for those. Deleting ROI will remove it from the list (should
            % have a listener for that). Set a tag for the ROI as well when
            % it is created so we cna access it again. Have the ROI show
            % the current ISI and SNR to show all the time...
        end

        % Button pushed function: NextClusterButton
        function NextClusterButtonPushed(app, event)
            if app.currentCluster == app.numClusters
                app.currentCluster = 1;
            else
                app.currentCluster = app.currentCluster+1;
            end
            app.currentCorrectedSpikes = cell2mat(app.correctedSpikeTimes(:,app.currentCluster));
            app.numPoints = size(app.waveforms{app.currentCluster},1);
            
            % Draw all the data with teh current ROI stuff
            updateAxes(app);
        end

        % Button down function: DBSCANChecker
        function DBSCANCheckerButtonDown(app, event)
            
        end

        % Button pushed function: AddROIButton
        function AddROIButtonPushed(app, event)
            % When push this put a confirmation screen up to figure out
            % which axes will be pressed to deal with.
            selection = {'PC1/PC2','PC1/PC3','PC2/PC3','PC1/Time','PC2/Time'};
            [index,tf] = listdlg('ListString',selection,'PromptString','Select axis to draw ROI',"SelectionMode","single",'Name','Axis Choice','ListSize',[100,150]);
            switch selection{index}
                case 'PC1/PC2'
                    app.chosenAxis = app.UIAxes1;
                    value = 1;
                case 'PC1/PC3'
                    app.chosenAxis = app.UIAxes2;
                    value = 2;
                case 'PC1/Time'
                    app.chosenAxis = app.UIAxes3;
                    value = 3;
                case 'PC2/PC3'
                    app.chosenAxis = app.UIAxes5;
                    value = 4;
                case 'PC2/Time'
                    app.chosenAxis = app.UIAxes6;
                    value = 5;
                otherwise
                    return;
            end
            app.numberROI(app.currentCluster) = app.numberROI(app.currentCluster) + 1;
            app.roiMade{app.currentCluster}(app.numberROI(app.currentCluster)).ROI = ...
                drawpolygon(app.chosenAxis,'UserData',app.numberROI(app.currentCluster),...
                'Color',app.colorOptions(app.numberROI(app.currentCluster),:));
            % Check if ROI was cancelled...
            if isempty(app.roiMade{app.currentCluster}(app.numberROI(app.currentCluster)).ROI.Position)
                delete(app.roiMade{app.currentCluster}(app.numberROI(app.currentCluster)).ROI);
                app.roiMade{app.currentCluster}(app.numberROI(app.currentCluster)) = [];
                app.numberROI(app.currentCluster) = app.numberROI(app.currentCluster)-1;
                return;
            else
                app.roiMade{app.currentCluster}(app.numberROI(app.currentCluster)).Position = app.roiMade{app.currentCluster}(app.numberROI(app.currentCluster)).ROI.Position;
                
            end
            % If ROI wasn't cancelled then figure out which points are
            % included in there
            %'ROI',[],'UIAxis',[],'WaveformHandle',[],'Points',[],'ISI',[],'SNR',[]
            app.roiMade{app.currentCluster}(app.numberROI(app.currentCluster)).Keep = true;
            app.roiMade{app.currentCluster}(app.numberROI(app.currentCluster)).UIAxis = app.chosenAxis;
            app.roiMade{app.currentCluster}(app.numberROI(app.currentCluster)).AxisNum = value;
            % Figure out which points and ISI an dSNR...
            updateROILabel(app,app.numberROI(app.currentCluster));
            % Draw the ROI stuff...
            drawROIWaveform(app,app.numberROI(app.currentCluster));
            
            % Add listeners and handlers to deal with that stuff...
            addlistener(app.roiMade{app.currentCluster}(app.numberROI(app.currentCluster)).ROI,'DeletingROI',@(src,evt) deleteROIHandler(app,src,evt));
            addlistener(app.roiMade{app.currentCluster}(app.numberROI(app.currentCluster)).ROI,'ROIMoved',@(src,evt) ROImovedHandler(app,src,evt));
            % Add context menu to include flipping positivitiy or not in th
            % ekeep..
            if app.roiMade{app.currentCluster}(app.numberROI(app.currentCluster)).Keep
                textmenu = 'Keeping points';
            else
                textmenu = 'Removing points';
            end
            mu = uimenu(app.roiMade{app.currentCluster}(app.numberROI(app.currentCluster)).ROI.ContextMenu);
            mu.Text = textmenu;
            mu.Tag = sprintf('ROIKeepSwitch%i_%i',app.numberROI(app.currentCluster),app.currentCluster);
            mu.MenuSelectedFcn = @(~,~)flipKeeping(app,app.numberROI(app.currentCluster));
            app.ClusterCountInfo.Text = sprintf('Current: %i/%i, total ROIs everywhere: %i',app.currentCluster,app.numClusters,sum(app.numberROI));
        end

        % Button pushed function: ClearROIsButton
        function ClearROIsButtonPushed(app, event)
            % Do the ROI ones now
            for whichOne = app.numberROI(app.currentCluster):-1:1
                delete(app.roiMade{app.currentCluster}(whichOne).WaveformHandle);
                delete(app.roiMade{app.currentCluster}(whichOne).ROI);
                app.roiMade{app.currentCluster}(whichOne) = [];
            end
            app.numberROI(app.currentCluster) = 0;
            app.ClusterCountInfo.Text = sprintf('Current: %i/%i, total ROIs everywhere: %i',app.currentCluster,app.numClusters,sum(app.numberROI));
        end

        % Callback function: SaveMenu, SaveROIsButton
        function SaveROIsButtonPushed(app, event)
            % When save do this...
            % Go through each ROI and save the ROIs.
            % If there are no ROIs for that particular set, then just save
            % the data as is.
            % When saving, go through and figure out which points are for
            % which session file so you can split it easily.
            
            % First figure out how many total ROIs were made
        try 
            totalNewClusters = 0;
            for whichCluster = 1:app.numClusters
                if app.numberROI(whichCluster) == 0
                    % Will just skip this one..
                    continue;
                end
                for chosenROI = 1:app.numberROI(whichCluster)
                    if app.roiMade{whichCluster}(chosenROI).Keep                        
                        totalNewClusters = totalNewClusters + 1;
                    else
                        continue;
                    end
                end
            end
            % Then create teh structure that we need for all of them with
            % number of rows equal to number of sessions and columns equal
            % to number of ROIs. numRois will be used for the array of SNR
            % and ISI
            newSNR = zeros(app.numberSessions,totalNewClusters);
            newISI = zeros(app.numberSessions,totalNewClusters);
            newSpikeStorage = cell(app.numberSessions,totalNewClusters);
            newWaveformStorage = cell(app.numberSessions,totalNewClusters);
            
            % Then for each ROI for one session, go through and find all
            % the points as noted by that cluster's list of session values.
            % Store the times of the points and the waveforms of the points
            clust_idx = 0;
            for whichCluster = 1:app.numClusters
                if app.numberROI(whichCluster) == 0
                    % Will just skip this one..
                    continue;
                end
                sessionIDs = cell2mat(app.sessionID(:,whichCluster));
                sessionTimes = cell2mat(app.spikeTimes(:,whichCluster));
                for chosenROI = 1:app.numberROI(whichCluster)
                    if ~app.roiMade{whichCluster}(chosenROI).Keep                        
                        continue;
                    else
                        clust_idx = clust_idx+1;
                    end
                    whichWaveformPoints = app.roiMade{whichCluster}(chosenROI).WaveformPoints;
                    for whichSession = 1:app.numberSessions
                        whichPointsForThisSession = sessionIDs == whichSession & whichWaveformPoints;
                        
                        spikeTiming = sessionTimes(whichPointsForThisSession);
                        waveforms = app.waveforms{whichCluster}(whichPointsForThisSession,:);
                        % Get the wavepoints..
                        newSNR(whichSession,clust_idx) = calculateSNR(app,waveforms);
                        newISI(whichSession,clust_idx) = calculateISI(app,spikeTiming);
                        newSpikeStorage{whichSession,clust_idx} = spikeTiming;
                        newWaveformStorage{whichSession,clust_idx} = waveforms;
                    end
                end
            end
            % Now for each session, store the results in a file
            listOfFiles = fullfile(app.location.NewDBSCAN,arrayfun(@(x) sprintf('voc_%s_%i_%i.mat',app.monkey,x,app.channel),app.sessionsInvolved,'UniformOutput',false));
            for whichSession = 1:app.numberSessions
                ISIvalues = newISI(whichSession,:);
                SNRvalues = newSNR(whichSession,:);
                spike_storage = newSpikeStorage(whichSession,:);
                waveForm_storage = newWaveformStorage(whichSession,:);
                redo_byHand = false;
                save(listOfFiles{whichSession},'ISIvalues','SNRvalues','redo_byHand','spike_storage','waveForm_storage');
            end
            uiconfirm(app.DBSCANChecker,'DBSCAN Save Status.','All sessions saved.','icon','success','options',{'OK'});
        catch ME
            uiconfirm(app.DBSCANChecker,'DBSCAN Save Status.','ERROR: Problem saving.','icon','error','options',{'OK'});
            rethrow(ME)
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DBSCANChecker = uifigure('Visible', 'off');
            app.DBSCANChecker.Name = 'MATLAB App';
            app.DBSCANChecker.ButtonDownFcn = createCallbackFcn(app, @DBSCANCheckerButtonDown, true);
            app.File = uimenu(app.DBSCANChecker);
            app.File.Text = 'File';
            app.OpenMenu = uimenu(app.File);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.File);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveROIsButtonPushed, true);
            app.SaveMenu.Text = 'Save';
            app.GridLayout = uigridlayout(app.DBSCANChecker);
            app.UIAxes1 = uiaxes(app.GridLayout);
            title(app.UIAxes1, '')
            xlabel(app.UIAxes1, 'PC1')
            ylabel(app.UIAxes1, 'PC2')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'PC1')
            ylabel(app.UIAxes2, 'PC3')
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, 'Time')
            ylabel(app.UIAxes3, 'PC1')
            app.UIAxes4 = uiaxes(app.GridLayout);
            title(app.UIAxes4, 'Current Units')
            xlabel(app.UIAxes4, 'Time (msec)')
            ylabel(app.UIAxes4, 'V')
            app.AddROIButton = uibutton(app.GridLayout, 'push');
            app.AddROIButton.ButtonPushedFcn = createCallbackFcn(app, @AddROIButtonPushed, true);
            app.AddROIButton.Enable = 'off';
            app.AddROIButton.Text = 'Add ROI';
            app.SaveROIsButton = uibutton(app.GridLayout, 'push');
            app.SaveROIsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveROIsButtonPushed, true);
            app.SaveROIsButton.Enable = 'off';
            app.SaveROIsButton.Text = 'Save ROIs';
            app.NextClusterButton = uibutton(app.GridLayout, 'push');
            app.NextClusterButton.ButtonPushedFcn = createCallbackFcn(app, @NextClusterButtonPushed, true);
            app.NextClusterButton.Enable = 'off';
            app.NextClusterButton.Text = 'Next Cluster';
            app.CurrentClusterInfo = uilabel(app.GridLayout);
            app.CurrentClusterInfo.Text = '';
            app.ClusterCountInfo = uilabel(app.GridLayout);
            app.ClusterCountInfo.Text = '';
            app.ClearROIsButton = uibutton(app.GridLayout, 'push');
            app.ClearROIsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearROIsButtonPushed, true);
            app.ClearROIsButton.Enable = 'off';
            app.ClearROIsButton.Text = 'Clear ROIs';
            app.UIAxes5 = uiaxes(app.GridLayout);
            title(app.UIAxes5, '')
            xlabel(app.UIAxes5, 'PC2')
            ylabel(app.UIAxes5, 'PC3')
            app.UIAxes6 = uiaxes(app.GridLayout);
            title(app.UIAxes6, '')
            xlabel(app.UIAxes6, 'Time')
            ylabel(app.UIAxes6, 'PC2')
            % Show the figure after all components are created
            app.DBSCANChecker.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DBSCANFixer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DBSCANChecker)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DBSCANChecker)
        end
    end
end