classdef QuickAnalysisSort < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QuickAnalysisFigure          matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        ChannelProcessingPanel       matlab.ui.container.Panel
        SessionsLabel                matlab.ui.control.Label
        QualityLabel                 matlab.ui.control.Label
        SessionsItsIn                matlab.ui.control.DropDown
        UnitQuality                  matlab.ui.control.DropDown
        SaveDataButton               matlab.ui.control.Button
        NorecordingsButton           matlab.ui.control.StateButton
        SomeButton                   matlab.ui.control.StateButton
        AllrecordingsButton          matlab.ui.control.StateButton
        ThresholdSettingsPanel       matlab.ui.container.Panel
        FindSpikesButton             matlab.ui.control.Button
        ValueSetEditField            matlab.ui.control.NumericEditField
        ValueSetEditFieldLabel       matlab.ui.control.Label
        ThresholdSlider              matlab.ui.control.Slider
        ClearButton                  matlab.ui.control.Button
        DataNavigationPanel          matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        SecondsPanel                 matlab.ui.container.Panel
        MinusSecondButton            matlab.ui.control.Button
        AddSecondButton              matlab.ui.control.Button
        MinutesPanel                 matlab.ui.container.Panel
        MinusMinuteButton            matlab.ui.control.Button
        AddMinuteButton              matlab.ui.control.Button
        ChannelNavigationPanel       matlab.ui.container.Panel
        DecrementChannelButton       matlab.ui.control.Button
        IncrementChannelButton       matlab.ui.control.Button
        ClearChannelButton           matlab.ui.control.Button
        ChosenChannelDropDown        matlab.ui.control.DropDown
        ChosenChannelLabel           matlab.ui.control.Label
        ChooseFilesPanel             matlab.ui.container.Panel
        CommonModeRejectionCheckBox  matlab.ui.control.CheckBox
        ClearFilesButton             matlab.ui.control.Button
        LoadFilesButton              matlab.ui.control.Button
        FilterSettingsPanel          matlab.ui.container.Panel
        UpperCutoffHzEditField       matlab.ui.control.NumericEditField
        UpperCutoffHzEditFieldLabel  matlab.ui.control.Label
        LowerCutoffEditField         matlab.ui.control.NumericEditField
        LowerCutoffHzEditFieldLabel  matlab.ui.control.Label
        SamplingRateEditField        matlab.ui.control.NumericEditField
        FsHzLabel                    matlab.ui.control.Label
        OrderEditField               matlab.ui.control.NumericEditField
        OrderEditFieldLabel          matlab.ui.control.Label
        CenterPanel                  matlab.ui.container.Panel
        RAWPanel                     matlab.ui.container.GridLayout
        RAW5                         matlab.ui.control.UIAxes
        RAW4                         matlab.ui.control.UIAxes
        RAW3                         matlab.ui.control.UIAxes
        RAW2                         matlab.ui.control.UIAxes
        RAW1                         matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
        WavesPanel                   matlab.ui.container.GridLayout
        Waves5                       matlab.ui.control.UIAxes
        Average5                     matlab.ui.control.UIAxes
        Average1                     matlab.ui.control.UIAxes
        Average2                     matlab.ui.control.UIAxes
        Average3                     matlab.ui.control.UIAxes
        Average4                     matlab.ui.control.UIAxes
        Waves4                       matlab.ui.control.UIAxes
        Waves3                       matlab.ui.control.UIAxes
        Waves2                       matlab.ui.control.UIAxes
        Waves1                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        FilePath; % Set of files chosen
        FileName % Name of first file
        listOfFiles % All of the file names
        numFilesCombined % How many files were chosen
        
        filesChosen = false; % Did you choose something
        channelChosen = false; % Did you choose channel setting
        thresholdChosen = false; % Was there a threshold chosen
        doCommonModeRejection = true; % Whether to do common mode rejection or not
        
        whichChannel % What channel was chosen
        maxChannels = 32;
        
        minutesToStore = 5; % How many chunks of data to take out (use linspace and then floor on that +1)
        secondsForSections = 60; % How many seconds of data for each chunk
        
        whichMinute; % What minute displaying at
        whichSecond; % What second within that minute displaying at
        
        % Data storage
        DataToStore = {};
        AverageData = {};
        FilteredData = {};
        
        % Settings for the filter
        lowerCut
        higherCut
        Fs
        order
        
        % Storing the links to the UIAxis panels
        RawPanels;
        WavesPanels;
        AveragePanels;
        
        threshold % Threshold value
        TLines; % Holds all of the threshold lines plotted
        RLines; % Holds all of the raw lines plotted
        RBoxes; % Holds all of the white boxes that go as background
        WLines; % Holds all of the wave plotted
        ALines; % Holds all of the average wave plotted
        
        % Store the unit found data
        UnitsFound;
        
        KeyThreshold; % Last value used
    end
    
    methods (Access = private)
        
        function clearStatesAndPanels(app)
        % Should only have the filter and files chosen visible
            app.ChannelNavigationPanel.Visible = false;
            app.DataNavigationPanel.Visible = false;
            app.ThresholdSettingsPanel.Visible = false;
            app.ChannelProcessingPanel.Visible = false;
            app.RAWPanel.Visible = false;
            app.WavesPanel.Visible = false;
            
            app.filesChosen = false; % Did you choose something
            app.channelChosen = false; % Did you choose channel setting
            app.thresholdChosen = false; % Was there a threshold chosen
            
            app.whichChannel = 0;
            app.ChosenChannelDropDown.Value = app.ChosenChannelDropDown.Items{1};
            
            if isgraphics(app.RLines,'line')
                delete(app.RLines);
            end
        end
        
        function load_wav_channel(app)
            % This will read in a wav file and only take in chunks of it at
            % a time to read in for the particular channel_idx of interest.
            % Data_Stored will have one channel loaded up. avg samples will
            % have the average samples for that channel for common mode
            % rejection.
            
            % Get the first level of data...
            file_info = audioinfo(fullfile(app.FilePath,app.FileName));
            if app.Fs ~= file_info.SampleRate
                app.Fs = file_info.SampleRate;
                app.SamplingRateEditField.Value = app.Fs;
                uialert(app.QuickAnalysisFigure,'Sampling Rate adjusted for wav files','Fs is not appropriate','Icon','warning');
            end
            % Now figure out the max number of channels
            app.maxChannels = file_info.NumChannels;
            app.DataToStore = cell(app.minutesToStore,app.numFilesCombined);
            app.AverageData = cell(app.minutesToStore,app.numFilesCombined);
            
            % How much to store for each one
            dataChunkSize = [app.Fs*app.secondsForSections,app.maxChannels];
            
            hdlg = uiprogressdlg(app.QuickAnalysisFigure,'message','Loading the .wav files');
            hdlg.Value = 0/(app.numFilesCombined+1);
            for file_idx = 1:app.numFilesCombined
                hdlg.Value = file_idx/(app.numFilesCombined+1);
                file_name = [app.FilePath,app.listOfFiles{file_idx}];
                file_info = audioinfo(file_name);
                startingPoints = linspace(1,file_info.TotalSamples,app.minutesToStore+1);
                for i = 1:app.minutesToStore
                    chunk_start = floor(startingPoints(i));
                    chunk_end = chunk_start+dataChunkSize(1)-1;
                    [data, ~] = audioread(file_name,[chunk_start,chunk_end]);
                    app.DataToStore{i,file_idx} = data*-1;
                    app.AverageData{i,file_idx} = mean(data,2)*-1;
                end
            end
            % Now store the data combined
            hdlg.Value = 1;
            delete(hdlg);
        end
        
        function filter_stored_data(app)
            % Set up the filter
            [b,a] = butter(app.order, [((app.lowerCut/app.Fs)*2), ((app.higherCut/app.Fs)*2)]);
            % Store the data
            app.FilteredData = cell(app.minutesToStore,app.numFilesCombined);
            hdlg = uiprogressdlg(app.QuickAnalysisFigure,'message','Filtering the data','Indeterminate','on');
            for min_idx = 1:app.minutesToStore
                for file = 1:app.numFilesCombined
                    if app.doCommonModeRejection
                        data = app.DataToStore{min_idx,file}-app.AverageData{min_idx,file};
                    else
                        data = app.DataToStore{min_idx,file};
                    end
                    % Now filter the data
                    app.FilteredData{min_idx,file} = filtfilt(b,a,data);
                end
            end
            delete(hdlg);
        end
        
        function displayChannelView(app,new_channel)
        % When choosing a valid channel. Show the new one.
            app.ChosenChannelDropDown.Value = app.ChosenChannelDropDown.Items{new_channel+1};
            % SHow data navigation
            app.DataNavigationPanel.Visible = true;
            app.channelChosen = true;
            % Threshold value should change
            clear_thresholds(app);
            % Clear the settings for the unit saver. And show ti if tagged
            % preivously
            app.ChannelProcessingPanel.Visible = setButtonsInSaveData(app,new_channel);
            
            % THe data navigation position will also be kept the same?? or
            % not
            app.whichMinute = 1; % For first minute
            app.whichSecond = 0;
            app.whichChannel = new_channel;
            % Now display the current data...
            displayCurrentData(app);
            
            app.UnitsFound.Channel(app.whichChannel)
        end
        
        function displayCurrentData(app)
            if isgraphics(app.RLines)
                delete(app.RLines);
            end
            if isgraphics(app.RBoxes)
                delete(app.RBoxes);
            end
            
            app.RAWPanel.Visible = true;
            % Display the data based on time
            t_start = floor(app.whichSecond*app.Fs)+ 1;
            t_end = floor((app.whichSecond+1)*app.Fs);
            time_stamps = (t_start:t_end)/app.Fs;
            
            maxY = -Inf; minY = Inf; % TO find the mins
            
            for file = 1:app.numFilesCombined
                ylim(app.RawPanels(file),'auto'); % Rever back to auto limits
                xlim(app.RawPanels(file),[time_stamps(1),time_stamps(end)]);
                toDisplay = app.FilteredData{app.whichMinute,file}(t_start:t_end,app.whichChannel);
                app.RLines(file) = line(app.RawPanels(file),time_stamps,toDisplay,'Tag',['RAW',num2str(file)]);
                title(app.RawPanels(file),app.listOfFiles{file},'Interpreter','none');
                minY = min(minY,app.RawPanels(file).YLim(1));
                maxY = max(maxY,app.RawPanels(file).YLim(2));
            end
            
            % Set the maximum and minimum
            new_limits = [minY,maxY];
            app.ThresholdSlider.Limits = new_limits;
            tick_marks = [linspace(minY,maxY,10)];
            app.ThresholdSlider.MajorTicks = [minY,0,maxY];
            app.ThresholdSlider.MinorTicks = tick_marks(2:end-1);
            
            for file = 1:app.numFilesCombined
                app.RawPanels(file).YLim = new_limits;
                % Need to draw this filled in area so that you can click on
                % any part and still get a response!
                xval = app.RawPanels(file).XLim;
                % Draw a white rectangle
                app.RBoxes(file) = fill(app.RawPanels(file),[xval(1),xval(2),xval(2),xval(1)],[minY,minY,maxY,maxY],[1,1,1],'Tag',['RB',num2str(file)]); 
                % Put it in the back
                numChildren = numel(app.RawPanels(file).Children);
                app.RawPanels(file).Children = app.RawPanels(file).Children([2:numChildren,1]);
            end
            
            if app.thresholdChosen
                update_threshold(app,app.threshold);
            end
        end
        
        function update_threshold(app, new_value)
            if ~isempty(new_value)
                % Now update the threshold and the associated values
                app.threshold = new_value;
                app.KeyThreshold = app.threshold;
                app.ValueSetEditField.Value = app.threshold;
                app.thresholdChosen = true;
                if app.threshold < app.ThresholdSlider.Limits(1)
                    app.ThresholdSlider.Value = app.ThresholdSlider.Limits(1);
                elseif app.threshold > app.ThresholdSlider.Limits(2)
                    app.ThresholdSlider.Value = app.ThresholdSlider.Limits(2);
                else
                    app.ThresholdSlider.Value = app.threshold;
                end
            end
            clear_thresholds(app);
            if isempty(app.threshold)
                return;
            end
            
            app.ThresholdSettingsPanel.Visible = true;
            app.WavesPanel.Visible = true;
            
            % Calculate the start and end of the line
            t_start = floor(app.whichSecond*app.Fs)+ 1;
            t_end = floor((app.whichSecond+1)*app.Fs);
            time_stamps = ([t_start,t_end])/app.Fs;
            data = [app.threshold, app.threshold];
            
            for file = 1:app.numFilesCombined
                app.TLines(file) = line(app.RawPanels(file),time_stamps,data,'LineWidth',1.2,'Color','red','Tag',['T',num2str(file)]);
            end
            
            
            % Update the average waves
            %draw_spikes(app);
        end
        
        function clear_thresholds(app)
            if isgraphics(app.TLines)
                delete(app.TLines)
            end
            if iscell(app.WLines) && sum(isgraphics(app.WLines{1})>0)
                for file = 1:app.numFilesCombined
                    delete(app.WLines{file});
                end
            end
            if isgraphics(app.ALines)
                delete(app.ALines);
            end
            app.WavesPanel.Visible = false;
            app.ThresholdSettingsPanel.Visible = false;
        end
        
        function draw_spikes(app)    
        % Figure out if looking for peaks or valleys (> 0 or not)
            findValleys = app.threshold < 0;
            % Num samples neded for 1 msec
            waveletPointCount = floor(app.Fs*.001)+1; % Add 1
            waveletBeforeCount = floor(app.Fs*.001*.5);
            % Find all the points that are > than the threshold
            spikeCounts = cell(app.minutesToStore,app.numFilesCombined);
            waveletHold = cell(app.minutesToStore,app.numFilesCombined);
            for file = 1:app.numFilesCombined
                for min_idx = 1:app.minutesToStore
                    raw_data = app.FilteredData{min_idx,file}(:,app.whichChannel);
                    if findValleys
                        points = raw_data <= app.threshold;
                    else
                        points = raw_data >= app.threshold;
                    end
                    % Remove conescutive true values
                    spikeTimes = [false;diff(points)==1];
                    % Ignore the first and last parts that can't be seen
                    spikeTimes(1:waveletBeforeCount*2) = false;
                    spikeTimes(end-waveletBeforeCount*2+1:end) = false;
                    % Store the ISIs of spike times. Find index of all the
                    % times that spikeTImes is true (Saw a spike) and then
                    % get the index position. And then take the
                    % differences. And convert to seconds.
                    spikeCounts{min_idx,file} = diff(find(spikeTimes))/app.Fs;
                    % Now get all the wavelets...
                    % Get the before times and after times
                    before = find(spikeTimes)-waveletBeforeCount;
                    after = find(spikeTimes)+waveletBeforeCount;
                    indeces_needed = arrayfun(@(x,y) x:y, before,after,'UniformOutput',false);
                    
                    wavelets = raw_data(cell2mat(indeces_needed));
                    % See if need to flip
                    if size(wavelets,2) == 1
                        wavelets = wavelets';
                    end
                    % Try finding absolute min/max here to align
                    if findValleys
                        [~,loc] = min(wavelets,[],2);
                    else
                        [~,loc] = max(wavelets,[],2);
                    end
                    toAdd = loc-11; % Subtract until midpoint to know how much farther to add                   
                    wavelets = raw_data(cell2mat(indeces_needed)+toAdd);
                    %wavelets = raw_data(cell2mat(indeces_needed));
                    % Store those wavelets
                    if size(wavelets,2) == 1
                        waveletHold{min_idx,file} = wavelets';
                    else
                        waveletHold{min_idx,file} = wavelets;
                    end
                end
            end
            % Get the msec bin time
            xvals = ((1:waveletPointCount)-waveletBeforeCount-1)/app.Fs;
            % Go through the plotting and ISI calculation and SNR
            if iscell(app.WLines) && sum(isgraphics(app.WLines{1})>0)
                for file = 1:app.numFilesCombined
                    if isgraphics(app.WLines{file})
                        delete(app.WLines{file});
                    end
                end
            end
            if isgraphics(app.ALines)
                delete(app.ALines);
            end
            for file = 1:app.numFilesCombined
                % All wavelets
                wavelets = cell2mat(waveletHold(:,file));
                % Subsset of wavelets
                numW = size(wavelets,1);
                if numW > 100
                    wdata = wavelets(randperm(numW,100),:);
                else
                    wdata = wavelets;
                end
                if numW == 0
                    app.WLines{file} = line(app.WavesPanels(file),xvals,ones(size(xvals)));
                    app.ALines(file,1) = line(app.AveragePanels(file),xvals,ones(size(xvals)));
                    continue
                end
                app.WLines{file} = line(app.WavesPanels(file),xvals,wdata,'LineWidth',0.2,'Tag',['W',num2str(file)]);
                ISI = cell2mat(spikeCounts(:,file));
                title(app.WavesPanels(file),['< 1 msec: ',num2str(100*sum(ISI<0.001)/numel(ISI),3),'%']);
                % The average one
                w_avg = mean(wavelets,1);
                w_std = std(wavelets,0,1);
                app.ALines(file,1) = line(app.AveragePanels(file),xvals,w_avg,'LineWidth',0.5,'Tag',['A1',num2str(file)]);
                app.ALines(file,2) = line(app.AveragePanels(file),xvals,w_avg+w_std,'LineWidth',0.3,'Tag',['A2',num2str(file)]);
                app.ALines(file,3) = line(app.AveragePanels(file),xvals,w_avg-w_std,'LineWidth',0.3,'Tag',['A3',num2str(file)]);
                % SNR calc
                if findValleys
                    signal = min(w_avg);
                else
                    signal = max(w_avg);
                end
                SNR = abs(mean(w_avg(1:4))-signal)/mean(w_std(1:4));
                title(app.AveragePanels(file),['SNR: ',num2str(SNR,3)]);
            end
            % Plot 1 msec of data centered at the thresh. Bunch of lines
            % Plot the mean and one std above and below in other plot
            % Calculate the ISI violations < 1 msec ratio
            % Calculate number of std above mean of outside in middle
        end
        
        function shouldShow = setButtonsInSaveData(app,new_channel)
            % First save the data of the old one, then transfer ot new
            % channel
            if app.whichChannel > 0 % If not the first one...
                app.UnitsFound.Channel(app.whichChannel).Unit = whichUnitType(app);
                app.UnitsFound.Channel(app.whichChannel).Quality = app.UnitQuality.Value;
                app.UnitsFound.Channel(app.whichChannel).Threshold = app.threshold;
                app.UnitsFound.Channel(app.whichChannel).Occurrences = app.SessionsItsIn.Value;
            end
            
            % Is there prior data that we need to show. Can we move on from
            % previous?
            app.AllrecordingsButton.Value = false;
            app.SomeButton.Value = false;
            app.NorecordingsButton.Value = false;
            shouldShow = true;
            
            switch app.UnitsFound.Channel(new_channel).Unit
                case {'all','some'}
                    app.threshold = app.UnitsFound.Channel(new_channel).Threshold;
                    app.thresholdChosen = true;
                    app.UnitQuality.Value = app.UnitsFound.Channel(new_channel).Quality;
                    app.SessionsItsIn.Value = app.UnitsFound.Channel(new_channel).Occurrences;
                case {'none','unknown','n/a'}
                    app.threshold = [];
                    app.thresholdChosen = false;
                    app.UnitQuality.Value = 0;
                    app.SessionsItsIn.Value = '0';
            end
            
            switch app.UnitsFound.Channel(new_channel).Unit
                case 'all'
                    app.AllrecordingsButton.Value = true;
                case 'some'
                    app.SomeButton.Value = true;
                case 'none'
                    app.NorecordingsButton.Value = true;
                case {'unknown','n/a'}
                    app.AllrecordingsButton.Value = false;
                    app.SomeButton.Value = false;
                    app.NorecordingsButton.Value = false;
                    shouldShow = false;
            end
        end
        
        function result = whichUnitType(app)
            if app.AllrecordingsButton.Value
                result = 'all';
            elseif app.SomeButton.Value
                result = 'some';
            elseif app.NorecordingsButton.Value
                result = 'none';
            else
                result = 'unknown';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clearStatesAndPanels(app);
            
            app.UnitQuality.ItemsData = [0 1 2 3 4 5 6];
            % Set the filter settings
            app.lowerCut = 300;
            app.higherCut = 8000;
            app.Fs = 20000;
            app.order = 6;
            
            % Get the set of all RAW, Waves, and Averages built up
            app.RawPanels = [app.RAW1,app.RAW2,app.RAW3,app.RAW4,app.RAW5];
            app.WavesPanels = [app.Waves1,app.Waves2,app.Waves3,app.Waves4,app.Waves5];
            app.AveragePanels = [app.Average1,app.Average2,app.Average3,app.Average4,app.Average5];
            
            
            % SEt the tags
            for i = 1:5
                app.RawPanels(i).Tag = ['RP',num2str(i)];
                app.RawPanels(i).NextPlot = 'add';
                app.WavesPanels(i).Tag = ['WP',num2str(i)];
                app.WavesPanels(i).NextPlot = 'add';
                app.AveragePanels(i).Tag = ['AP',num2str(i)];
                app.AveragePanels(i).NextPlot = 'add';
            end
            for i = 1:5
                xlabel(app.RawPanels(i),'')
                ylabel(app.RawPanels(i),'');
                xlabel(app.WavesPanels(i),'')
                ylabel(app.WavesPanels(i),'');
                xlabel(app.AveragePanels(i),'')
                ylabel(app.AveragePanels(i),'');
            end
            
            Channel = struct('Unit',[],'Quality',[],'Threshold',[],'Occurrences',[]);
            app.UnitsFound = struct('Monkey',[],'Sessions',[],'First',[],'Last',[],...
                'NumChannels',[],'NumUnits',[],'NumMaintained',[],'Channel',Channel);
            for u = 1:32
                app.UnitsFound.Channel(u) =  struct('Unit','n/a','Quality',0,'Threshold',-inf,'Occurrences','0');
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.QuickAnalysisFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {764, 764, 764};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {764, 764};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 356};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: LoadFilesButton
        function LoadFilesButtonPushed(app, event)
            % Run the clear files function
            clearStatesAndPanels(app);
            
            % First ask the person to choose mulitple files
            [filename,pathname]= uigetfile('*n.wav','File Selector','MultiSelect','on');
            if (pathname ~= 0) % Did they even choose anything?
                if iscell(filename) % Multiple files chosen
                    app.FilePath = pathname;
                    app.FileName = filename{1}; % Just use the first one
                    app.listOfFiles = filename; % Locations of where they are...
                    app.numFilesCombined = numel(filename); % How many files were combined
                else % JUst one and treat it as such
                    app.FilePath = pathname;
                    app.FileName = filename;
                    app.listOfFiles = {filename}; % Locations of where they are...
                    app.numFilesCombined = 1; % How many files were combined
                end
                
                %mypathname = [app.FilePath,app.FileName];
                %app.filelist.Text = mypathname;
                app.ChannelNavigationPanel.Visible = true;
                app.filesChosen = true; % Did you choose something
                app.channelChosen = false; % Did you choose channel setting
                app.thresholdChosen = false; % Was there a threshold chosen
                
                % Load files in sections raw! Section them up as well (10
                % minutes, 1 minute bins?)
                load_wav_channel(app);
                
                % Filter and store the data
                filter_stored_data(app);
                % Do common mode rejection with filtering of data
                
                % Set up everything for storing data... Get the information
                % and then also label all the channels available as open o.
                partsFound = strsplit(app.listOfFiles{1},'_');
                app.UnitsFound.Monkey = partsFound{2};
                app.UnitsFound.Sessions = app.listOfFiles;
                app.UnitsFound.First = str2num(partsFound{3}(1:end-5));
                partsFound = strsplit(app.listOfFiles{end},'_');
                app.UnitsFound.Last = str2num(partsFound{3}(1:end-5));
                app.UnitsFound.NumChannels = app.maxChannels;
                for u = 1:app.maxChannels
                    %field = ['U',num2str(u)];
                    %app.UnitsFound.(field) = 'o';
                    app.UnitsFound.Channel(u).Unit = 'unknown';
                end
                % Save the data of the UnitsFound struct...
                % Check that fiel exists and put in data from old.
                if isfile('UnitsFoundData_v2.mat')
                    % Open the file
                    load('UnitsFoundData_v2.mat','FinalUnitsFound');
                    % Check to see if session was already labeled
                    a = strcmp({FinalUnitsFound(:).Monkey},app.UnitsFound.Monkey);
                    b = [FinalUnitsFound(:).First]==app.UnitsFound.First;
                    c = [FinalUnitsFound(:).Last]==app.UnitsFound.Last;
                    duplicate = find(a & b & c);
                    if ~isempty(duplicate)
                        app.UnitsFound = FinalUnitsFound(duplicate);
                    end
                end
            end
        end

        % Button pushed function: ClearFilesButton
        function ClearFilesButtonPushed(app, event)
            clearStatesAndPanels(app);
        end

        % Button pushed function: ClearChannelButton
        function ClearChannelButtonPushed(app, event)
            app.ChosenChannelDropDown.Value = app.ChosenChannelDropDown.Items{1};    
            app.DataNavigationPanel.Visible = false;
            app.ThresholdSettingsPanel.Visible = false;
            app.ChannelProcessingPanel.Visible = false;
            app.RAWPanel.Visible = false;
            app.WavesPanel.Visible = false;
            
            app.filesChosen = true; % Did you choose something
            app.channelChosen = false; % Did you choose channel setting
            app.thresholdChosen = false; % Was there a threshold chosen
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.WavesPanel.Visible = false;
            
            app.filesChosen = true; % Did you choose something
            app.channelChosen = true; % Did you choose channel setting
            app.thresholdChosen = false; % Was there a threshold chosen
            
            app.threshold = 0;
            clear_thresholds(app);
            
            % Go through and clear all the axes just in case something left
            % there
            for file = 1:app.numFilesCombined
                cla(app.RawPanels(file));
                cla(app.WavesPanels(file));
                cla(app.AveragePanels(file));
            end
            displayCurrentData(app)
        end

        % Value changed function: ChosenChannelDropDown
        function ChosenChannelDropDownValueChanged(app, event)
            value = app.ChosenChannelDropDown.Value;
            if value(1) == 'N'
                ClearChannelButtonPushed(app,[]);
            else
                parts = strsplit(value,' ');
                channelNum = str2num(parts{2});
                if channelNum <= app.maxChannels
                    app.channelChosen = true;
                    % Display data
                    displayChannelView(app,channelNum);
                else
                    uialert(app.QuickAnalysisFigure,'Channel does not exist','Incorrect channel','Icon','error');
                end
            end
        end

        % Button pushed function: DecrementChannelButton
        function DecrementChannelButtonPushed(app, event)
            if app.whichChannel <= 1
                uialert(app.QuickAnalysisFigure,'Cannot decrement lower','Warning','Icon','warning');
            else
                % Display data
                displayChannelView(app,app.whichChannel - 1)
            end
        end

        % Button pushed function: IncrementChannelButton
        function IncrementChannelButtonPushed(app, event)
            if app.whichChannel == app.maxChannels
                uialert(app.QuickAnalysisFigure,'Cannot increment higher','Warning','Icon','warning');
            else
                % Display data
                displayChannelView(app,app.whichChannel + 1);
            end
        end

        % Value changed function: CommonModeRejectionCheckBox
        function CommonModeRejectionCheckBoxValueChanged(app, event)
            value = app.CommonModeRejectionCheckBox.Value;
            app.doCommonModeRejection = value;
            filter_stored_data(app);
        end

        % Button pushed function: AddSecondButton
        function AddSecondButtonPushed(app, event)
            if app.whichSecond < app.secondsForSections-1
                app.whichSecond = app.whichSecond+1;
                % Now display the current data...
                displayCurrentData(app);
            else
                uialert(app.QuickAnalysisFigure,'Cannot increment higher','Warning','Icon','warning');
            end
        end

        % Button pushed function: MinusSecondButton
        function MinusSecondButtonPushed(app, event)
            if app.whichSecond > 1
                app.whichSecond = app.whichSecond-1;
                % Now display the current data...
                displayCurrentData(app);
            else
                uialert(app.QuickAnalysisFigure,'Cannot decrement lower','Warning','Icon','warning');
            end
        end

        % Button pushed function: AddMinuteButton
        function AddMinuteButtonPushed(app, event)
            if app.whichMinute < app.minutesToStore-1
                app.whichMinute = app.whichMinute+1;
                app.whichSecond = 0;
                % Now display the current data...
                displayCurrentData(app);
            else
                uialert(app.QuickAnalysisFigure,'Cannot increment higher','Warning','Icon','warning');
            end
        end

        % Button pushed function: MinusMinuteButton
        function MinusMinuteButtonPushed(app, event)
            if app.whichMinute > 1
                app.whichMinute = app.whichMinute-1;
                app.whichSecond = 0;
                % Now display the current data...
                displayCurrentData(app);
            else
                uialert(app.QuickAnalysisFigure,'Cannot decrement lower','Warning','Icon','warning');
            end
        end

        % Window button down function: QuickAnalysisFigure
        function QuickAnalysisFigureWindowButtonDown(app, event)
            % First see if something of interest was clicked
            if ~isempty(event.Source.CurrentObject)
                % Which object was touched (if it has a tag)
                tagOfObject = event.Source.CurrentObject.Tag;
                whichAxis = [];
                % See if clicked on a raw point
                if startsWith(tagOfObject,'R')
                    rawIndex = str2num(tagOfObject(end));
                    whichAxis = app.RawPanels(rawIndex);
                end
                if startsWith(tagOfObject,'T')
                    rawIndex = str2num(tagOfObject(end));
                    whichAxis = app.RawPanels(rawIndex);
                end
                if isempty(whichAxis)
                    return;
                end
                pointClicked = whichAxis.CurrentPoint; % What is that point
                % Get the value of the new threshold
                yval = pointClicked(1,2); % [xf, yf, zf; xb, yb, zb]
                app.thresholdChosen = true;
                % Update the threshold
                update_threshold(app, yval);
            end
        end

        % Value changed function: ValueSetEditField
        function ValueSetEditFieldValueChanged(app, event)
            value = app.ValueSetEditField.Value;
            update_threshold(app,value);
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            value = app.ThresholdSlider.Value;
            update_threshold(app,value);
        end

        % Value changing function: ThresholdSlider
        function ThresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ValueSetEditField.Value = changingValue;
        end

        % Button pushed function: FindSpikesButton
        function FindSpikesButtonPushed(app, event)
            if app.thresholdChosen
                %hdlg = uiprogressdlg(app.QuickAnalysisFigure,'message','Finding spikes','Indeterminate','on');
                app.FindSpikesButton.Text = 'Wait...';
                draw_spikes(app);
                app.ChannelProcessingPanel.Visible = true;
                % Set the button value
                %setButtonsInSaveData(app,app.whichChannel);
                app.FindSpikesButton.Text = 'Find Spikes';
                %close(hdlg);
            end
        end

        % Value changed function: AllrecordingsButton
        function AllrecordingsButtonValueChanged(app, event)
            value = app.AllrecordingsButton.Value;
            app.SomeButton.Value = false;
            app.NorecordingsButton.Value = false;
            if value % Saying it has something at each one
                app.UnitsFound.Channel(app.whichChannel).Unit = 'all';
                app.UnitsFound.Channel(app.whichChannel).Quality = app.UnitQuality.Value;
                app.UnitsFound.Channel(app.whichChannel).Threshold = app.threshold;
                switch app.numFilesCombined
                    case 1
                        type = '1';
                    case 2
                        type = '1-2';
                    case 3
                        type = '1-2-3';
                    case 4
                        type = '1-2-3-4';
                    case 5
                        type = '1-2-3-4-5';
                end
                app.UnitsFound.Channel(app.whichChannel).Occurrences = type;
                app.SessionsItsIn.Value = type;
            else
                app.UnitsFound.Channel(app.whichChannel).Unit = 'unknown';
                app.UnitsFound.Channel(app.whichChannel).Quality = app.UnitQuality.Value;
                app.UnitsFound.Channel(app.whichChannel).Threshold = [];
                app.UnitsFound.Channel(app.whichChannel).Occurrences = '0';
            end
        end

        % Value changed function: SomeButton
        function SomeButtonValueChanged(app, event)
            value = app.SomeButton.Value;
            app.NorecordingsButton.Value = false;
            app.AllrecordingsButton.Value = false;
            if value % Saying it has something at one of them
                app.UnitsFound.Channel(app.whichChannel).Unit = 'some';
                app.UnitsFound.Channel(app.whichChannel).Quality = app.UnitQuality.Value;
                app.UnitsFound.Channel(app.whichChannel).Threshold = app.threshold;
                app.UnitsFound.Channel(app.whichChannel).Occurrences = app.SessionsItsIn.Value;
            else
                app.UnitsFound.Channel(app.whichChannel).Unit = 'unknown';
                app.UnitsFound.Channel(app.whichChannel).Quality = app.UnitQuality.Value;
                app.UnitsFound.Channel(app.whichChannel).Threshold = [];
                app.UnitsFound.Channel(app.whichChannel).Occurrences = '0';
            end
        end

        % Value changed function: NorecordingsButton
        function NorecordingsButtonValueChanged(app, event)
            value = app.NorecordingsButton.Value;
            app.AllrecordingsButton.Value = false;;
            app.SomeButton.Value = false;
            
            if value % Saying it has something at one of them
                app.UnitsFound.Channel(app.whichChannel).Unit = 'none';
                app.UnitsFound.Channel(app.whichChannel).Quality = app.UnitQuality.Value;
                app.UnitsFound.Channel(app.whichChannel).Threshold = app.threshold;
                app.UnitsFound.Channel(app.whichChannel).Occurrences = '0';
            else
                app.UnitsFound.Channel(app.whichChannel).Unit = 'unknown';
                app.UnitsFound.Channel(app.whichChannel).Quality = app.UnitQuality.Value;
                app.UnitsFound.Channel(app.whichChannel).Threshold = [];
                app.UnitsFound.Channel(app.whichChannel).Occurrences = '0';
            end
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            % Save the data of the UnitsFound struct...
            % Check that fiel exists
            
            % Calculate the number of units found!!!!
            NumUnits = 0;
            NumMaintained = 0;
            for i = 1:app.maxChannels
                switch app.UnitsFound.Channel(i).Unit
                    case 'all'
                        NumUnits = NumUnits + 1;
                        NumMaintained = NumMaintained + 1;
                    case 'some'
                        NumUnits = NumUnits + 1;
                end
            end
            app.UnitsFound.NumUnits = NumUnits;
            app.UnitsFound.NumMaintained = NumMaintained;
            
            if isfile('UnitsFoundData_v2.mat')
                % Open the file
                load('UnitsFoundData_v2.mat','FinalUnitsFound');
                % Check to see if session was already labeled
                a = strcmp({FinalUnitsFound(:).Monkey},app.UnitsFound.Monkey);
                b = [FinalUnitsFound(:).First]==app.UnitsFound.First;
                c = [FinalUnitsFound(:).Last]==app.UnitsFound.Last;
                duplicate = find(a & b & c);
                if isempty(duplicate)
                    % Add to the end if not, replace if yes
                    FinalUnitsFound(end+1) = app.UnitsFound;
                else
                    FinalUnitsFound(duplicate) = app.UnitsFound;
                end
            else
                FinalUnitsFound = app.UnitsFound;
            end
            save('UnitsFoundData_v2.mat','FinalUnitsFound');
        end

        % Window key press function: QuickAnalysisFigure
        function QuickAnalysisFigureWindowKeyPress(app, event)
            key = event.Key;
            switch key
                case 'leftarrow' % leftkey Go previous call
                    DecrementChannelButtonPushed(app,[]);
                case 'rightarrow' % rightkey Go next call
                    IncrementChannelButtonPushed(app)
                case 'uparrow' % uparrow
                    if app.KeyThreshold < 0
                        value = app.KeyThreshold*0.9;
                    else
                        value = app.KeyThreshold*1.10;
                    end
                    update_threshold(app,value);
                case 'downarrow'
                    if app.KeyThreshold < 0
                        value = app.KeyThreshold*1.10;
                    else
                        value = app.KeyThreshold*0.9;
                    end
                    update_threshold(app,value);
                case {'0',')'}
                    app.UnitQuality.Value = 0;
                    UnitQualityValueChanged(app,[]);
                case {'1','!'} % 1'st
                    app.UnitQuality.Value = 1;
                    UnitQualityValueChanged(app,[]);
                case {'2','@'}
                    app.UnitQuality.Value = 2;
                    UnitQualityValueChanged(app,[]);
                case {'3','#'}
                    app.UnitQuality.Value = 3;
                    UnitQualityValueChanged(app,[]);
                case {'4','$'}
                    app.UnitQuality.Value = 4;
                    UnitQualityValueChanged(app,[]);
                case {'5','%'}
                    app.UnitQuality.Value = 5;
                    UnitQualityValueChanged(app,[]);
                case {'6','^'}
                    app.UnitQuality.Value = 6;
                    UnitQualityValueChanged(app,[])
                case {'w','W'}
                    app.AllrecordingsButton.Value = ~app.AllrecordingsButton.Value;
                    AllrecordingsButtonValueChanged(app, []);
                case {'e','E'}
                    app.SomeButton.Value = ~app.SomeButton.Value;
                    SomeButtonValueChanged(app, []);
                case {'r','R'}
                    app.NorecordingsButton.Value = ~app.NorecordingsButton.Value
                    NorecordingsButtonValueChanged(app, []);
                case {'s','S'}
                    SaveDataButtonPushed(app, [])
                case {'f','F'}
                    FindSpikesButtonPushed(app,[]);
                case {'c','C'}
                    ClearButtonPushed(app,[]);
            end
        end

        % Value changed function: UnitQuality
        function UnitQualityValueChanged(app, event)
            value = app.UnitQuality.Value;
            app.UnitsFound.Channel(app.whichChannel).Quality = value;
        end

        % Value changed function: SessionsItsIn
        function SessionsItsInValueChanged(app, event)
            value = app.SessionsItsIn.Value;
            app.UnitsFound.Channel(app.whichChannel).Occurrences = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QuickAnalysisFigure = uifigure('Visible', 'off');
            app.QuickAnalysisFigure.Name = 'Quick Analysis of Channels';
            app.QuickAnalysisFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.QuickAnalysisFigure.WindowButtonDownFcn = createCallbackFcn(app, @QuickAnalysisFigureWindowButtonDown, true);
            app.QuickAnalysisFigure.WindowKeyPressFcn = createCallbackFcn(app, @QuickAnalysisFigureWindowKeyPress, true);
            app.GridLayout = uigridlayout(app.QuickAnalysisFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.LeftPanel);
            app.FilterSettingsPanel = uipanel(app.GridLayout4);
            app.FilterSettingsPanel.Title = 'Filter Settings';
            app.OrderEditFieldLabel = uilabel(app.FilterSettingsPanel);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.FilterSettingsPanel, 'numeric');
            app.OrderEditField.Value = 6;
            app.FsHzLabel = uilabel(app.FilterSettingsPanel);
            app.FsHzLabel.Text = 'Fs (Hz)';
            app.SamplingRateEditField = uieditfield(app.FilterSettingsPanel, 'numeric');
            app.SamplingRateEditField.ValueDisplayFormat = '%11.4d';
            app.SamplingRateEditField.Value = 20000;
            app.LowerCutoffHzEditFieldLabel = uilabel(app.FilterSettingsPanel);
            app.LowerCutoffHzEditFieldLabel.Text = 'Lower Cut off (Hz)';
            app.LowerCutoffEditField = uieditfield(app.FilterSettingsPanel, 'numeric');
            app.LowerCutoffEditField.Value = 300;
            app.UpperCutoffHzEditFieldLabel = uilabel(app.FilterSettingsPanel);
            app.UpperCutoffHzEditFieldLabel.Text = 'Upper Cut off (Hz)';
            app.UpperCutoffHzEditField = uieditfield(app.FilterSettingsPanel, 'numeric');
            app.UpperCutoffHzEditField.Value = 8000;
            app.ChooseFilesPanel = uipanel(app.GridLayout4);
            app.ChooseFilesPanel.Title = 'Choose Files';
            app.LoadFilesButton = uibutton(app.ChooseFilesPanel, 'push');
            app.LoadFilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFilesButtonPushed, true);
            app.LoadFilesButton.Text = 'Load Files';
            app.ClearFilesButton = uibutton(app.ChooseFilesPanel, 'push');
            app.ClearFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFilesButtonPushed, true);
            app.ClearFilesButton.Text = 'Clear Files';
            app.CommonModeRejectionCheckBox = uicheckbox(app.ChooseFilesPanel);
            app.CommonModeRejectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @CommonModeRejectionCheckBoxValueChanged, true);
            app.CommonModeRejectionCheckBox.Text = 'Common Mode Rejection';
            app.CommonModeRejectionCheckBox.Value = true;
            app.ChannelNavigationPanel = uipanel(app.GridLayout4);
            app.ChannelNavigationPanel.Title = 'Channel Navigation';
            app.ChosenChannelLabel = uilabel(app.ChannelNavigationPanel);
            app.ChosenChannelLabel.Text = {'Chosen '; 'Channel'};
            app.ChosenChannelDropDown = uidropdown(app.ChannelNavigationPanel);
            app.ChosenChannelDropDown.Items = {'Nothing', 'CH 1', 'CH 2', 'CH 3', 'CH 4', 'CH 5', 'CH 6', 'CH 7', 'CH 8', 'CH 9', 'CH 10', 'CH 11', 'CH 12', 'CH 13', 'CH 14', 'CH 15', 'CH 16', 'CH 17', 'CH 18', 'CH 19', 'CH 20', 'CH 21', 'CH 22', 'CH 23', 'CH 24', 'CH 25', 'CH 26', 'CH 27', 'CH 28', 'CH 29', 'CH 30', 'CH 31', 'CH 32', '', ''};
            app.ChosenChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChosenChannelDropDownValueChanged, true);
            app.ChosenChannelDropDown.Value = 'Nothing';
            app.ClearChannelButton = uibutton(app.ChannelNavigationPanel, 'push');
            app.ClearChannelButton.ButtonPushedFcn = createCallbackFcn(app, @ClearChannelButtonPushed, true);
            app.ClearChannelButton.Text = 'Clear Channel';
            app.IncrementChannelButton = uibutton(app.ChannelNavigationPanel, 'push');
            app.IncrementChannelButton.ButtonPushedFcn = createCallbackFcn(app, @IncrementChannelButtonPushed, true);
            app.IncrementChannelButton.Tooltip = {'Right arrow key'};
            app.IncrementChannelButton.Text = 'Increment';
            app.DecrementChannelButton = uibutton(app.ChannelNavigationPanel, 'push');
            app.DecrementChannelButton.ButtonPushedFcn = createCallbackFcn(app, @DecrementChannelButtonPushed, true);
            app.DecrementChannelButton.Tooltip = {'Left arrow key'};
            app.DecrementChannelButton.Text = 'Decrement';
            app.DataNavigationPanel = uipanel(app.GridLayout4);
            app.DataNavigationPanel.Title = 'Data Navigation';
            app.GridLayout5 = uigridlayout(app.DataNavigationPanel);
            app.MinutesPanel = uipanel(app.GridLayout5);
            app.MinutesPanel.Title = 'Minutes';
            app.AddMinuteButton = uibutton(app.MinutesPanel, 'push');
            app.AddMinuteButton.ButtonPushedFcn = createCallbackFcn(app, @AddMinuteButtonPushed, true);
            app.AddMinuteButton.Text = '++';
            app.MinusMinuteButton = uibutton(app.MinutesPanel, 'push');
            app.MinusMinuteButton.ButtonPushedFcn = createCallbackFcn(app, @MinusMinuteButtonPushed, true);
            app.MinusMinuteButton.Text = '--';
            app.SecondsPanel = uipanel(app.GridLayout5);
            app.SecondsPanel.Title = 'Seconds';
            app.AddSecondButton = uibutton(app.SecondsPanel, 'push');
            app.AddSecondButton.ButtonPushedFcn = createCallbackFcn(app, @AddSecondButtonPushed, true);
            app.AddSecondButton.Text = '++';
            app.MinusSecondButton = uibutton(app.SecondsPanel, 'push');
            app.MinusSecondButton.ButtonPushedFcn = createCallbackFcn(app, @MinusSecondButtonPushed, true);
            app.MinusSecondButton.Text = '--';
            app.ThresholdSettingsPanel = uipanel(app.GridLayout4);
            app.ThresholdSettingsPanel.Title = 'Threshold Settings';
            app.ClearButton = uibutton(app.ThresholdSettingsPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ThresholdSlider = uislider(app.ThresholdSettingsPanel);
            app.ThresholdSlider.Limits = [-10 10];
            app.ThresholdSlider.MajorTicks = [-10 0 10];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdSliderValueChanging, true);
            app.ThresholdSlider.Tooltip = {'Up and down arrow key to increase'};
            app.ValueSetEditFieldLabel = uilabel(app.ThresholdSettingsPanel);
            app.ValueSetEditFieldLabel.Text = 'Value Set';
            app.ValueSetEditField = uieditfield(app.ThresholdSettingsPanel, 'numeric');
            app.ValueSetEditField.Limits = [-10 10];
            app.ValueSetEditField.ValueChangedFcn = createCallbackFcn(app, @ValueSetEditFieldValueChanged, true);
            app.ValueSetEditField.Tooltip = {'Up and down arrow key to increase'};
            app.FindSpikesButton = uibutton(app.ThresholdSettingsPanel, 'push');
            app.FindSpikesButton.ButtonPushedFcn = createCallbackFcn(app, @FindSpikesButtonPushed, true);
            app.FindSpikesButton.Tooltip = {'F to find spikes'};
            app.FindSpikesButton.Text = 'Find Spikes';
            app.ChannelProcessingPanel = uipanel(app.GridLayout4);
            app.ChannelProcessingPanel.Title = 'Channel Processing';
            app.AllrecordingsButton = uibutton(app.ChannelProcessingPanel, 'state');
            app.AllrecordingsButton.ValueChangedFcn = createCallbackFcn(app, @AllrecordingsButtonValueChanged, true);
            app.AllrecordingsButton.Tooltip = {'There is unit activity in each of the sessions. Press W'};
            app.AllrecordingsButton.Text = 'All recordings';
            app.SomeButton = uibutton(app.ChannelProcessingPanel, 'state');
            app.SomeButton.ValueChangedFcn = createCallbackFcn(app, @SomeButtonValueChanged, true);
            app.SomeButton.Tooltip = {'Some of the sessions have unit activity others do not. Press E. Select value that shows which of the 5 sessions has it.'};
            app.SomeButton.Text = 'Some';
            app.NorecordingsButton = uibutton(app.ChannelProcessingPanel, 'state');
            app.NorecordingsButton.ValueChangedFcn = createCallbackFcn(app, @NorecordingsButtonValueChanged, true);
            app.NorecordingsButton.Tooltip = {'No unit activity seen. This one should be ignored. Press D'};
            app.NorecordingsButton.Text = 'No recordings';
            app.SaveDataButton = uibutton(app.ChannelProcessingPanel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Tooltip = {'S to Save'};
            app.SaveDataButton.Text = 'Save Data';
            app.UnitQuality = uidropdown(app.ChannelProcessingPanel);
            app.UnitQuality.Items = {'0', '1', '2', '3', '4', '5', '6'};
            app.UnitQuality.ValueChangedFcn = createCallbackFcn(app, @UnitQualityValueChanged, true);
            app.UnitQuality.Tooltip = {'Press 1 2 3 4 or 5. 5 being the best. 1 being the worst.'};
            app.UnitQuality.Value = '0';
            app.SessionsItsIn = uidropdown(app.ChannelProcessingPanel);
            app.SessionsItsIn.Items = {'0', '1', '2', '3', '4', '5', '1-1', '1-2', '1-3', '1-4', '1-5', '2-3', '2-4', '2-5', '3-4', '3-5', '4-5', '1-2-3', '1-2-4', '1-2-5', '1-3-4', '1-3-5', '1-4-5', '2-3-4', '2-3-5', '2-4-5', '3-4-5', '1-2-3-4', '1-2-3-5', '1-2-4-5', '1-3-4-5', '2-3-4-5', '1-2-3-4-5'};
            app.SessionsItsIn.ValueChangedFcn = createCallbackFcn(app, @SessionsItsInValueChanged, true);
            app.SessionsItsIn.Tooltip = {'Which sessions have any sort of data for it.'};
            app.SessionsItsIn.Value = '0';
            app.QualityLabel = uilabel(app.ChannelProcessingPanel);
            app.QualityLabel.Text = 'Quality';
            app.SessionsLabel = uilabel(app.ChannelProcessingPanel);
            app.SessionsLabel.Text = 'Sessions';
            app.CenterPanel = uipanel(app.GridLayout);
            app.RAWPanel = uigridlayout(app.CenterPanel);
            app.RAW1 = uiaxes(app.RAWPanel);
            title(app.RAW1, 'Title')
            xlabel(app.RAW1, 'X')
            ylabel(app.RAW1, 'Y')
            app.RAW2 = uiaxes(app.RAWPanel);
            title(app.RAW2, 'Title')
            xlabel(app.RAW2, 'X')
            ylabel(app.RAW2, 'Y')
            app.RAW3 = uiaxes(app.RAWPanel);
            title(app.RAW3, 'Title')
            xlabel(app.RAW3, 'X')
            ylabel(app.RAW3, 'Y')
            app.RAW4 = uiaxes(app.RAWPanel);
            title(app.RAW4, 'Title')
            xlabel(app.RAW4, 'X')
            ylabel(app.RAW4, 'Y')
            app.RAW5 = uiaxes(app.RAWPanel);
            title(app.RAW5, 'Title')
            xlabel(app.RAW5, 'X')
            ylabel(app.RAW5, 'Y')
            app.RightPanel = uipanel(app.GridLayout);
            app.WavesPanel = uigridlayout(app.RightPanel);
            app.Waves1 = uiaxes(app.WavesPanel);
            title(app.Waves1, 'Title')
            xlabel(app.Waves1, 'X')
            ylabel(app.Waves1, 'Y')
            app.Waves2 = uiaxes(app.WavesPanel);
            title(app.Waves2, 'Title')
            xlabel(app.Waves2, 'X')
            ylabel(app.Waves2, 'Y')
            app.Waves3 = uiaxes(app.WavesPanel);
            title(app.Waves3, 'Title')
            xlabel(app.Waves3, 'X')
            ylabel(app.Waves3, 'Y')
            app.Waves4 = uiaxes(app.WavesPanel);
            title(app.Waves4, 'Title')
            xlabel(app.Waves4, 'X')
            ylabel(app.Waves4, 'Y')
            app.Average4 = uiaxes(app.WavesPanel);
            title(app.Average4, 'Title')
            xlabel(app.Average4, 'X')
            ylabel(app.Average4, 'Y')
            app.Average3 = uiaxes(app.WavesPanel);
            title(app.Average3, 'Title')
            xlabel(app.Average3, 'X')
            ylabel(app.Average3, 'Y')
            app.Average2 = uiaxes(app.WavesPanel);
            title(app.Average2, 'Title')
            xlabel(app.Average2, 'X')
            ylabel(app.Average2, 'Y')
            app.Average1 = uiaxes(app.WavesPanel);
            title(app.Average1, 'Title')
            xlabel(app.Average1, 'X')
            ylabel(app.Average1, 'Y')
            app.Average5 = uiaxes(app.WavesPanel);
            title(app.Average5, 'Title')
            xlabel(app.Average5, 'X')
            ylabel(app.Average5, 'Y')
            app.Waves5 = uiaxes(app.WavesPanel);
            title(app.Waves5, 'Title')
            xlabel(app.Waves5, 'X')
            ylabel(app.Waves5, 'Y')
            % Show the figure after all components are created
            app.QuickAnalysisFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QuickAnalysisSort

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QuickAnalysisFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QuickAnalysisFigure)
        end
    end
end