classdef SpikeHunter4_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        RawPlot                      matlab.ui.control.UIAxes
        WavePlot                     matlab.ui.control.UIAxes
        SnipetPlot                   matlab.ui.control.UIAxes
        ClusterPlot                  matlab.ui.control.UIAxes
        ISIPlot                      matlab.ui.control.UIAxes
        AverageWave                  matlab.ui.control.UIAxes
        BackWard                     matlab.ui.control.Button
        Forward                      matlab.ui.control.Button
        SelectSpike                  matlab.ui.control.Button
        ClusterSpike                 matlab.ui.control.Button
        text3                        matlab.ui.control.Label
        TimeEdit                     matlab.ui.control.EditField
        ResetSpike                   matlab.ui.control.Button
        BigBackWard                  matlab.ui.control.Button
        BigForward                   matlab.ui.control.Button
        DropSpike                    matlab.ui.control.Button
        uipanel3                     matlab.ui.container.Panel
        SaveSort                     matlab.ui.control.Button
        LoadSort                     matlab.ui.control.Button
        NewSort                      matlab.ui.control.Button
        filelist                     matlab.ui.control.Label
        CHLabel                      matlab.ui.control.Label
        SpikeThreshold               matlab.ui.control.Button
        SelectPoints                 matlab.ui.control.Button
        AddUnit                      matlab.ui.control.Button
        ClusterAxes                  matlab.ui.container.ButtonGroup
        PCA_1_2                      matlab.ui.control.RadioButton
        PCA_1_3                      matlab.ui.control.RadioButton
        PCA_2_3                      matlab.ui.control.RadioButton
        PCA_1_Time                   matlab.ui.control.RadioButton
        PCA_2_Time                   matlab.ui.control.RadioButton
        PCA_3_Time                   matlab.ui.control.RadioButton
        ResetPoints                  matlab.ui.control.Button
        DropUnit                     matlab.ui.control.Button
        uipanel4                     matlab.ui.container.Panel
        CurrentUnit                  matlab.ui.control.EditField
        TotUnit                      matlab.ui.control.Label
        text13                       matlab.ui.control.Label
        IsoText                      matlab.ui.control.EditField
        ProcessUnits                 matlab.ui.control.Button
        GrabUnit                     matlab.ui.control.Button
        ClearArtifacts               matlab.ui.control.Button
        ClearCluster                 matlab.ui.control.Button
        text12                       matlab.ui.control.Label
        VertZoomIn                   matlab.ui.control.Button
        VertZoomOut                  matlab.ui.control.Button
        ClusterTemplate              matlab.ui.control.Button
        UndoClear                    matlab.ui.control.Button
        FinalizeClear                matlab.ui.control.Button
        TemplateThresh               matlab.ui.control.Button
        TemplateClear                matlab.ui.control.Button
        MergeUnit                    matlab.ui.control.Button
        MergeUnitNum                 matlab.ui.control.EditField
        UnitStats                    matlab.ui.control.Button
        MergeAccept                  matlab.ui.control.Button
        InputCheckerPanel            matlab.ui.container.Panel
        ButtonPressed                matlab.ui.control.Label
        ClicksMade                   matlab.ui.control.Label
        ClicksMadeLabel              matlab.ui.control.Label
        ThresholdingModeButtonGroup  matlab.ui.container.ButtonGroup
        TroughsButton                matlab.ui.control.RadioButton
        PeaksButton                  matlab.ui.control.RadioButton
        CommonModeRejectionCheckBox  matlab.ui.control.CheckBox
        NewChannelButton             matlab.ui.control.Button
    end

    properties (Access = private)
        Initialized  = 0;
        cmap = [[1,0,0]; ...
            [1,0.375,0]; ...
            [0,0.5,0.2]; ...
            [0.3,0.2,0.8]; ...
            [1,0,0.6]; ...
            [0.5,0,1]; ...
            [0.5,0.3,0]; ...
            [0,0.6,0.6]; ...
            [0.8,0,1]];
        %*******
        FDataSample = [];
        DataSample = [];
        FileName = [];
        FilePath = [];
        CommonMode = true;  % use common mode rejection
        DM = 0;
        ChanNums = 0;
        SetIso = 0;
        MergeNum = 0;
        LastMerged = [];
        %*** internal book keeps
        ChangeUnit = [];
        ColorCnt = 0;   % count color to assign different in order
        %********
        TimeStart = 0.0; % earliest time
        TimeScale = 0.1; % length of horizontal on raw plot
        BigTimeScale = 5; % length for large steps in time
        DataSegNum = 20; % chunks to look through, even spaced over file
        DataLen = 5;    % duration in secs
        Fs = 20000;      % data sampling rate
        NullN = 1000;
        %**************
        ClearThreshSnips = [];
        ClearThreshNull = [];
        ClearClusterPts = [];
        ClearCnt = 0;
        ClearList = cell(1,1);  % list of chan x times to set to zero (artifact rejection)
        %*************
        SnipPre = 0.0;
        SnipPost = 0.0;
        SnipLen = 0.0;
        SnipCnt = 0;        % user selected waveforms
        Snips = cell(1,1);  % snipets
        SnipMino = [];
        SnipMaxo = [];
        SnipRange = [];  % range of channels in current spike targets
        SpikeRange = [];
        %********** data structs in spike waveform selection
        Threshold = [];   % defined at the moment to spike sort
        ThreshBox = [];   % second criterium for threshold, cross box
        ThreshSnips = []; % matrix of spike waves (across channels)
        ThreshUsed = [];  % mark those which have been assigned as units
        ThreshNull = [];  % subset of random snipets to form a noise dist
        NullTimes = [];   % times of random sampled snipets
        ThreshTimes = []; % list of trough times for spikes
        ThreshCnt = [];   % number of threshold crossings
        ClusterPts = [];  % trough time, points in a PCA space(pca 1+2)
        ClusterVec = [];  % prin comp vectors on which points are based
        ClusterSet = [];  % subset of points labeled into cluster
        ClusterState = 1;     % default is PCA 1 and 2
        UnitColor = [1,0,0];  % color when waves selected in possible unit
        Isolation = 0;     % scored 0 to 5, 5 being best
        AutoIso = 0;       % later will automate iso metric
        FlagTimes = [];    % if processed, flagged times on full file
        %*************
        UnitNum = 0;           % number of the selected unit
        UnitTot = 0;           % total number of units
        UnitInfo = cell(1,1);  % cell with unit info, ThreshSnips etc..
        %***********************
        MAP = []; %MAP;
        % This deals with getting input points
        needPoints = 0; % How many points to get
        pointHolder = []; % Has all the points in [xv,yv] format
        whichInput = ''; % Which function to finish for input
        getInput = false; % SHould you be looking for points
        currentPoint = 0; % Current point you're waiting on
        axis_to_listen = '';
        % When dealing with multiple concatenations
        listOfFiles = {}; % Locations of where they are...
        numFilesCombined = 1; % How many files were combined
        timeCutOffs = []; % Sample index of start of each session
        % Holds all the raw data for one channel when using this so you
        % don't do it multiple times...
        spdata = [];
        avgspdata = [];
        % Looking for troughs or peaks mode
        lookForPeaks = false;
    end
    
    methods (Access = private)
        
        function setPointInput(app, axis_type, type, num_points)
            app.getInput = true;
            app.whichInput = type;
            app.needPoints = num_points;
            app.currentPoint = 1;
            app.pointHolder = zeros(app.needPoints,2);
            app.axis_to_listen = axis_type;
            app.ButtonPressed.Text = type;
            app.ClicksMade.Text = sprintf('%i / %i',0,app.needPoints);
        end
        
        function MarmoSpike_Init(app)
            app.Initialized  = 1;
            %*********
            disp('Initializing SpikeSortGUI ...');
            app.cmap = [[1,0,0]; ...
                [1,0.375,0]; ...
                [0,0.5,0.2]; ...
                [0.3,0.2,0.8]; ...
                [1,0,0.6]; ...
                [0.5,0,1]; ...
                [0.5,0.3,0]; ...
                [0,0.6,0.6]; ...
                [0.8,0,1]];
            %*******
            app.FDataSample = [];
            app.DataSample = []; % Holds all the data
            app.FileName = [];
            app.FilePath = [];
            app.CommonMode = true;  % use common mode rejection
            app.DM = 0;
            app.ChanNums = 0;
            app.SetIso = 0;
            app.MergeNum = 0;
            app.LastMerged = [];
            %*** internal book keeps
            app.ChangeUnit = [];
            app.ColorCnt = 0;   % count color to assign different in order
            %********
            app.TimeStart = 0.0; % earliest time
            app.TimeScale = 0.1; % length of horizontal on raw plot
            app.BigTimeScale = 5; % length for large steps in time
            app.DataSegNum = 20; % chunks to look through, even spaced over file
            app.DataLen = 5;    % duration in secs
            app.NullN = 1000;
            %**************
            app.ClearThreshSnips = [];
            app.ClearThreshNull = [];
            app.ClearClusterPts = [];
            app.ClearCnt = 0;
            app.ClearList = cell(1,1);  % list of chan x times to set to zero (artifact rejection)
            %*************
            app.SnipPre = floor(0.7 * (app.Fs/1000));  % ms before trough
            app.SnipPost = floor(0.7 * (app.Fs/1000)); % ms after trough
            app.SnipLen = 1 + app.SnipPre + app.SnipPost;
            app.SnipCnt = 0;        % user selected waveforms
            app.Snips = cell(1,1);  % snipets
            app.SnipMino = [];
            app.SnipMaxo = [];
            app.SnipRange = [];  % range of channels in current spike targets
            app.SpikeRange = [];
            %********** data structs in spike waveform selection
            app.Threshold = [];   % defined at the moment to spike sort
            app.ThreshBox = [];   % second criterium for threshold, cross box
            app.ThreshSnips = []; % matrix of spike waves (across channels)
            app.ThreshUsed = [];  % mark those which have been assigned as units
            app.ThreshNull = [];  % subset of random snipets to form a noise dist
            app.NullTimes = [];   % times of random sampled snipets
            app.ThreshTimes = []; % list of trough times for spikes
            app.ThreshCnt = [];   % number of threshold crossings
            app.ClusterPts = [];  % trough time, points in a PCA space(pca 1+2)
            app.ClusterVec = [];  % prin comp vectors on which points are based
            app.ClusterSet = [];  % subset of points labeled into cluster
            app.ClusterState = 1;     % default is PCA 1 and 2
            app.UnitColor = [1,0,0];  % color when waves selected in possible unit
            app.Isolation = 0;     % scored 0 to 5, 5 being best
            app.AutoIso = 0;       % later will automate iso metric
            app.FlagTimes = [];    % if processed, flagged times on full file
            %*************
            app.UnitNum = 0;           % number of the selected unit
            app.UnitTot = 0;           % total number of units
            app.UnitInfo = cell(1,1);  % cell with unit info, ThreshSnips etc..
            %***********************
            app.MAP = [1:16]; %MAP;
            app.CurrentUnit.Value = num2str(app.UnitNum);
            app.TimeEdit.Value = num2str(app.TimeScale);
            app.TotUnit.Text = num2str(app.UnitTot);
            %******************
            app.CommonMode = app.CommonModeRejectionCheckBox.Value;
        end
        
        
        function Store_Update_Unit(app)
            
            if (app.UnitNum == 0)
                return;
            end
            hdlg = uiprogressdlg(app.figure1,'message','Updating unit to database ...');
            %***************
            info.SnipCnt = app.SnipCnt;        % user selected waveforms
            info.Snips = app.Snips;  % snipets
            info.SnipMino = app.SnipMino;
            info.SnipMaxo = app.SnipMaxo;
            %*** don't change vert axis in raw plots because you zeroed out spikes
            %*** that were already stored and sorted
            tmp = app.SnipMaxo - app.SnipMino;
            if isempty(app.SpikeRange)
                app.SpikeRange = tmp;
            else
                if (tmp > app.SpikeRange)
                    app.SpikeRange = tmp;
                end
            end
            %******
            info.SnipRange = app.SnipRange;  % range of channels in current spike targets
            info.Threshold = app.Threshold;   % defined at the moment to spike sort
            info.ThreshBox = app.ThreshBox;
            info.ThreshSnips = app.ThreshSnips; % matrix of spike waves (across channels)
            info.ThreshNull = app.ThreshNull;
            info.NullTimes = app.NullTimes;
            info.ThreshTimes = app.ThreshTimes;
            info.ThreshCnt = app.ThreshCnt;   % number of threshold crossings
            info.ClusterPts = app.ClusterPts;  % points in a PCA space (pca 1, pca 2, time)
            info.ClusterVec = app.ClusterVec;
            info.ClusterSet = app.ClusterSet;
            info.ClusterState = app.ClusterState;
            info.Isolation = app.Isolation;
            info.AutoIso = app.AutoIso;
            info.UnitColor = app.UnitColor;
            info.FlatTimes = app.FlagTimes;
            info.lookForPeaks = app.lookForPeaks;
            %*****************
            app.UnitInfo{app.UnitNum} = info;
            app.CurrentUnit.Value = num2str(app.UnitNum);
            app.TotUnit.Text = num2str(app.UnitTot);
            
            
            %****** DONT ZERO AWAY EXAMPLES ... mark used times instead
            %****** and exclude those from thresholding in future sorts
            %****** or another way, do not allow further thresholding ...
            %************** label select points as used
            update_thresh_used(app);
            
            %**************************************
            
            close(hdlg);
            %*********
            
            plot_raw_spikes(app);
            plot_waveforms(app);
            plot_average_waveforms(app);
            plot_cluster(app);
            plot_isi(app,[],[],true);
            plot_snipets(app);
        end
        
        function [ix, iy] = axes_from_cluster_state(app)
            switch app.ClusterState
                case 1
                    ix = 1; iy = 2;
                case 2
                    ix = 1; iy = 3;
                case 3
                    ix = 2; iy = 3;
                case 4
                    ix = 1; iy = 4;
                case 5
                    ix = 2; iy = 4;
                case 6
                    ix = 3; iy = 4;
                otherwise
                    ix = 1; iy = 2;
            end
        end
        
        function drop_spike(app, xv, ~)
            %****** define a time box had yv as last one
            tmin = min(xv);  % min time
            tmax = max(xv);
            
            %*** loop through snips, if overlapping in time cut them and resort
            k = 1;
            updatestruct = 0;
            while (k <= app.SnipCnt)
                if ( (app.Snips{k}.time >= tmin) && (app.Snips{k}.time <= tmax) )
                    %**** found one, delete it and resort others
                    for ik = (k+1):app.SnipCnt
                        app.Snips{ik-1} = app.Snips{ik};  % shift to erase
                    end
                    app.SnipCnt = app.SnipCnt -1;
                    updatestruct = 1;
                end
                k = k + 1;
            end
            %******* now the question is do we clear out old memory and recreate
            if (updatestruct)
                zSnips = app.Snips;
                clear app.Snips;
                app.Snips = cell(1,1);
                for k = 1:app.SnipCnt
                    app.Snips{k} = zSnips{k};
                end
                clear zSnips;
                %****** load back updated handles class
                
            end
        end
        
        function snip = grab_spike(app, xv, yv)
            
            
            %TScale = app.TimeScale;
            
            %****** define a time box
            tmin = min(xv);  % min time
            tmax = max(xv);
            ymin = ceil(min(yv));
            if (ymin < 1)
                ymin = 1;
            end
            ymax = floor(max(yv));
            if (ymax > app.ChanNums)
                ymax = app.ChanNums;
            end
            
            %******* find the trough in selected zone (average across chans)
            tgo = 1 + floor( tmin * app.Fs);
            tfi = 1 + ceil(tmax * app.Fs);
            %xt = (tgo:tfi) * (1/app.Fs);
            snipper = app.FDataSample(ymin:ymax,tgo:tfi);
            if (ymax > ymin)
                msnipper = mean(snipper);
            else
                msnipper = snipper;
            end
            it = find( msnipper == min(msnipper) );
            if app.lookForPeaks
                it = find( msnipper == max(msnipper) ); % Flipper function
            else
                it = find( msnipper == min(msnipper) );
            end
            if isempty(it)
                snip = [];
                return;
            end
            
            %************ having trough/peak, cut out the snippet of fixed size
            snip.time = tmin + it(1)/app.Fs;   %time of trough
            igo = 1 + floor( snip.time * app.Fs) - app.SnipPre;
            ifi = 1 + floor( snip.time * app.Fs) + app.SnipPost;
            snip.chans = ymin:ymax;  % list of channels
            snip.times = igo:ifi;
            snip.data = app.FDataSample(snip.chans,snip.times);
        end
        
        function [data_stored,avgsamples] = load_wav_channel(app,file_name,channel_idx)
            % This will read in a wav file and only take in chunks of it at
            % a time to read in for the particular channel_idx of interest.
            % Data_Stored will have one channel loaded up. avg samples will
            % have the average samples for that channel for common mode
            % rejection.
            
            % First check to see how many files were selected. if more than
            % one, then do a different version with more of these
            
            if app.numFilesCombined == 1
                file_info = audioinfo(file_name);
                app.Fs = file_info.SampleRate;
                
                % Create a vector to hold single channel data
                data_stored = zeros(file_info.TotalSamples,1);
                avgsamples = data_stored;
                % Do 10% chunks, so need 11 points from 1 to end.
                total_chunks = 10;
                chunk_indeces = floor(linspace(1,file_info.TotalSamples,total_chunks+1));
                chunk_indeces(end) = file_info.TotalSamples;
                for i = 1:total_chunks
                    [data, ~] = audioread(file_name,[chunk_indeces(i),chunk_indeces(i+1)]);
                    data_stored(chunk_indeces(i):chunk_indeces(i+1)) = data(:,channel_idx)*-1;
                    avgsamples(chunk_indeces(i):chunk_indeces(i+1)) = mean(data,2)*-1;
                end
            else % Have multiple files to read
                cell_data = cell(app.numFilesCombined,3);
                prior_samples = 0;
                hdlg = uiprogressdlg(app.figure1,'message','Loading the .wav files');
                hdlg.Value = 0;
                for file_idx = 1:app.numFilesCombined
                    hdlg.Value = (file_idx-1)/app.numFilesCombined;
                    file_name = [app.FilePath,app.listOfFiles{file_idx}]
                    file_info = audioinfo(file_name);
                    app.Fs = file_info.SampleRate;
                    % Create a vector to hold single channel data
                    temp_data_stored = zeros(file_info.TotalSamples,1);
                    temp_avgsamples = temp_data_stored;
                    
                    % Do 10% chunks, so need 11 points from 1 to end.
                    total_chunks = 10;
                    chunk_indeces = floor(linspace(1,file_info.TotalSamples,total_chunks+1));
                    chunk_indeces(end) = file_info.TotalSamples;
                    for i = 1:total_chunks
                        [data, ~] = audioread(file_name,[chunk_indeces(i),chunk_indeces(i+1)]);
                        temp_data_stored(chunk_indeces(i):chunk_indeces(i+1)) = data(:,channel_idx)*-1;
                        temp_avgsamples(chunk_indeces(i):chunk_indeces(i+1)) = mean(data,2)*-1;
                    end
                    if file_idx == 1
                        cell_data{file_idx,1} = prior_samples + 1;
                    else
                        cell_data{file_idx,1} = cell_data{file_idx-1,1} + prior_samples;
                    end
                    prior_samples = file_info.TotalSamples;
                    cell_data{file_idx,2} = temp_data_stored;
                    cell_data{file_idx,3} = temp_avgsamples;
                end
                % Now store the data combined
                hdlg.Value = 1;
                app.timeCutOffs = cell2mat(cell_data(:,1));
                data_stored = cell2mat(cell_data(:,2));
                avgsamples = cell2mat(cell_data(:,3));
                delete(hdlg);
            end
        end
        
    
        function load_and_filter_data_sample(app)
            %********** perform filtering of raw signal
            
            hdlg = uiprogressdlg(app.figure1,'Message','Loading continuous channels');
            hdlg.Value = 0;
            %****** re-written for a single file mode (process one channel file)
            app.MAP = 1;
            app.ChanNums = size(app.MAP,1);
            app.DataSample = zeros(app.ChanNums+1,(app.DataSegNum * app.DataLen * app.Fs));
            
            %***** compute mean across all channels (common mode)
            for k = 1:app.ChanNums
                %imap = app.MAP(k);
                SpikeFiles = [app.FilePath,app.FileName];
                if isempty(SpikeFiles)
                    uialert(app.figure1,'Error finding continuous file','ERROR','Icon','error');
                    %******* reset values before you return, it failed
                    app.MAP = [];
                    app.ChanNums = 0;
                    app.DataSample = [];
                    %**********
                    return;
                end
                % First one is for opening ephys data
                %[spdata,sptime,spinfo] = read_ephys.load_open_ephys_data(SpikeFiles);
                % Ask which channel to filter
                channelList = 1:32;
                [index tf] = listdlg('ListString',sprintfc('%d',channelList),'SelectionMode','single',...
                    'Name','File Loader','PromptString','Choose channel to look at and load',...
                    'InitialValue',1,'ListSize',[320,200]);
                [app.spdata,app.avgspdata] = load_wav_channel(app,SpikeFiles,channelList(index));
                app.MAP = channelList(index);
                app.CHLabel.Text = sprintf('CH: %d',app.MAP);
                fprintf('Continuous data %s loaded\n',SpikeFiles);
                %****** accumulate for average
%                 if (k == 1)
%                     avspdata = spdata;
%                 else
%                     avspdata = avspdata + spdata;
%                 end
                %****** store data segments for viewing later ***********
                dN = size(app.spdata,1);
                dM = size(app.DataSample,2);
                for zk = 1:app.DataSegNum
                    spgo = 1 + floor( (zk-1) * dN / app.DataSegNum);
                    spfi = spgo - 1 + (app.DataLen * app.Fs);
                    igo = 1 + floor( (zk-1) * dM / app.DataSegNum);
                    ifi = igo - 1 + (app.DataLen * app.Fs);
                    app.DataSample(k,igo:ifi) = app.spdata(spgo:spfi,1);
                    if app.CommonMode % Store the average value for those parts
                        app.DataSample(end,igo:ifi) = app.avgspdata(spgo:spfi,1);
                    end
                end
                %*********
                hdlg.Value = (k/(app.ChanNums+1));
                fprintf('Subsampled data extracted from %s\n',SpikeFiles);
                %*********
            end
            hdlg.Value = 1;
%             if (app.ChanNums > 1)  % don't do common mode, set at 0, if single chan
%                 %avspdata = avspdata / app.ChanNums;
%                 app.DataSample(app.ChanNums+1,:) = mean(app.DataSample(1:app.ChanNums,:),1);
%             end
            app.DM = dM;
            close(hdlg);
            %******
            
            %******* THEN BANDPASS FILTER THE DATA SAMPLE
            hdlg = uiprogressdlg(app.figure1,'Message','Prefiltering data segments ...');
            hdlg.Value = 0;
            app.FDataSample = app.DataSample; % filtered copy of raw
            %[b,a] = butter(3, [((800/app.Fs)*2), ((8000/app.Fs)*2)]);
            [b,a] = butter(6, [((300/app.Fs)*2), ((8000/app.Fs)*2)]);
            for k = 1:app.DataSegNum
                hdlg.Value = (k/app.DataSegNum);
                igo = 1 + floor( (k-1) * app.DM / app.DataSegNum);
                ifi = igo - 1 + (app.DataLen * app.Fs);
                %***** last channel is the average of all by default
                if app.CommonMode
                    zdata = app.DataSample(app.ChanNums+1,igo:ifi);
                end
                for zk = 1:app.ChanNums+1
                    data = app.DataSample(zk,igo:ifi);
                    if app.CommonMode && (zk <= app.ChanNums)
                        data = data - zdata;  % common mode rejection?
                    end
%                     fdata = filter(b,a,data');
%                     fdata = flipud(fdata);
%                     fdata = filter(b,a,fdata);
%                     fdata = flipud(fdata);
                    fdata = filtfilt(b,a,data);
                    app.FDataSample(zk,igo:ifi) = fdata';
                end
            end
            hdlg.Value = 1;
            close(hdlg);
            %******** STORE SPIKE INTO GUI and return for sorting
            
        end
        
        function plot_average_waveforms(app)
            
            %axes(app.AverageWave); 
            hold(app.AverageWave,'off'); 
            plot(app.AverageWave,0,0,'k.','Tag','averagewave'); 
            axis(app.AverageWave,'off');
            if (app.UnitTot == 0)
                return;
            end
            xt = (-app.SnipPre:app.SnipPost)*(1000/app.Fs); % is ms
            %*********
            if (app.UnitTot >= 1)
                %***** fill blank white area to start
                maxchan = -Inf;
                minchan = Inf;
                for sk = 1:app.UnitTot
                    maxchan = max(maxchan,(app.UnitInfo{sk}.SnipRange(2)+1));
                    minchan = min(minchan,(app.UnitInfo{sk}.SnipRange(1)-1));
                end
                fill(app.AverageWave,[xt(1),xt(end),xt(end),xt(1)],[maxchan,maxchan,minchan,minchan],[1,1,1],'Tag','averagewave'); 
                hold(app.AverageWave,'on');
                %****** find max and min scale range
                %****** plot the mean waveforms
                for sk = 1:app.UnitTot
                    cset = app.UnitInfo{sk}.ClusterSet;
                    unip = mean( app.UnitInfo{sk}.ThreshSnips( cset, :) );
                    vnip = std( app.UnitInfo{sk}.ThreshSnips( cset, :) );
                    snrange = app.UnitInfo{sk}.SnipRange;
                    nchans = snrange(2) - snrange(1) + 1;
                    mchans = snrange(1):snrange(2);
                    mino = app.UnitInfo{sk}.SnipMino;
                    maxo = app.UnitInfo{sk}.SnipMaxo;
                    colo = app.UnitInfo{sk}.UnitColor;
                    len = app.SnipLen;
                    %**********
                    plot_my_waveforms(app,xt,unip,vnip,mchans,nchans,len,maxo,mino,colo,app.AverageWave);
                    %******
                    hh = text(app.AverageWave, (xt(1)+(xt(end)-xt(1))*0.8), (minchan+((maxchan-minchan)*(0.6 + (0.3*((sk-0.5)/app.UnitTot))))),...
                        sprintf('U%d',sk));
                    set(hh,'Fontsize',8);
                    set(hh,'Color',colo);
                end
                xlabel(app.AverageWave,'Time (ms)');
                ylabel(app.AverageWave,'Voltage (normed)');
                axis(app.AverageWave,'tight');
                axis(app.AverageWave,'off');
            end
        end
        
        function plot_cluster(app)
            
            %axes(app.ClusterPlot); 
            hold(app.ClusterPlot,'off');
            plot(app.ClusterPlot,0,0,'k.','Tag','clusterplotempty');
            axis(app.ClusterPlot,'off');
            if isempty(app.ClusterPts)
                disp('Empty cluster points')
                return;
            else
                if (app.ThreshCnt > 15000)
                    rr = randperm(app.ThreshCnt);
                    rset = rr(1:15000);
                else
                    rset = 1:app.ThreshCnt;
                end
                if ~isempty(app.ThreshUsed)
                    raset = find( ismember(rset,app.ThreshUsed(:,1)) == 0);
                    rbset = find( ismember(rset,app.ThreshUsed(:,1)) >= 1);
                else
                    raset = 1:size(rset,2);
                    rbset = [];
                end
                nset = (app.ThreshCnt+1):(app.ThreshCnt+app.NullN);
                fset = 1:app.ThreshCnt;
                %*****
                [ix,iy] = axes_from_cluster_state(app);
                xx = app.ClusterPts(rset,ix);
                yy = app.ClusterPts(rset,iy);
                nxx = app.ClusterPts(nset,ix);
                nyy = app.ClusterPts(nset,iy);
                fxx = app.ClusterPts(fset,ix);
                fyy = app.ClusterPts(fset,iy);
                %*********  scale range larger so all points contained
                mino1 = min(min([xx ; nxx; fxx]));
                maxo1 = max(max([xx ; nxx; fxx]));
                diff = (maxo1-mino1);
                mino1 = mino1 - (diff*0.25);
                maxo1 = maxo1 + (diff*0.25);
                %**********
                mino2 = min(min([yy ; nyy; fyy]));
                maxo2 = max(max([yy ; nyy; fyy]));
                diff = (maxo2-mino2);
                mino2 = mino2 - (diff*0.1);
                maxo2 = maxo2 + (diff*0.1);
                %***********
                fill(app.ClusterPlot,[mino1,maxo1,maxo1,mino1],[maxo2,maxo2,mino2,mino2],[1,1,1],'Tag','clusterplotfill');
                hold(app.ClusterPlot,'on');
                if ~isempty(rbset)
                    h = plot(app.ClusterPlot,xx(rbset),yy(rbset),'ko','Tag','clusterplotrbset');
                    hold(app.ClusterPlot,'on');
                    set(h,'Markersize',1);
                    set(h,'Color',[0.6,0.6,0.6]);
                end
                if ~isempty(raset)
                    h = plot(app.ClusterPlot,xx(raset),yy(raset),'ko','Tag','clusterplotraset');
                    hold(app.ClusterPlot,'on');
                    set(h,'Markersize',1);
                end
                %***********
                if ~isempty(app.ClusterSet)
                    h = plot(app.ClusterPlot,fxx(app.ClusterSet),fyy(app.ClusterSet),'ro','Tag','clusterplotclusterset');
                    set(h,'Markersize',1);
                    set(h,'Color',app.UnitColor);
                end
                h = plot(app.ClusterPlot,nxx,nyy,'co','Tag','clusterplotnoiseset');
                hold(app.ClusterPlot,'on')
                set(h,'Markersize',1);
                axis(app.ClusterPlot,[mino1 maxo1 mino2 maxo2]);
                axis(app.ClusterPlot,'off');
            end
        end
        
        function plot_isi(app, stimes, cola,plotOnApp)
            if plotOnApp
                thisaxis = app.ISIPlot;
            else
                thisaxis = gca;
            end
            if isempty(stimes)
                hold(thisaxis,'off'); 
                plot(thisaxis,0,0,'k.','tag','isiplot'); 
                axis(thisaxis,'off');
            end
            if isempty(app.ClusterSet) && isempty(stimes)
                return;
            else
                MISI = 15;
                if (isempty(stimes))
                    sptimes = app.ClusterPts(app.ClusterSet,4) * (1000/app.Fs);  % times in ms
                    colo = app.UnitColor;
                else
                    sptimes = stimes;
                    colo = cola;
                end
                isi = diff(sptimes);
                bx = 0.2:0.2:MISI;  % twenty ms, look for refractory period
                by = histcounts(isi,bx);
                bx = bx(1:(end-1));
                by = by(1:(end-1)); % throw out last point ... lots of spikes past 20ms
                %*************
                fill(thisaxis,[0,MISI,MISI,0],[max(by),max(by),0,0],[1,1,1],'tag','isiplot'); 
                hold(thisaxis,'on');
                h = bar(thisaxis,bx(1:end-1),by, 1.0,'k');
                %h = histogram(app.ISIPLot,isi,bx,'FaceColor','k');
                hold(thisaxis,'on');
                set(h,'FaceColor',colo);
                set(h,'EdgeColor',colo);
                axis(thisaxis,[0 MISI 0 max(max(by),1)]);
                h = plot(thisaxis,[1,1],[0,max(max(by),1)],'k--');
                set(h,'LineWidth',2);
                zo =  bx <= 1;
                iperc = (sum(by(zo))/length(sptimes))*100;
                h = text(thisaxis,(MISI-10),(max(max(by),1)*0.8),sprintf('Viol.(<1ms): %3.1f',iperc));
                set(h,'FontSize',8.0);
                axis(thisaxis,'off');
            end
        end
        
        function plot_my_waveforms(app, xt, uu, su, mchans, nchans, len, maxo, mino, colo,plotOnApp)
            % plots a multi-channel waveform
            
            sdata = reshape( uu, [nchans len]);
            if ~isempty(su)
                ssdata = reshape( su,[nchans len]);
            else
                su = zeros(size(uu));
            end
            for ik = 1:nchans
                % These might need to be app.WavePlot
                h = plot(plotOnApp,xt,mchans(ik)+(sdata(ik,:)/(maxo-mino)),'k-','tag',['waveplotchan',num2str(ik)]);
                hold(plotOnApp,'on');
                set(h,'Color',colo);
                set(h,'LineWidth',2);
                h = plot(plotOnApp,xt,mchans(ik)+((sdata(ik,:)+ssdata(ik,:))/(maxo-mino)),'k-','tag',['waveplotchxn',num2str(ik)]); 
                hold(plotOnApp,'on');
                set(h,'Color',colo);
                h = plot(plotOnApp,xt,mchans(ik)+((sdata(ik,:)-ssdata(ik,:))/(maxo-mino)),'k-','tag',['waveplotchzn',num2str(ik)]);
                hold(plotOnApp,'on');
                set(h,'Color',colo);
            end
        end
        
        function plot_raw_spikes(app)
       
            hold(app.RawPlot,'off'); axis(app.RawPlot,'on');
            
            if isempty(app.DataSample)
                return;
            end
            
            Scale = 1.0;
            TScale = app.TimeScale;
            %******
            igo = 1 + floor( app.TimeStart * app.Fs);
            ifi = igo - 1 + (app.TimeScale * app.Fs);
            xt = (igo:ifi) * (1/app.Fs);
            mxt = xt(1)-(app.TimeScale/40);
            %***** fill a white background first
            maxchan = app.ChanNums+2;
            fill(app.RawPlot,[mxt,xt(end),xt(end),mxt],[maxchan,maxchan,0,0],[1,1,1],'Tag','rawplotfill');
            hold(app.RawPlot,'on');
            %*******************
            xp = mxt + (floor((ifi-igo)/120) * (1/app.Fs));
            xp2 = (igo + floor(6.5*(ifi-igo)/8)) * (1/app.Fs);
            rng = max(range(app.FDataSample(1:app.ChanNums,:),2))*0.5;
            if isempty(app.SpikeRange)
                app.SpikeRange = rng;
                
            else
                rng = app.SpikeRange;
            end
            %*****
            for zk = 1:app.ChanNums
                data = app.FDataSample(zk,igo:ifi);
                data = Scale * (data - mean(data))/rng;  % normalized, max +1 min -1
                h = plot(app.RawPlot,xt,data+zk,'k-','Tag',['rawplotch',num2str(zk)]);
                hold(app.RawPlot,'on');
                if ~isempty(app.SnipRange)
                    if ( (zk <= app.SnipRange(2)) && (zk >= app.SnipRange(1)) )
                        set(h,'Color',[0,0,0.4]); % label spike hunted chans dark blue
                    end
                end
                if (1)
                    hh = text(app.RawPlot,xp,zk+0.0,sprintf('%d',app.MAP(zk)),'Tag','rawplottext');
                    set(hh,'Fontsize',6);
                    set(hh,'Color',[0,0,1]);
                end
                %*****
            end
            %*********** if cluster is set, identify any spikes and plot them orange
            if ~isempty(app.ClusterSet)
                for zk = 1:size(app.ClusterSet,1)
                    kk = app.ClusterSet(zk);
                    if (app.ThreshTimes(kk) >= igo) && (app.ThreshTimes(kk) <= ifi)
                        yt = [(app.SnipRange(1)-0.5),(app.SnipRange(2)+0.5)];
                        xt = [(app.ThreshTimes(kk)-app.SnipPre - 10),(app.ThreshTimes(kk)+app.SnipPost + 10)];
                        xt = xt * (1/app.Fs);
                        h = plot(app.RawPlot,[xt(1),xt(1),xt(end),xt(end),xt(1)],...
                            [yt(1),yt(end),yt(end),yt(1),yt(1)],'m-','Tag',['rawplotcluster',num2str(kk)]);
                    end
                end
            end
            %******* monstrous ... plot all pre-selected units in their colors
            if ~isempty(app.UnitInfo)
                for uk = 1:app.UnitTot
                    %***** load relevant info
                    zClusterSet = app.UnitInfo{uk}.ClusterSet;
                    zThreshTimes = app.UnitInfo{uk}.ThreshTimes;
                    zSnipRange = app.UnitInfo{uk}.SnipRange;
                    zThreshSnips = app.UnitInfo{uk}.ThreshSnips;
                    zUnitColor = app.UnitInfo{uk}.UnitColor;
                    %************************
                    for zk = 1:size(zClusterSet,1)
                        kk = zClusterSet(zk);
                        if (zThreshTimes(kk) >= igo) && (zThreshTimes(kk) <= ifi)
                            zN = zSnipRange(2) - zSnipRange(1) + 1;
                            znip = reshape( zThreshSnips(kk,:), [app.SnipLen, zN])';
                            xt = (zThreshTimes(kk)-app.SnipPre):...
                                (zThreshTimes(kk)+app.SnipPost);
                            xt = xt * (1/app.Fs);
                            for ik = 1:zN
                                data = Scale * (znip(ik,:)-mean(znip(ik,:)))/rng;
                                h = plot(app.RawPlot,xt,zSnipRange(1)+(ik-1)+data,'r-','Tag',['rawplot',num2str(uk*100+zk)]); 
                                hold(app.RawPlot,'on');
                                set(h,'Color',zUnitColor);
                                set(h,'LineWidth',1.5);  % thicker so easier to see
                            end
                        end
                    end
                    %*********
                end
            end
            
            %******* loop and plot any spike snipetplot within the current window
            for zk = 1:app.SnipCnt
                if (app.Snips{zk}.times(1) >= igo) && (app.Snips{zk}.times(end) <= ifi)
                    for kk = 1:size(app.Snips{zk}.chans,2)
                        xt = [(app.Snips{zk}.times(1)-10),(app.Snips{zk}.times(end)+10)] * (1/app.Fs);
                        yt = [(app.SnipRange(1)-0.5),(app.SnipRange(2)+0.5)];
                        h = plot(app.SnipetPlot,[xt(1),xt(1),xt(end),xt(end),xt(1)],...
                            [yt(1),yt(end),yt(end),yt(1),yt(1)],'r-','tag',['snipetplotsnipcnt',num2str(zk*100+kk)]);
                    end
                end
            end
            %*********************
            hh = text(app.RawPlot,xp2,(maxchan-0.5),sprintf('%4.1f - %4.1f secs',app.TimeStart,...
                (app.TimeStart+app.TimeScale)));
            set(hh,'Fontsize',10);
            set(hh,'Color',[1,0,0]);
            %******
            xlabel(app.RawPlot,'Time (s)');
            ylabel(app.RawPlot,'Voltage (normed)');
            axis(app.RawPlot,'tight');
            axis(app.RawPlot,'off');
        end
        
        function plot_snipets(app)
            
            hold(app.SnipetPlot,'off'); 
            plot(app.SnipetPlot,0,0,'k.','tag','snipetplotempty'); 
            axis(app.SnipetPlot,'off');
            
            if isempty(app.ThreshSnips)
                return;
            else
                zN = app.SnipRange(2) - app.SnipRange(1) + 1;
                xt = (-app.SnipPre:app.SnipPost) * (1000/app.Fs);
                %***** place white background
                fill(app.SnipetPlot,[xt(1),xt(end),xt(end),xt(1)],[zN+0.75,zN+0.75,0.25,0.25],[1,1,1],'tag','snipetplotfill');
                hold(app.SnipetPlot,'on');
                h = text(app.SnipetPlot,(xt(1)+(0.75*(xt(end)-xt(1)))),zN+0.65,sprintf('N=%d',app.ThreshCnt),'tag','snipetplottext');
                set(h,'Color',[0,0,0]);
                set(h,'Fontsize',8);
                if ~isempty(app.ClusterSet)
                    h = text(app.SnipetPlot,(xt(1)+(0.75*(xt(end)-xt(1)))),zN+0.45,sprintf('N=%d',size(app.ClusterSet,1)),'tag','snipetplottagtext');
                    set(h,'Color',[1,0,0]);
                    set(h,'Fontsize',8);
                end
                %*********
                mino = app.SnipMino;
                maxo = app.SnipMaxo;
                %***** limit snipets shown to max of 500 (otherwise too confusing)
                if (app.ThreshCnt < 500)
                    rset = 1:app.ThreshCnt;
                else
                    rr = randperm(app.ThreshCnt);
                    rset = rr(1:500);
                end
                %****** then plot the null distribution also
                for k = 1:app.NullN
                    znip = reshape( app.ThreshNull(k,:), [app.SnipLen, zN])';
                    for zk = 1:zN
                        h = plot(app.SnipetPlot,xt,zk+(znip(zk,:)/(2*(maxo-mino))),'c:','tag',['snipetplotnull',num2str(k*100+zk)]);
                        hold(app.SnipetPlot,'on');
                        % set(h,'Color',[0.5,0.5,0.5]);
                    end
                end
                %*************
                if ~isempty(app.ThreshUsed)
                    raset = find( ismember(rset,app.ThreshUsed) == 0);
                    rbset = find( ismember(rset,app.ThreshUsed) == 1);
                else
                    raset = 1:size(rset,2);
                    rbset = [];
                end
                if ~isempty(rbset)
                    for kk = 1:size(rbset,2)
                        k = rset(rbset(kk));
                        znip = reshape( app.ThreshSnips(k,:), [app.SnipLen, zN])';
                        for zk = 1:zN
                            h = plot(app.SnipetPlot,xt,zk+(znip(zk,:)/(2*(maxo-mino))),'y:','tag',['snipetploty',num2str(kk*100+zk)]);
                            hold(app.SnipetPlot,'on');
                            set(h,'Color',[0.6,0.6,0.6]);
                        end
                    end
                end
                if ~isempty(raset)
                    for kk = 1:size(raset,2)
                        k = rset(raset(kk));
                        znip = reshape( app.ThreshSnips(k,:), [app.SnipLen, zN])';
                        for zk = 1:zN
                            plot(app.SnipetPlot,xt,zk+(znip(zk,:)/(2*(maxo-mino))),'k:','tag',['snipetplotraset',num2str(kk*100+zk)]);
                            hold(app.SnipetPlot,'on');
                        end
                    end
                end
                %***** plot selected waves
                if ~isempty(app.ClusterSet)
                    if size(app.ClusterSet,1) > 100
                        rr = randperm(size(app.ClusterSet,1));
                        cset = app.ClusterSet(rr(1:100))';
                    else
                        cset = app.ClusterSet';
                    end
                    for kk = 1:size(cset,2)
                        k = cset(kk);
                        znip = reshape( app.ThreshSnips(k,:), [app.SnipLen, zN])';
                        for zk = 1:zN
                            h = plot(app.SnipetPlot,xt,zk+(znip(zk,:)/(2*(maxo-mino))),'r-','tag',['snipetplotselected',num2str(kk*100+zk)]);
                            hold(app.SnipetPlot,'on');
                            set(h,'Color',app.UnitColor);
                        end
                    end
                end
                %****************
                axis(app.SnipetPlot,'tight');
                axis(app.SnipetPlot,'off');
            end
        end
        
        function plot_waveforms(app)
            
            hold(app.WavePlot,'off')
            plot(app.WavePlot,0,0,'k.','tag','waveplotempty');
            axis(app.WavePlot,'off')
            if isempty(app.DataSample)
                return;
            end
            
            xt = (-app.SnipPre:app.SnipPost)*(1000/app.Fs); % is ms
            %*********
            if (app.SnipCnt >= 1)
                %***** fill blank white area to start
                maxchan = app.SnipRange(2)+1;
                minchan = app.SnipRange(1)-4;
                fill(app.WavePlot,[xt(1),xt(end),xt(end),xt(1)],[maxchan,maxchan,minchan,minchan],[1,1,1],'tag','waveplotfill'); 
                hold(app.WavePlot,'on');
                %****** find max and min scale range
                mino = Inf;
                maxo = -Inf;
                for sk = 1:app.SnipCnt
                    snip = app.Snips{sk};
                    mino = min(min(min(snip.data)),mino);
                    maxo = max(max(max(snip.data)),maxo);
                end
                %****** plot the snips
                snippers = [];
                for sk = 1:app.SnipCnt
                    snip = app.Snips{sk};
                    for zk = 1:size(snip.chans,2)
                        snippers = [snippers ; snip.data(zk,:)];  % steepest trough found
                        plot(app.WavePlot,xt,snip.chans(zk)+(snip.data(zk,:)/(maxo-mino)),'k-','tag',['waveplotsnippet',num2str(sk*100+zk)]);
                        hold(app.WavePlot,'on');
                        if (1)
                            hh = text(app.WavePlot,xt(2),snip.chans(zk)+0.4,sprintf('%d',app.MAP(snip.chans(zk))),'tag',['waveplottxt',num2str(sk*100+zk)]);
                            set(hh,'Fontsize',6);
                            set(hh,'Color',[0,0,1]);
                        end
                    end
                end
                %*********
                if (size(snippers,1) > 1)
                    msnipper = mean(snippers);
                    smsnipper = std(snippers);
                    %*******
                    app.SnipMino = mino;
                    app.SnipMaxo = maxo;
                    % load back to store
                    ominchan = app.SnipRange(1)-2;
                    %*******
                    h = plot(app.WavePlot,xt,ominchan+(2*msnipper/(maxo-mino)),'r-','tag','waveplotomnichanavg');
                    hold(app.WavePlot,'on');
                    set(h,'LineWidth',2);
                    set(h,'Color',app.UnitColor);
                    h = plot(app.WavePlot,xt,ominchan+(2*(msnipper+smsnipper)/(maxo-mino)),'r-','tag',['waveplotomnichanstdu']);
                    hold(app.WavePlot,'on');
                    set(h,'Color',app.UnitColor);
                    h = plot(app.WavePlot,xt,ominchan+(2*(msnipper-smsnipper)/(maxo-mino)),'r-','tag',['waveplotomnichanstdd']);
                    hold(app.WavePlot,'on');
                    set(h,'Color',app.UnitColor);
                    if ~isempty(app.Threshold)
                        val = ominchan+(2*app.Threshold/(maxo-mino));
                        h = plot(app.WavePlot,[xt(1),xt(end)],[val,val],'r--','tag','waveplotomnichanval');
                        set(h,'LineWidth',2);
                    end
                    if ~isempty(app.ThreshBox)
                        y1  = ominchan+(2*app.ThreshBox(3)/(maxo-mino));
                        y2  = ominchan+(2*app.ThreshBox(4)/(maxo-mino));
                        x1 = app.ThreshBox(1);
                        x2 = app.ThreshBox(2);
                        h = plot(app.WavePlot,[x1,x1,x2,x2,x1],[y1,y2,y2,y1,y1],'r-','tag','waveplotomnichanthreshbox');
                        set(h,'LineWidth',1);
                    end
                    if (1)
                        hh = text(app.WavePlot,xt(2),ominchan+1.2,sprintf('mean'),'tag','waveplottextmean');
                        set(hh,'Fontsize',8);
                        set(hh,'Color',[1,0,0]);
                        plot(app.WavePlot,xt,ominchan*ones(size(xt)),'k-','tag','waveplotomnichanones');
                    end
                    %*******
                    zz = find( msnipper == min(msnipper));  % trough time
                    plot(app.WavePlot,[xt(zz(1)),xt(zz(1))],[minchan,maxchan],'r-','tag','waveplotalign'); % align vert on trough
                    %******
                end
                xlabel(app.WavePlot,'Time (ms)');
                ylabel(app.WavePlot,'Voltage (normed)');
                axis(app.WavePlot,'tight');
                axis(app.WavePlot,'off');
            end
        end
        
        function [ThreshCnt, ThreshSnips, ThreshTimes, TMax] = run_threshold(app, SnipRange, Threshold, ThreshBox, NS, FullData, pca_vec)
            %********** returns list of waveforms cross threshold criteria
            %********** over channels SnipRange based on Threshold and Box
            %******* SnipRange - set of channels to use in threshold, min to max
            %******* Threshold - in voltage, at min for a trough
            %******* ThreshBox - if used, box wave must enter, else empty
            %******* NS - if not empty, use times listed included to identify waves
            %******* FullData - use full data stream, not sorting sample
            %******* PCAvec - contians cluster points with spikes?
            bef_period = floor(app.SnipPre/2);
            ref_period = floor(app.SnipPost/2);
            %*******
            zcnt = 0;
            %app.MAP = 8;


            %******* if using full data then have to load all of it first
            if (FullData)
                disp('Loading Raw Data to Threshold');
                SPdata = cell(1,(SnipRange(2)-SnipRange(1)+1));
                %[b,a] = butter(3, [((800/app.Fs)*2),((8000/app.Fs)*2)]);
                [b,a] = butter(6, [((300/app.Fs)*2), ((8000/app.Fs)*2)]);
                % When doing just one channel
                if app.CommonMode
                    data = app.spdata - app.avgspdata;
                else
                    data = app.spdata;
                end
                %******* if app.CommonMode and many channels, we have an
                %******* issue where we need the whole channel average ...
                %******* this would probably need to pass in as an arg
                for zk = 1:size(SPdata,2)
                    fprintf('Channel %d of %d\n',zk,size(SPdata,2));
                    kk = SnipRange(1)+zk-1;
                    if size(SPdata,2) == 1
                        imap = app.MAP;
                    else
                        imap = app.MAP(kk);
                    end
                    % tag = sprintf('*CH%d.continuous',imap);
                    % SpikeFiles = dir([app.FilePath,filesep,tag]);
                    
                    %SpikeFiles = [app.FilePath,app.FileName];
                    %[data,sptime,spinfo] = read_ephys.load_open_ephys_data(SpikeFiles);
                    %[data,avdata] = load_wav_channel(app,SpikeFiles,imap);
%                     data = app.spdata;
                    %**** Common mode rejection (for multi-chan record)
%                     if app.CommonMode && (zk <= app.ChanNums)
%                       data = app.spdata - app.avgspdata;
%                     end
                    %**** Band-pass filter ********
                    disp('Band-pass filtering channel ...');
%                     fdata = filter(b,a,data);   % note data orientation, Nx1
%                     fdata = flipud(fdata);
%                     fdata = filter(b,a,fdata);
%                     fdata = flipud(fdata);
                    fdata = filtfilt(b,a,data);
                    SPdata{1,zk} = fdata;  % fdata';
                    %**********
                end
                TMax = size(fdata,1);  %column vector in this return format
            else
                TMax = size(app.FDataSample,2);
            end
            %******* compute the average across chans ********
            for zk = SnipRange(1):SnipRange(2)
                %*******
                if (FullData)
                    kk = zk - SnipRange(1) + 1;
                    sdata = SPdata{1,zk}';
                else
                    sdata = app.FDataSample(zk,:);
                end
                %******
                if (zk == SnipRange(1))
                    zdata = sdata;
                else
                    zdata = zdata + sdata;
                end
                zcnt = zcnt + 1;
            end
            zdata = zdata / zcnt;
            %********** run thresholding on the average over chans ****
            if (isempty(NS))
                %******* then threshold the mean over channels
                %********** alternative will threshold from mean of channels
                if app.lookForPeaks
                    s = find( (zdata(2:end ) >= Threshold ) & ...
                    (zdata(1:(end - 1)) < Threshold) );
                else
                    s = find( (zdata(2:end ) < Threshold ) & ...
                    (zdata(1:(end - 1)) >= Threshold) );
                end
                
                if (~isempty(s))
                    s = s( (s > app.SnipPre) & (s < (size(zdata, 2) - app.SnipPost)) );    % remove spikes close to boundary
                    
                    slast = 0;
                    for j = 1:length(s)
                        if (s(j) < slast)
                            s(j) = NaN;
                        else
                            % Flipper function
                            if app.lookForPeaks
                                maxo = max(zdata(s(j):(s(j)+ref_period)));
                                z = find( zdata(s(j):(s(j)+ref_period)) == maxo);
                            else
                                mino = min(zdata(s(j):(s(j)+ref_period)));
                                z = find( zdata(s(j):(s(j)+ref_period)) == mino);
                            end
                            s(j) = s(j) + z(1);  % trough
                            slast = s(j) + ref_period;
                        end
                    end
                    z = find( ~isnan(s) );
                    s = s(z);
                end
                
                ss = s;
                %******* if a thresh box, then apply that criterium also ...
                if ~isempty(ThreshBox)
                    y1 = ThreshBox(3);  % max
                    y2 = ThreshBox(4);  % min
                    x1 = floor( ThreshBox(1) * (app.Fs/1000));
                    x2 = floor( ThreshBox(2) * (app.Fs/1000));
                    %******
                    ss = [];
                    for k = 1:size(s,2)
                        troa = s(k) + x1;
                        trob = s(k) + x2;
                        zdat = zdata(troa:trob);
                        
                        if app.lookForPeaks
                            zz = find( (zdat < y1) & (zdat >= y2) );
                        else
                            zz = find( (zdat >= y1) & (zdat <= y2) );
                        end
                        if ~isempty(zz)
                            ss = [ss s(k)];
                        end
                    end
                end
            else
                ss = NS;
            end
            %***** search crossings, find troughs/peaks, collect snipets
            if ~isempty(ss)
                %***************
                disp('Thresholding ...');
                ThreshCnt = size(ss,2);
                if isempty(pca_vec)
                    ThreshSnips = zeros(ThreshCnt,(zcnt*app.SnipLen));
                    TmpSnip = zeros(1,(zcnt*app.SnipLen));
                else
                    ThreshSnips = zeros(ThreshCnt,3);
                end
                ThreshTimes = zeros(1,ThreshCnt);
                for k = 1:ThreshCnt
                    %*******
                    tro = ss(k);
                    ThreshTimes(k) = tro;
                    %******* grab the snipet
                    ia = tro - app.SnipPre;
                    ib = tro + app.SnipPost;
                    for zk = SnipRange(1):SnipRange(2)
                        ja = 1 + ((zk - SnipRange(1))*app.SnipLen);
                        jb = ja + app.SnipLen - 1;
                        if (FullData)
                            kk = 1 + zk - SnipRange(1);
                            TmpSnip(1,ja:jb) = SPdata{1,kk}(ia:ib);
                        else
                            TmpSnip(1,ja:jb) = app.FDataSample(zk,ia:ib);
                        end
                    end
                    %****** store data
                    if isempty(pca_vec)
                        ThreshSnips(k,:) = TmpSnip;
                    else
                        ThreshSnips(k,:) = (pca_vec * TmpSnip')'; % PCA projection
                    end
                    %***********
                end
            else
                ThreshCnt = 0;
                ThreshSnips = [];
                ThreshTimes = [];
            end
        end
        
        function set_current_unit(app, myunit)
            %***** given a unit number, set to that one and update
            
            if (myunit > 0) && (myunit <= app.UnitTot)
                hdlg = uiprogressdlg(app.figure1,'message','Reloading unit ...');
                app.UnitNum = myunit;
                app.ChangeUnit = [];
                %*****************
                app.SnipCnt = app.UnitInfo{myunit}.SnipCnt;
                app.Snips = app.UnitInfo{myunit}.Snips;
                app.SnipMino = app.UnitInfo{myunit}.SnipMino;
                app.SnipMaxo = app.UnitInfo{myunit}.SnipMaxo;
                app.SnipRange = app.UnitInfo{myunit}.SnipRange;  % range of channels in current spike targets
                app.Threshold = app.UnitInfo{myunit}.Threshold;   % defined at the moment to spike sort
                app.ThreshBox = app.UnitInfo{myunit}.ThreshBox;   % defined at the moment to spike sort
                app.ThreshSnips = app.UnitInfo{myunit}.ThreshSnips; % matrix of spike waves (across channels)
                app.ThreshNull = app.UnitInfo{myunit}.ThreshNull;
                app.NullTimes = app.UnitInfo{myunit}.NullTimes;
                app.ThreshTimes = app.UnitInfo{myunit}.ThreshTimes;
                app.ThreshCnt = app.UnitInfo{myunit}.ThreshCnt;   % number of threshold crossings
                app.ClusterPts = app.UnitInfo{myunit}.ClusterPts;  % points in a PCA space (pca 1, pca 2, time)
                app.ClusterVec = app.UnitInfo{myunit}.ClusterVec;
                app.ClusterSet = app.UnitInfo{myunit}.ClusterSet;
                app.ClusterState = app.UnitInfo{myunit}.ClusterState;
                app.UnitColor = app.UnitInfo{myunit}.UnitColor;
                app.Isolation = app.UnitInfo{myunit}.Isolation;
                app.AutoIso = app.UnitInfo{myunit}.AutoIso;
                % app.FlagTimes = app.UnitInfo{myunit}.FlagTimes;
                app.IsoText.Value = num2str(app.Isolation);
                app.lookForPeaks = app.UnitInfo{myunit}.lookForPeaks;
                
                %******************
                
                update_thresh_used(app);  % which units are taken may have changed
                
                close(hdlg);
                %************n
                plot_raw_spikes(app);
                plot_waveforms(app);
                plot_average_waveforms(app);
                plot_cluster(app);
                plot_snipets(app);
                plot_isi(app,[],[],true);
            else
                if (myunit == 0)
                    ResetSpike_Callback(app,[]);
                else
                    app.CurrentUnit.Value = num2str(app.UnitNum);
                    
                end
            end
        end
        
        function threshold_spikes(app)
            
            %*****************************************
            bef_period = app.SnipPre;
            ref_period = app.SnipPost;
            %**** first, compute the mean across channels
            if ~isempty(app.SnipRange) && ~isempty( app.Threshold )
                %**********
                hdlg = uiprogressdlg(app.figure1,'message','Thresholding spike crossings ...');
                app.ThreshCnt = 0;
                app.ThreshSnips = [];
                app.ThreshNull = [];
                app.ThreshUsed = [];
                %******* must also reset the cluster info
                if ~isempty(app.ClusterPts)
                    clear app.ClusterPts;
                end
                app.ClusterPts = [];  % trough time, points in a PCA space(pca 1+2)
                app.ClusterVec = [];  % prin comp vectors on which points are based
                app.ClusterSet = [];  %
                %**********
                [C,S,T] = run_threshold(app,app.SnipRange,app.Threshold,...
                    app.ThreshBox,[],0,[]);
                app.ThreshCnt = C;
                app.ThreshSnips = S;
                app.ThreshTimes = T;
                %********
                zn = size(app.FDataSample,2);
                ns = bef_period+floor( rand(1,app.NullN) * (zn-bef_period-ref_period-1));
                [C,S,T] = run_threshold(app,app.SnipRange,app.Threshold,...
                    app.ThreshBox,ns,0,[]);
                app.ThreshNull = S;
                app.NullTimes = T;
                %***********
                close(hdlg);
            end
            
            update_thresh_used(app);
        end
        
        function update_thresh_used(app)
            %*****************************************
            ref_period2 = floor( app.SnipPost/2);
            %**** first, compute the mean across channels
            if ~isempty(app.SnipRange) && ~isempty( app.Threshold )
                %**********
                hdlg = uiprogressdlg(app.figure1,'message','Updating thresh used ...');
                %********* make a list of all previous selected spike times so
                %**** you can mark those as used (ThreshUsed) if within refractory
                %**** of newly selected ones
                sptimes = [];
                spunits = [];
                %****** any previous units
                if (app.UnitTot > 0)
                    for k = 1:app.UnitTot
                        clust = app.UnitInfo{k}.ClusterSet;
                        tims = app.UnitInfo{k}.ThreshTimes';
                        sptimes = [sptimes ; tims(clust)];
                        spunits = [spunits ; (k*ones(size(tims(clust))))];
                    end
                end
                %*****************
                if ~isempty(sptimes)
                    for k = 1:app.ThreshCnt
                        dist = abs( app.ThreshTimes(k) - sptimes);
                        if ( min(dist) < ref_period2)
                            zz = find( dist == min(dist));
                            app.ThreshUsed = [app.ThreshUsed ; [k spunits(zz(1))] ];
                        end
                    end
                else
                    app.ThreshUsed = [];
                end
                close(hdlg);
            end
            
        end
        
        function fig_handle = copyUIAxesToAxis(app,axFrom)
        % This function will take a given uiaxes object and create a new
        % figure and fill that figure with a replot of the axes data and
        % information. The new figure can then be used for ginput functions
        % and return that data as necessary. Afterwards, the figure should
        % be deleted.
            fig_handle = figure();
            axTo = axes(fig_handle);
            % Custom version of copyobj() which works to copy a UIAxes object to
            % an old-school axes object.
            
            % Copy children (lines).
            copyobj(axFrom.Children, axTo);
            % Copy titles and labels.
            copyobj([axFrom.Title axFrom.XLabel axFrom.YLabel], axTo)
            
            % Transfer other properties.
            uiAxParams = get(axFrom);
            uiAxParamNames = fieldnames(uiAxParams);
            % Get list of editable params in new axis
            editableParams = fieldnames(set(axTo));
            % Remove the UIAxes params that aren't editable in the new axes (add others you don't want)
            badFields = uiAxParamNames(~ismember(uiAxParamNames, editableParams));
            badFields = [badFields; 'Parent'; 'Children'; 'XAxis'; 'YAxis'; 'ZAxis'; ...
            'Position'; 'OuterPosition'; 'XLabel'; 'YLabel'; 'ZLabel'; 'Title'];
            uiAxGoodParams = rmfield(uiAxParams,badFields);
            % set editable params on new axes
            set(axTo, uiAxGoodParams);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Put this here so i know where to change it.
            % Originally at 30000
            app.Fs = 20000;      % data sampling rate
            if (app.Initialized == 0)  % only call first time?
                %***********
                MarmoSpike_Init(app);
            end
            % Update handles structure
            plot_raw_spikes(app)
            plot_waveforms(app);
            plot_average_waveforms(app);
            plot_cluster(app);
            plot_isi(app,[],[],true);
            plot_snipets(app);
            pause(2)
            app.figure1.WindowState = 'maximized';
            app.CommonMode = app.CommonModeRejectionCheckBox.Value;
        end

        % Button pushed function: AddUnit
        function AddUnit_Callback(app, event)
            % hObject    handle to AddUnit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %***** update stored information
            
            if isempty(app.ClusterSet)
                uialert(app.figure1,'message','Select Cluster First');
                return;
            end
            if (app.SetIso == 0)
                uialert(app.figure1,'message','Set Isolation Before Adding');
                return;
            else
                app.SetIso = 0;
            end
            %********
            if (app.UnitNum == 0)  % adding a completely new unit
                app.UnitTot = app.UnitTot + 1;
                app.UnitNum = app.UnitTot;  % add a new unit
                %***************
                if ~isempty(app.ClearThreshSnips)
                    %***** release stored memory
                    clear app.ClearThreshSnips;
                    clear app.ClearThreshNull;
                    %*********
                    app.ClearThreshSnips = [];
                    app.ClearThreshNull = [];
                end
                %***********
                it = mod(app.ColorCnt,8)+2;
                app.ColorCnt = app.ColorCnt + 1;
                app.UnitColor =  app.cmap(it,:);
            end   % otherwise just updaing a current unit
            %***********
            
            Store_Update_Unit(app);
        end

        % Button pushed function: BackWard
        function BackWard_Callback(app, event)
            % hObject    handle to BackWard (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            hold(app.RawPlot,'off');
            if (app.TimeStart >= 0)
                app.TimeStart = max(0,(app.TimeStart - (app.TimeScale/2)));
            end
            
            
            plot_raw_spikes(app);
        end

        % Button pushed function: BigBackWard
        function BigBackWard_Callback(app, event)
            % hObject    handle to BigBackWard (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            hold(app.RawPlot,'off');
            if (app.TimeStart > app.BigTimeScale)
                app.TimeStart = app.TimeStart - app.BigTimeScale;
                
                
                plot_raw_spikes(app);
            else
                uialert(app.figure1,'message','At minimum time, use fine increments');
            end
        end

        % Button pushed function: BigForward
        function BigForward_Callback(app, event)
            % hObject    handle to BigForward (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            hold(app.RawPlot,'off');
            if (app.TimeStart < ((app.DataSegNum * app.DataLen) - app.BigTimeScale - app.TimeScale))
                app.TimeStart = app.TimeStart + app.BigTimeScale;
                
                
                plot_raw_spikes(app)
            else
                uialert(app.figure1,'message','At maximum time, use fine increments');
            end
        end

        % Button pushed function: ClearArtifacts
        function ClearArtifacts_Callback(app, event)
            
            if isempty(app.ThreshSnips)
                uialert(app.figure1,'No spike thresholded yet','Warning','icon','warning');
                return;
            else
%                 fig_handle_for_input = copyUIAxesToAxis(app,app.SnipetPlot);
%                 [xv,yv] = ginput(4);
%                 delete(fig_handle_for_input);
                setPointInput(app,app.SnipetPlot,'clearartifacts',4)
%                 xv = [xv ; xv(1)];
%                 yv = [yv ; yv(1)];
%                 hdlg = uiprogressdlg(app.figure1,'message','Removing waveforms','Indeterminate','on');
%                 %********* find any snipets that intersect the line
%                 %********* and set them to zero
%                 zN = app.SnipRange(2) - app.SnipRange(1) + 1;
%                 xt = (-app.SnipPre:app.SnipPost) * (1000/app.Fs);
%                 mino = app.SnipMino;
%                 maxo = app.SnipMaxo;
%                 %****** store cleared structs in case you need to undo
%                 if isempty(app.ClusterSet)
%                     app.ClearThreshSnips = app.ThreshSnips;
%                     app.ClearThreshNull = app.ThreshNull;
%                     app.ClearClusterPts = app.ClusterPts;
%                 end
%                 %******
%                 upunit = 0;
%                 for k = 1:(size(app.ThreshSnips,1)+size(app.ThreshNull,1))
%                     if (k <= size(app.ThreshSnips,1))
%                         znip = reshape( app.ThreshSnips(k,:), [app.SnipLen, zN])';
%                     else
%                         kk = k - size(app.ThreshSnips,1);
%                         znip = reshape( app.ThreshNull(kk,:), [app.SnipLen, zN])';
%                     end
%                     ixt = [];
%                     iyt = [];
%                     for zk = 1:zN
%                         ixt = [ixt ; xt'];
%                         iyt = [iyt ; (zk+(znip(zk,:)/(2*(maxo-mino))))'];
%                     end
%                     tag = inpolygon(ixt,iyt,xv,yv);
%                     if (sum(tag) > 0)
%                         if isempty(app.ClusterSet)
%                             %********* zero out identified waveforms
%                             if (k <= size(app.ThreshSnips,1))
%                                 app.ThreshSnips(k,:) = 0;
%                                 it = app.ThreshTimes(k);
%                             else
%                                 kk = k - size(app.ThreshSnips,1);
%                                 app.ThreshNull(kk,:) = 0;
%                                 it = app.NullTimes(kk);
%                             end
%                             if ~isempty(app.ClusterPts)
%                                 app.ClusterPts(k,:) = 0; % set to origin
%                             end
%                             %**** eliminate them from raw data record too?
%                             igo = it - app.SnipPre;
%                             ifi = it + app.SnipPost;
%                             chans = app.SnipRange(1):app.SnipRange(2);
%                             app.ClearCnt = app.ClearCnt + 1;
%                             app.ClearList{app.ClearCnt}.chans = chans;
%                             app.ClearList{app.ClearCnt}.times = igo:ifi;
%                             %***************
%                         else
%                             if (k <= size(app.ThreshSnips,1))
%                                 z = find(app.ClusterSet == k);
%                                 if ~isempty(z)
%                                     zz = [1:(z(1)-1),(z(1)+1):size(app.ClusterSet,1)];
%                                     app.ClusterSet = app.ClusterSet(zz);
%                                     upunit = 1;
%                                 end
%                             end
%                             
%                         end
%                     end
%                 end
%                 %************
%                 if (upunit)
%                     if (app.UnitNum > 0)
%                         
%                         Store_Update_Unit(app);
%                     end
%                 end
%                 
%                 close(hdlg);
%                 %***********
%                 if ~isempty(app.ClusterPts)  % recompute clusters after removal
%                     plot_cluster(app);
%                 end
%                 plot_snipets(app);
%                 plot_isi(app,[],[]);
            end
        end

        % Button pushed function: ClearCluster
        function ClearCluster_Callback(app, event)
            
            if isempty(app.ClusterPts)
                uialert(app.figure1,'No clusters yet','Warning','icon','warning');
                return;
            else
%                 fig_handle_for_input = copyUIAxesToAxis(app,app.ClusterPlot);
%                 [xv,yv] = ginput(4);
%                 delete(fig_handle_for_input);
                setPointInput(app, app.ClusterPlot,'clearcluster',4);
%                 xv = [xv ; xv(1)];
%                 yv = [yv ; yv(1)];
%                 hdlg = uiprogressdlg(app.figure1,'Message','Removing points and their waveforms','Indeterminate','on');
%                 %********* find points in cluster plot to remove
%                 [ix,iy] = axes_from_cluster_state(app);
%                 fxx = app.ClusterPts(:,ix);
%                 fyy = app.ClusterPts(:,iy);
%                 taggers = inpolygon(fxx,fyy,xv,yv);
%                 remset = find( taggers == 1);
%                 %****** store cleared structs in case you need to undo
%                 if isempty(app.ClusterSet)
%                     app.ClearThreshSnips = app.ThreshSnips;
%                     app.ClearThreshNull = app.ThreshNull;
%                     app.ClearClusterPts = app.ClusterPts;
%                     %******
%                     for zk = 1:size(remset,1)
%                         k = remset(zk);
%                         %********* zero out identified waveforms
%                         if (k <= size(app.ThreshSnips,1))
%                             app.ThreshSnips(k,:) = 0;
%                             it = app.ThreshTimes(k);
%                         else
%                             kk = k - size(app.ThreshSnips,1);
%                             app.ThreshNull(kk,:) = 0;
%                             it = app.NullTimes(kk);
%                         end
%                         app.ClusterPts(k,:) = 0; % set to origin
%                         %**** eliminate them from raw data record too?
%                         igo = it - app.SnipPre;
%                         ifi = it + app.SnipPost;
%                         chans = app.SnipRange(1):app.SnipRange(2);
%                         app.ClearCnt = app.ClearCnt + 1;
%                         app.ClearList{app.ClearCnt}.chans = chans;
%                         app.ClearList{app.ClearCnt}.times = igo:ifi;
%                         %***************
%                     end
%                 else   % otherwise just remove from cluster set
%                     upunit = 0;
%                     for zk = 1:size(remset,1)
%                         z = find(app.ClusterSet == remset(zk));
%                         if ~isempty(z)
%                             zz = [1:(z(1)-1),(z(1)+1):size(app.ClusterSet,1)];
%                             app.ClusterSet = app.ClusterSet(zz);
%                             upunit = 1;
%                         end
%                     end
%                     if (upunit)
%                         if (app.UnitNum > 0)
%                             
%                             Store_Update_Unit(app);
%                         end
%                     end
%                 end
%                 %************
%                 
%                 close(hdlg);
%                 %***********
%                 if ~isempty(app.ClusterPts)  % reset clusters
%                     plot_cluster(app);
%                 end
%                 plot_snipets(app);
%                 plot_isi(app,[],[]);
            end
        end

        % Selection changed function: ClusterAxes
        function ClusterAxes_SelectionChangedFcn(app, event)
            
            % Choose one of the following options for plotting
            switch event.NewValue.Text
                case 'PCA1,2'
                    app.ClusterState = 1;
                case 'PCA1,3'
                    app.ClusterState = 2;
                case 'PCA2,3'
                    app.ClusterState = 3;
                case 'PCA1,Time'
                    app.ClusterState = 4;
                case 'PCA2,Time'
                    app.ClusterState = 5;
                case 'PCA3,Time'
                    app.ClusterState = 6;
            end
            
            if ~isempty(app.ClusterPts)
                plot_cluster(app);
            end
        end

        % Button pushed function: ClusterSpike
        function ClusterSpike_Callback(app, event)
            %*************
            if isempty(app.ThreshSnips)
                uialert(app.figure1,'No waveforms to cluster yet ...','Warning','icon','warning');
            else
                hdlg = uiprogressdlg(app.figure1,'message','Computing PCA from thresholded','Indeterminate','on');
                %*********
                tsnips = app.ThreshSnips;
                [c,s,la] = pca(tsnips);
                clear tsnips;
                app.ClusterVec = c(:,1:3)';
                app.ClusterPts = [s(:,1:3) app.ThreshTimes'];
                s = app.ClusterVec * app.ThreshNull';
                app.ClusterPts = [app.ClusterPts ; [s' app.NullTimes']];
                %**********
                
                close(hdlg);
                %*************
                plot_cluster(app);
            end
        end

        % Button pushed function: ClusterTemplate
        function ClusterTemplate_Callback(app, event)
            %*************
            if isempty(app.ThreshSnips)
                uialert(app.figure1,'message','No waveforms to cluster yet ...','title','Warning ClusterTemplate_Callback','icon','warning');
            else
                if (app.SnipCnt < 5)
                    uialert(app.figure1,'Must select at least 5 example waveforms','Warning ClusterTemplate_CallBack','icon','warning');
                    return;
                else
                    hdlg = uiprogressdlg(app.figure1,'message','Computing PCA from your templates','Indeterminate','off');
                    %*************
                    zN = app.SnipRange(2) - app.SnipRange(1) + 1;
                    tsnips = zeros(app.SnipCnt, (zN*app.SnipLen) );
                    for k = 1:app.SnipCnt
                        tsnips(k,:) = reshape(app.Snips{k}.data,[app.SnipLen,zN])';
                    end
                    [c,s,la] = pca(tsnips);
                    clear tsnips;
                    app.ClusterVec = c(:,1:3)';
                    s = ( app.ClusterVec * app.ThreshSnips' )';
                    app.ClusterPts = [s(:,1:3) app.ThreshTimes'];
                    s = app.ClusterVec * app.ThreshNull';
                    app.ClusterPts = [app.ClusterPts ; [s' app.NullTimes']];
                    %**********
                    
                    close(hdlg);
                    %*************
                    plot_cluster(app);
                end
            end
        end

        % Value changed function: CurrentUnit
        function CurrentUnit_Callback(app, event)
            % hObject    handle to CurrentUnit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of CurrentUnit as text
            %        str2double(get(hObject,'String')) returns contents of CurrentUnit as a double
            if ~isempty(app.ChangeUnit)
                myunit = str2double(app.ChangeUnit);
            else
                myunit = str2double(app.CurrentUnit.Value);
            end
            set_current_unit(app,myunit);
        end

        % Button pushed function: DropSpike
        function DropSpike_Callback(app, event)
            %axes(app.RawPlot);
            %fig_handle_for_input = copyUIAxesToAxis(app,app.RawPlot);
            setPointInput(app, app.RawPlot,'dropspike', 2)
%             %[xv,yv] = ginput(2);
%             delete(fig_handle_for_input);
%             drop_spike(app,xv,yv);
%             
%             plot_raw_spikes(app);
%             plot_waveforms(app);
%             plot_average_waveforms(app);
        end

        % Button pushed function: DropUnit
        function DropUnit_Callback(app, event)
            if (app.UnitTot >= 1) && (app.UnitNum >= 1)
                hdlg = uiprogressdlg(app.figure1,'message','Clearing unit ...','Indeterminate','on');
                %********
                clear app.UnitInfo{app.UnitNum};
                for k = app.UnitNum:(app.UnitTot-1)
                    app.UnitInfo{k} = app.UnitInfo{k+1};
                end
                app.UnitTot = app.UnitTot - 1;
                app.UnitNum = 0;
                app.SetIso = 0;
                %******* current unit becomes next potential unit
                app.CurrentUnit.Value = num2str(app.UnitNum);
                app.TotUnit.Text = num2str(app.UnitTot);
                %***********
                
                update_thresh_used(app);
                close(hdlg);
                %**********
                ResetPoints_Callback(app,[]);
                plot_waveforms(app);
                plot_average_waveforms(app);
                %**********
            else
                uialert(app.figure1,'Select Unit first ...','DropUnit_Callback','icon','info');
            end
        end

        % Button pushed function: FinalizeClear
        function FinalizeClear_Callback(app, event)
            if (app.ClearCnt > 0)
                hdlg = uiprogressdlg(app.figure1,'message','Clearing from raw data','value',0);
                for k = 1:app.ClearCnt
                    chans = app.ClearList{app.ClearCnt}.chans;
                    times = app.ClearList{app.ClearCnt}.times;
                    app.FDataSample(chans,times) = 0;  % blank away raw data
                    hdlg.Value = k/app.ClearCnt;
                end
                hdlg.Value = 0.99;
                pause(2);
                close(hdlg);
            end
            
            plot_raw_spikes(app);
        end

        % Button pushed function: Forward
        function Forward_Callback(app, event)
            %axes(app.RawPlot); 
            hold(app.RawPlot,'off');
            if (app.TimeStart < ((app.DataSegNum * app.DataLen)-app.TimeScale))
                app.TimeStart = min((app.TimeStart + (app.TimeScale/2)),...
                    ((app.DataSegNum * app.DataLen)-app.TimeScale));
            end
            
            
            plot_raw_spikes(app);
        end

        % Button pushed function: GrabUnit
        function GrabUnit_Callback(app, event)
            if app.UnitTot > 0
                app.ChangeUnit = [];
                %axes(app.RawPlot);
                %fig_handle_for_input = copyUIAxesToAxis(app,app.RawPlot);
                %[xv,yv] = ginput(2);
                setPointInput(app,app.RawPlot, 'grabunit', 2)
%                 delete(fig_handle_for_input);
%                 hdlg = uiprogressdlg(app.figure1,'message','Searching for unit number ...','title','GrabUnit_Callback');
%                 %**** search all sorted units to identify which unit matches the spike
%                 %**** time and channels selected (if more than one, take first found)
%                 foundunit = 0;
%                 for k = 1:app.UnitTot
%                     hdlg.Value = k/app.UnitTot;
%                     set = app.UnitInfo{k}.ClusterSet;
%                     times = app.UnitInfo{k}.ThreshTimes(set) * (1/app.Fs);  % times
%                     zz = find( (times >= xv(1)) & (times <= xv(end)) );
%                     if isempty(zz)
%                         continue;
%                     else
%                         chans = mean(app.UnitInfo{k}.SnipRange);
%                         if ( (chans >= min(yv)) && (chans <= max(yv)) )
%                             foundunit = k;
%                             break;
%                         else
%                             continue;
%                         end
%                     end
%                 end
%                 close(hdlg);
%                 %******* once found, call CurrentUnit with arg to set to that unit
%                 if (foundunit)
%                     app.ChangeUnit = num2str(foundunit);
%                     
%                     CurrentUnit_Callback(app,[])
%                 end
            end
        end

        % Value changed function: IsoText
        function IsoText_Callback(app, event)
            % hObject    handle to IsoText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of IsoText as text
            %        str2double(get(hObject,'String')) returns contents of IsoText as a double
            
            app.Isolation = str2double(app.IsoText.Value);
            app.SetIso = 1;
            
        end

        % Button pushed function: LoadSort
        function LoadSort_Callback(app, event)
            % hObject    handle to LoadSort (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            [fname,pathname]= uigetfile('*_Sort.mat','File Selector');
            if (pathname ~= 0)
                
                %********* now try to load in that data
                hold(app.RawPlot,'off'); 
                axis(app.RawPlot,'off');
                
                hdlg = uiprogressdlg(app.figure1,'message','Loading data file ...','title','LoadSort_Callback','Indeterminate',"on");
                %******* try to load store info struct
                filename = [pathname,fname];
                if ~exist(filename)
                    disp('Unable to load file');
                    return;
                else
                    disp('Loading sorting parameters ...');
                    load(filename);
                end
                %**********
                app.FDataSample = info.FDataSample;
                app.DataSample = info.DataSample;
                app.FilePath = info.FilePath;
                app.FileName = info.FileName;
                app.CommonMode = info.CommonMode;  % use common mode rejection
                app.DM = info.DM;
                app.ChanNums = info.ChanNums;
                app.ChangeUnit = info.ChangeUnit;
                app.ColorCnt = info.ColorCnt;
                app.SetIso = 0;
                %***********
                app.TimeStart = info.TimeStart; % earliest time
                app.TimeScale = info.TimeScale; % length of horizontal on raw plot
                app.BigTimeScale = info.BigTimeScale; % length for large steps in time
                app.DataSegNum = info.DataSegNum; % chunks to look through, even spaced over file
                app.DataLen = info.DataLen;    % duration in secs
                app.Fs = info.Fs;      % data sampling rate
                app.NullN = info.NullN;
                %**********
                app.SnipCnt = info.SnipCnt;        % user selected waveforms
                app.Snips = info.Snips;  % snipets
                app.SnipPre = info.SnipPre;  % ms before trough
                app.SnipPost = info.SnipPost; % ms after trough
                app.SnipLen = info.SnipLen;
                app.SnipMino = info.SnipMino;
                app.SnipMaxo = info.SnipMaxo;
                app.SpikeRange = info.SpikeRange;
                app.SnipRange = info.SnipRange;  % range of channels in current spike targets
                app.MAP = info.MAP;
                %*************************
                app.Threshold = info.Threshold;   % defined at the moment to spike sort
                app.ThreshBox = info.ThreshBox;
                app.ThreshSnips = info.ThreshSnips; % matrix of spike waves (across channels)
                app.ThreshUsed = info.ThreshUsed;
                app.ThreshNull = info.ThreshNull;
                app.NullTimes = info.NullTimes;
                app.ThreshTimes = info.ThreshTimes;
                app.ThreshCnt = info.ThreshCnt;   % number of threshold crossings
                app.ClusterPts = info.ClusterPts;  % points in a PCA space (pca 1, pca 2, time)
                app.ClusterVec = info.ClusterVec;
                app.ClusterSet = info.ClusterSet;
                app.ClusterState = info.ClusterState;
                app.UnitColor = info.UnitColor;
                app.Isolation = info.Isolation;
                app.AutoIso = info.AutoIso;
                app.FlagTimes = info.FlagTimes;
                %******************
                app.UnitNum = info.UnitNum;
                app.UnitTot = info.UnitTot;
                app.UnitInfo = info.UnitInfo;
                %****************
                app.CurrentUnit.Value = num2str(app.UnitNum);
                app.TimeEdit.Value = num2str(app.TimeScale);
                app.TotUnit.Text = num2str(app.UnitTot);
                app.IsoText.Value = num2str(app.Isolation);
                %****************
                app.listOfFiles = info.listOfFiles; % Locations of where they are...
                app.numFilesCombined = info.numFilesCombined; % How many files were combined
                app.timeCutOffs = info.timeCutOffs; % Sample index of start of each session
                %Load back up all the original data to boot...
                [app.spdata,app.avgspdata] = load_wav_channel(app,[app.FilePath,app.FileName],app.MAP);
                for zk = 1:app.UnitTot
                    % if info doesn't have lookForPeaks then set it to
                    % bottom
                    if ~isfield(info.UnitInfo{zk},'lookForPeaks')
                        app.UnitInfo{zk}.lookForPeaks = false;
                        beep
                    end
                end;
                %*************************
                clear info;
                close(hdlg);
                disp('Parameters loaded.');
                %*************
                app.filelist.Text = [app.FilePath,app.FileName];
                app.TimeEdit.Value = sprintf('%4.1f',app.TimeScale);
                
                
                %******** store to file that can be reloaded
                
                
                plot_raw_spikes(app);
                plot_waveforms(app);
                plot_average_waveforms(app);
                app.CHLabel.Text = sprintf('CH: %d',app.MAP);
            end
        end

        % Button pushed function: MergeAccept
        function MergeAccept_Callback(app, event)
            % hObject    handle to MergeAccept (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if ~isempty(app.LastMerged)
                hdlg = uiprogressdlg(app.figure1,'message','Dropping original merged units','title','MergeAccept_Callback','Indeterminate','off');
                % if accepting, then DropUnit for the two going into merge
                newunit = app.UnitNum;
                z = find( app.LastMerged < newunit);
                newunit = newunit - size(z,2);
                %**************
                set_current_unit(app, app.LastMerged(1));
                
                DropUnit_Callback(app,[]);
                
                set_current_unit(app, app.LastMerged(2));
                
                DropUnit_Callback(app,[]);
                
                app.LastMerged = [];
                
                close(hdlg);
                set_current_unit(app,newunit);
                %%**************
            end
        end

        % Value changed function: MergeUnitNum
        function MergeUnitNum_Callback(app, event)
            % hObject    handle to MergeUnitNum (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of MergeUnitNum as text
            %        str2double(get(hObject,'String')) returns contents of MergeUnitNum as a double
            
            %app.MergeNum = str2double(get(hObject,'String'));
            app.MergeNum = str2double(app.MergeUnitNum.Value);
        end

        % Button pushed function: MergeUnit
        function MergeUnit_Callback(app, event)
            % hObject    handle to MergeUnit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %******* Merge Unit will Add to a New Unit the current, plus merge the
            %******* other, and then user can accept it and delete the other options
            %********** so the user has to keep track to drop the other two if merged
            %*************
            
            if (app.MergeNum > 0) && (app.MergeNum <= app.UnitTot)
                if (app.UnitNum > 0) && (app.UnitNum <= app.UnitTot)
                    if (app.UnitNum ~= app.MergeNum)
                        iSnipRange = app.UnitInfo{app.MergeNum}.SnipRange;
                        if ( (app.SnipRange(1) == iSnipRange(1)) && ...
                                (app.SnipRange(2) == iSnipRange(2)) )
                            %*** store which two are being merged
                            app.LastMerged = [ app.UnitNum app.MergeNum];
                            %*** basically would run add unit of current unit first
                            app.UnitNum = 0; % reset this as if new sort
                            app.SetIso = 1;  % don't require to reset iso
                            
                            AddUnit_Callback(app,[]); %add as new unit
                            
                            %******* and then would grab other info to append to it
                            info = app.UnitInfo{app.MergeNum};
                            %*** pick lowest threshold
                            if (info.Threshold < app.Threshold)
                                app.Threshold = info.Threshold;
                            end
                            %*** append new waveforms from merged unit
                            Tcnt = app.ThreshCnt;
                            cset = info.ClusterSet;  % only add others spikes
                            Mcnt = size(cset,1);
                            app.ThreshSnips = [app.ThreshSnips ; info.ThreshSnips(cset,:)];
                            app.ClusterSet = [app.ClusterSet ; ((Tcnt+1):(Tcnt+Mcnt))'];
                            tmpClust = app.ClusterPts((Tcnt+1):end,:);
                            %********
                            app.ThreshTimes = [app.ThreshTimes(1:Tcnt) ...
                                info.ThreshTimes(cset)];
                            %****** note, you must project their snips into the same
                            %****** PCA vector space ....
                            s = app.ClusterVec * info.ThreshSnips(cset,:)';
                            ClustPts = [s' info.ThreshTimes(cset)'];
                            %***********************
                            app.ClusterPts = [app.ClusterPts(1:Tcnt,:) ; ...
                                ClustPts ; ...
                                tmpClust];
                            app.ThreshCnt = Tcnt+Mcnt;
                            %*******
                            app.MergeNum = 0;  % reset merge to zero
                            app.MergeUnitNum.Value = num2str(app.MergeNum);
                            %**********
                            
                            %******** update the unit, how it is stored
                            Store_Update_Unit(app); %update
                        else
                            uialert(app.figure1,'title','MergeUnit_Callback','message','Channel range not the same for merge units','icon','error');
                        end
                    else
                        uialert(app.figure1,'title','MergeUnit_Callback','message','Unit Num same as Merge Num','icon','error');
                    end
                else
                    uialert(app.figure1,'title','MergeUnit_Callback','message','Requires current unit selected','icon','error');
                end
            else
                uialert(app.figure1,'title','MergeUnit_Callback','message','Requires valid merge unit selected','icon','error');
            end
        end

        % Button pushed function: NewSort
        function NewSort_Callback(app, event)
            % hObject    handle to NewSort (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % First one is for open ephys
            %[filename,pathname]= uigetfile('*.continuous','File Selector');
            % This is for my wav files
            [filename,pathname]= uigetfile('*n.wav','File Selector','MultiSelect','on');
            if (pathname ~= 0)
                if iscell(filename) % Multiple files chosen
                    app.FilePath = pathname;
                    app.FileName = filename{1}; % Just use the first one
                    app.listOfFiles = filename; % Locations of where they are...
                    app.numFilesCombined = numel(filename); % How many files were combined
                    app.timeCutOffs = []; % Sample index of start of each session
                else % JUst one and treat it as such
                    app.FilePath = pathname;
                    app.FileName = filename;
                    app.listOfFiles = {filename}; % Locations of where they are...
                    app.numFilesCombined = 1; % How many files were combined
                    app.timeCutOffs = [1]; % Sample index of start of each session
                end
                
                mypathname = [app.FilePath,app.FileName];
                app.filelist.Text = mypathname;
                
                disp(mypathname)
                disp(app.FilePath)
                disp(app.FileName)
                disp(pwd)
                
                app.Isolation = 0;
                app.AutoIso = 0;
                app.IsoText.Value = num2str(app.Isolation);
                
                %********* now try to load in that data
                hold(app.RawPlot,'off');
                plot(app.RawPlot,0,0,'k.','Tag','rawplotempty');
                text(app.RawPlot,0,0,'Loading raw data .....'); 
                axis(app.RawPlot,'off');
                %********* if older memory, clear it and reset
                if ~isempty(app.FDataSample)
                    clear app.DataSample;
                    clear app.FDataSample;
                    clear app.MAP;
                    tpath = app.FilePath;
                    tname = app.FileName;
                    MarmoSpike_Init(app);
                    app.FilePath = tpath;
                    app.FileName = tname;
                end
                
                %*********
                load_and_filter_data_sample(app);  % will load from directory
                
                plot_raw_spikes(app);
                %*********************************
            end
            
        end

        % Button pushed function: ProcessUnits
        function ProcessUnits_Callback(app, event)
           
            
            %********** perform filtering of raw signal
            % problem with Tpca ... how computed?  Should be derived from
            fileName = inputdlg('Name of file to save spikes','File name required');
            if strcmp(fileName,'')
                fileName = app.FileName;
            end
            
            %***** first determine how many continuous channels in the directory
            hdlg = uiprogressdlg(app.figure1,'message','Isolating units to export ...','title','ProcessUnits_Callback','Value',0);
            oldLookForPeaks = app.lookForPeaks;
            for zk = 1:app.UnitTot
                %******** grab key info from unit
                range = app.UnitInfo{zk}.SnipRange;
                app.lookForPeaks = app.UnitInfo{zk}.lookForPeaks;
                nchans = range(2) - range(1) + 1;
                mchans = range(1):range(2);
                thresh = app.UnitInfo{zk}.Threshold;
                cset = app.UnitInfo{zk}.ClusterSet;
                spks = app.UnitInfo{zk}.ThreshSnips(cset,:);
                cvec = app.UnitInfo{zk}.ClusterVec;
                cpts = (cvec * spks')';
                ctims = app.UnitInfo{zk}.ThreshTimes(cset);
                spkcnt = size(spks,1);
                colo = app.UnitInfo{zk}.UnitColor;
                maxo = app.UnitInfo{zk}.SnipMaxo;
                mino = app.UnitInfo{zk}.SnipMino;
                xt = (-app.SnipPre:app.SnipPost)*(1000/app.Fs); % is ms
                len = app.SnipLen;
                %*********** average data channels and threshold samples *********
                hdlg.Value = (zk-0.8)/app.UnitTot;
                %   the UnitInfo{zk} struct, no??
                if (1)   % use the full data stream
                    [TCnt,Tpca,TTimes,TMax] = run_threshold(app,...
                        range,thresh,[],[],1,cvec);
                else     % sanity check on snipet stream, should be similar
                    [TCnt,Tpca,TTimes,TMax] = run_threshold(app,...
                        range,thresh,[],[],0,cvec);
                end
                
                hdlg.Value = (zk-0.5)/app.UnitTot;
                %****** strech time vector, match snips and full
                ctims = ceil(ctims  * (TMax/size(app.FDataSample,2)));
                cpts = [cpts ctims'];
                %***** when called with pca_vec, it returns pca pts in TSnips
                fprintf('Identified %d threshold events ...\n',TCnt(1));
                %***** now process all events to categorize as within cluster
                fprintf('Categorizing threshold events to include in cluster...\n');
                fset = [];
                DistThresh = 1.0;  % must be within mean dist of the cluster
                CN = 5;
                TimeLim = (TMax/(2*app.DataSegNum));
                %*********
                bestdist = Inf * ones(1,CN);
                bestpts = zeros(1,CN);
                TMod = floor(TCnt(1)/10);
                %*******
                for k = 1:TCnt(1)
                    %**** for a given event, find the CN nearest cluster points
                    cpt = Tpca(k,:);  % 4D point
                    %**** limit search to points within 10% of time
                    tset = find( (cpts(:,4) >= (TTimes(k)-TimeLim)) & (cpts(:,4) <= (TTimes(k)+TimeLim) ) );
                    
                    if isempty(tset)
                        continue;   % no cluster points near this time in data
                    else
                        
                        %****** find the CN closest points to your event
                        bestdist(:) = Inf;
                        bestpts(:) = 0;
                        bestmax = Inf;
                        for kk = 1:size(tset,1)
                            dist = sum( (cpts(tset(kk),1:3) - cpt).^2 );
                            if (dist < bestmax)   % only compare if better than worst of CN dists
                                zz = find( bestdist == bestmax);
                                bestdist(zz(1)) = dist;
                                bestpts(zz(1)) = kk;
                                bestmax = max(bestdist);   % update bestmax
                            end
                        end
                        %***** what is the mean dist between close clust points
                        cdist = 0;
                        cnt = 0;
                        for kk = 1:CN
                            if (bestpts(kk))
                                for jj = (kk+1):CN
                                    if (bestpts(jj))
                                        cdist = cdist + sum( (cpts(tset(bestpts(kk))) - ...
                                            cpts(tset(bestpts(jj)))).^2 );
                                        cnt = cnt + 1;
                                    end
                                end
                            end
                        end
                        if (cnt == 0)
                            continue;
                        end
                        cdist = cdist/cnt;   % mean between points
                        %******* make a decision, is it within the cluster?
                        if (min(bestdist) < (DistThresh * cdist))
                            fset = [fset ; k];
                        end
                        %*************
                        if (mod(k,TMod) == 0)
                            val = (k/TCnt(1));
                            fprintf('Flagging events %d percent\n',floor(val*100));
                            hdlg.Value = (zk-0.5*(1-val))/app.UnitTot;
                        end
                        %************
                    end
                end
                fprintf('Flagged a total of %d events\n',size(fset,1));
                %********* store flagged times
                app.UnitInfo{zk}.FlagTimes = TTimes(fset);
                app.UnitInfo{zk}.AutoIso = 0;
                
                %******** plot your example waveform
                H = figure(zk); set(H,'Position',[500 0 1000 800]); 
                hold off;
                h = subplot('position',[0.1 0.7 0.2 0.2]); hold off;
                plot_my_waveforms(app,xt,mean(spks),std(spks),mchans,nchans,len,maxo,mino,colo,h);
                xlabel(h,'Time (ms)');
                ylabel(h,'Voltage (normed)');
                title(h,sprintf('%s: CH%d U%d',char(fileName),app.MAP,zk));
                axis tight;
                %********* compute and plot the ISI distribution
                h = subplot('position',[0.1 0.25 0.2 0.2]); hold off;
                plot_isi(app, (TTimes(fset) * (1000/app.Fs)), colo,false);
                title(h,sprintf('U%d,Iso(%d),Rate(%5.1f)',zk,app.UnitInfo{zk}.Isolation,...
                    (size(fset,1)/(TMax/app.Fs))));
                
                %*********** next step, plot clusters *********
                ShowFlag = 1;
                Tstd = prctile(Tpca,[0.1 99.9]);
                MaxPts = 500000;
                if (TCnt(1) > MaxPts)  % show a max of 5000 points (otherwise too dense)
                    rr = randperm(TCnt(1));
                    dset = rr(1:MaxPts);
                else
                    dset = 1:TCnt(1);
                end
                if (size(fset,1) > (MaxPts/4) )
                    rr = randperm(size(fset,1));
                    gset = fset(rr(1:floor(MaxPts/4)));
                else
                    gset = fset;
                end
                %******
                h = subplot('position',[0.4 0.7 0.2 0.2]); hold off;
                hh = plot(Tpca(dset,1),Tpca(dset,2),'k.'); hold on; set(hh,'Markersize',2);
                hh = plot(Tpca(gset,1),Tpca(gset,2),'k.'); hold on; set(hh,'Markersize',5);
                set(hh,'Color',colo);  % flagged points
                axis( ([Tstd(1,1) Tstd(2,1)  Tstd(1,2) Tstd(2,2)]) );
                xlabel('PCA 1'); ylabel('PCA 2');
                %*******
                h = subplot('position',[0.7 0.7 0.2 0.2]); hold off;
                hh = plot(Tpca(dset,1),TTimes(dset),'k.'); hold on; set(hh,'Markersize',2);
                hh = plot(Tpca(gset,1),TTimes(gset),'k.'); hold on; set(hh,'Markersize',5);
                set(hh,'Color',colo);  % flagged points
                axis tight; V = axis;
                axis( [Tstd(1,1) Tstd(2,1) V(3) V(4)]);
                xlabel('PCA 1'); ylabel('Time');
                %******
                h = subplot('position',[0.4 0.4 0.2 0.2]); hold off;
                hh = plot(Tpca(dset,1),Tpca(dset,3),'k.'); hold on; set(hh,'Markersize',2);
                hh = plot(Tpca(gset,1),Tpca(gset,3),'k.'); hold on; set(hh,'Markersize',5);
                set(hh,'Color',colo);  % flagged points
                axis( ([Tstd(1,1) Tstd(2,1)  Tstd(1,3) Tstd(2,3)]) );
                xlabel('PCA 1'); ylabel('PCA 3');
                %*******
                h = subplot('position',[0.7 0.4 0.2 0.2]); hold off;
                hh = plot(Tpca(dset,3),TTimes(dset),'k.'); hold on; set(hh,'Markersize',2);
                hh = plot(Tpca(gset,3),TTimes(gset),'k.'); hold on; set(hh,'Markersize',5);
                set(hh,'Color',colo);  % flagged points
                axis tight; V = axis;
                axis( [Tstd(1,3) Tstd(2,3) V(3) V(4)]);
                xlabel('PCA 3'); ylabel('Time');
                %******
                h = subplot('position',[0.4 0.1 0.2 0.2]); hold off;
                hh = plot(Tpca(dset,2),Tpca(dset,3),'k.'); hold on; set(hh,'Markersize',2);
                hh = plot(Tpca(gset,2),Tpca(gset,3),'k.'); hold on; set(hh,'Markersize',5);
                set(hh,'Color',colo);  % flagged points
                axis( ([Tstd(1,2) Tstd(2,2)  Tstd(1,3) Tstd(2,3)]) );
                xlabel('PCA 2'); ylabel('PCA 3');
                %*******
                h = subplot('position',[0.7 0.1 0.2 0.2]); hold off;
                hh = plot(Tpca(dset,2),TTimes(dset),'k.'); hold on; set(hh,'Markersize',2);
                hh = plot(Tpca(gset,2),TTimes(gset),'k.'); hold on; set(hh,'Markersize',5);
                set(hh,'Color',colo);  % flagged points
                axis tight; V = axis;
                axis( [Tstd(1,2) Tstd(2,2) V(3) V(4)]);
                xlabel('PCA 2'); ylabel('Time');
                %**************
                
                %******* save figure summary
                %figtag = [char(filename),sprintf('U%d.jpeg',zk)];
                figtag = sprintf('%s_%d_U%d.jpeg',char(fileName),app.MAP,zk);
                %figtag = [char(fileName),'_',num2str(app.MAP),'_spk.mat']
                %fname = fullfile(app.FilePath,figtag);
                
                fname = fullfile('\\cube.ucsd.edu\millerlab\Vlad_Analysis\SpikeHunters',figtag);
                z = getframe(H);  % current figure
                imwrite(z.cdata,fname); %,'Compression','none');
                %********
                hdlg.Value = zk/app.UnitTot;
                %*********************
            end
            app.lookForPeaks = oldLookForPeaks;
            close(hdlg);
            %******* save results to spk file
%             fname = fullfile(app.FilePath,sprintf('%s_%d_spk.mat',char(fileName),app.MAP));
            fname = fullfile('\\cube.ucsd.edu\millerlab\Vlad_Analysis\SpikeHunters',sprintf('%s_%d_spk.mat',char(fileName),app.MAP));
            sp = cell(1,app.UnitTot);
            for zk = 1:app.UnitTot
                spk = struct('ss', [], 'clu', [],'iso',[],'wave',[],'range',[]);
                spk.ss = app.UnitInfo{zk}.FlagTimes;
                spk.clu = zk;
                %***** store mean wave
                cset = app.UnitInfo{zk}.ClusterSet;
                spks = app.UnitInfo{zk}.ThreshSnips(cset,:);
                mwave = mean(spks);
                swave = std(spks);
                range = app.UnitInfo{zk}.SnipRange;
                %*********
                spk.iso = app.UnitInfo{zk}.Isolation;
                spk.wave = [mwave ; (mwave+swave); (mwave-swave)];
                spk.range = range;
                %********
                sp{zk} = spk;
            end
            save(fname,'sp');
            % Go and save the spike sorting stuff too
            SaveSort_Callback(app, [])
        end

        % Button pushed function: ResetPoints
        function ResetPoints_Callback(app, event)
            % hObject    handle to ResetPoints (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if isempty(app.ClusterSet)
                uialert(app.figure1,'message','No points to clear');
                return;
            else
                hdlg = uiprogressdlg(app.figure1,'message','Resetting Cluster Select','title','ResetPoints_Callback','Indeterminate','on');
                clear app.ClusterSet;
                app.ClusterSet = [];
                app.UnitColor = [1,0,0];
                app.UnitNum = 0;
                app.CurrentUnit.Value = num2str(app.UnitNum);
                %*******
                
                %************
                close(hdlg);
                plot_raw_spikes(app);
                plot_cluster(app);
                plot_isi(app,[],[],true);
                plot_snipets(app);
            end
        end

        % Button pushed function: ResetSpike
        function ResetSpike_Callback(app, event)
            % hObject    handle to ResetSpike (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            clear app.Snips;
            app.SnipCnt = 0;
            app.Snips = cell(1,1);
            app.SnipRange = [];
            app.Threshold = [];   % defined at the moment to spike sort
            clear app.ThreshSnips;
            clear app.ThreshUsed;
            app.ThreshSnips = []; % matrix of spike waves (across channels)
            app.ThreshUsed = [];
            app.ThreshCnt = [];   % number of threshold crossings
            clear app.ClusterPts;
            app.ClusterPts = [];  % trough time, points in a PCA space(pca 1+2)
            app.ClusterVec = [];  % prin comp vectors on which points are based
            app.ClusterSet = [];  % subset of selected points
            app.UnitColor = [1,0,0];
            app.UnitNum = 0;
            %*******
            
            app.CurrentUnit.Value = num2str(app.UnitNum);
            %********
            
            plot_raw_spikes(app);
            plot_waveforms(app);
            plot_average_waveforms(app);
            plot_cluster(app);
            plot_isi(app,[],[],true);
            plot_snipets(app);
        end

        % Button pushed function: SaveSort
        function SaveSort_Callback(app, event)
            % hObject    handle to SaveSort (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            hdlg = uiprogressdlg(app.figure1,'message','Saving sorting parameters ...','title','SaveSort_Callback','Indeterminate','on');
            info.FDataSample = app.FDataSample;
            info.DataSample = app.DataSample;
            info.FileName = app.FileName;
            info.FilePath = app.FilePath;
            info.CommonMode = app.CommonMode;  % use common mode rejection
            info.DM = app.DM;
            info.ChanNums = app.ChanNums;
            info.ChangeUnit = app.ChangeUnit;
            info.ColorCnt = app.ColorCnt;
            %*************
            info.TimeStart = app.TimeStart; % earliest time
            info.TimeScale = app.TimeScale; % length of horizontal on raw plot
            info.BigTimeScale = app.BigTimeScale; % length for large steps in time
            info.DataSegNum = app.DataSegNum; % chunks to look through, even spaced over file
            info.DataLen = app.DataLen;    % duration in secs
            info.Fs = app.Fs;      % data sampling rate
            info.NullN = app.NullN;
            %*****************
            info.SnipCnt = app.SnipCnt;        % user selected waveforms
            info.Snips = app.Snips;  % snipets
            info.SnipPre = app.SnipPre;  % ms before trough
            info.SnipPost = app.SnipPost; % ms after trough
            info.SnipLen = app.SnipLen;
            info.MAP = app.MAP;
            %****** all informaiton below here is needed to define a unit
            info.SnipMino = app.SnipMino;
            info.SnipMaxo = app.SnipMaxo;
            info.SpikeRange = app.SpikeRange;
            info.SnipRange = app.SnipRange;  % range of channels in current spike targets
            info.Threshold = app.Threshold;   % defined at the moment to spike sort
            info.ThreshBox = app.ThreshBox;
            info.ThreshSnips = app.ThreshSnips; % matrix of spike waves (across channels)
            info.ThreshUsed = app.ThreshUsed;
            info.ThreshNull = app.ThreshNull;
            info.NullTimes = app.NullTimes;
            info.ThreshTimes = app.ThreshTimes;
            info.ThreshCnt = app.ThreshCnt;   % number of threshold crossings
            info.ClusterPts = app.ClusterPts;  % points in a PCA space (pca 1, pca 2, time)
            info.ClusterVec = app.ClusterVec;
            info.ClusterSet = app.ClusterSet;
            info.ClusterState = app.ClusterState;
            info.UnitColor = app.UnitColor;
            info.Isolation = app.Isolation;
            info.AutoIso = app.AutoIso;
            info.FlagTimes = app.FlagTimes;
            %******* cell struct will store this unit information
            info.UnitNum = app.UnitNum;
            info.UnitTot = app.UnitTot;
            info.UnitInfo = app.UnitInfo;
            %*******************
            info.listOfFiles = app.listOfFiles; % Locations of where they are...
            info.numFilesCombined = app.numFilesCombined; % How many files were combined
            info.timeCutOffs = app.timeCutOffs; % Sample index of start of each session
            %******** store to file that can be reloaded
            fileName = inputdlg('Name of Sort/Cluster file','File name required');
            if strcmp(fileName,'')
                fileName = app.FileName;
            end
            %fname = fullfile(info.FilePath,[char(fileName),'_',num2str(app.MAP),'_Sort.mat']);
            fname = fullfile('\\cube.ucsd.edu\millerlab\Vlad_Analysis\SpikeHunters',[char(fileName),'_',num2str(app.MAP),'_Sort.mat']);
            save(fname,'info');
            clear info;
            disp('Parameters saved.');
            close(hdlg);
        end

        % Button pushed function: SelectPoints
        function SelectPoints_Callback(app, event)

            %axes(app.ClusterPlot);
            if isempty(app.ClusterPts)
                uialert(app.figure1,'message','Not spikes thresholded yet');
                return;
            else
%                 fig_handle_for_input = copyUIAxesToAxis(app,app.ClusterPlot);
%                 [xv,yv] = ginput(4);
%                 delete(fig_handle_for_input);
                setPointInput(app,app.ClusterPlot,'selectpoints',4);
%                 hdlg = uiprogressdlg(app.figure1,'message','Computing cluster points','title','SelectPoints_Callback','Indeterminate','off');
%                 xv = [xv ; xv(1)];
%                 yv = [yv ; yv(1)];
%                 ClusterPoly = [xv' ; yv'];
%                 [ix,iy] = axes_from_cluster_state(app);
%                 fxx = app.ClusterPts(1:app.ThreshCnt,ix);
%                 fyy = app.ClusterPts(1:app.ThreshCnt,iy);
%                 taggers = inpolygon(fxx,fyy,ClusterPoly(1,:)',ClusterPoly(2,:)');
%                 %***** force any used ones out of being tagged
%                 if ~isempty(app.ThreshUsed)
%                     taggers( app.ThreshUsed(:,1) ) = 0;
%                 end
%                 %*********
%                 if isempty(app.ClusterSet)
%                     app.ClusterSet = find( taggers == 1);
%                 else
%                     z = find(taggers == 1);
%                     upunit = 0;
%                     for zk = 1:size(z,1)
%                         k = z(zk);  % unit
%                         if ~ismember(k,app.ClusterSet)
%                             app.ClusterSet = [app.ClusterSet ; k];
%                             upunit = 1;
%                         end
%                     end
%                     if (upunit)
%                         if (app.UnitNum > 0)
%                             
%                             Store_Update_Unit(app);
%                         end
%                     end
%                 end
%                 %*********
%                 
%                 close(hdlg);
%                 plot_raw_spikes(app);
%                 plot_waveforms(app);
%                 plot_average_waveforms(app);
%                 plot_cluster(app);
%                 plot_isi(app,[],[]);
%                 plot_snipets(app);
            end
        end

        % Button pushed function: SelectSpike
        function SelectSpike_Callback(app, event)

            %axes(app.RawPlot);
%             fig_handle_for_input = copyUIAxesToAxis(app,app.RawPlot);
%             [xv,yv] = ginput(2);
%             delete(fig_handle_for_input);
            setPointInput(app, app.RawPlot,'selectspike', 2)
%             if (app.ThreshCnt > 0)
%                 yv = app.SnipRange;  % cannot change channels included if already thresholded
%             end
%             snip = grab_spike(app,xv,yv);
%             if ~isempty(snip)
%                 app.SnipCnt = app.SnipCnt + 1;
%                 app.Snips{app.SnipCnt} = snip;
%                 maxo = max(snip.chans);
%                 mino = min(snip.chans);
%                 if isempty(app.SnipRange)
%                     app.SnipRange = [mino,maxo];
%                 else
%                     if (maxo > app.SnipRange(2))
%                         app.SnipRange(2) = maxo;
%                     end
%                     if (mino < app.SnipRange(1))
%                         app.SnipRange(1) = mino;
%                     end
%                 end
%             end
%             
%             
%             plot_raw_spikes(app);
%             plot_waveforms(app);
%             plot_average_waveforms(app);
        end

        % Button pushed function: SpikeThreshold
        function SpikeThreshold_Callback(app, event)

      
            if (app.SnipCnt > 0)
                %axes(app.WavePlot);
                %fig_handle_for_input = copyUIAxesToAxis(app,app.WavePlot);
                %[xv,yv] = ginput(1);
                %delete(fig_handle_for_input);
                setPointInput(app,app.WavePlot,'spikethreshold',1);
%                 %******* go from plot coordinates to wave height in original voltage
%                 ominchan = app.SnipRange(1)-2;
%                 maxo = app.SnipMaxo;
%                 mino = app.SnipMino;
%                 app.Threshold = (yv(1)-ominchan)*((maxo-mino)/2);
%                 %****** sanity check the users range on threshold
%                 if (app.Threshold > ((mino-maxo)/8) )
%                     app.Threshold = ((mino-maxo)/8);
%                 end
%                 if (app.Threshold < (mino-maxo))
%                     app.Threshold = (mino-maxo);
%                 end
%                 %*********
%                 
%                 %****** compute threshold crossings and get snipets
%                 threshold_spikes(app);
%                 %********************
%                 
%                 plot_waveforms(app);
%                 plot_average_waveforms(app);
%                 plot_cluster(app);
%                 plot_snipets(app);
%                 %*********************
            else
                uialert(app.figure1,'message','Select Candidate Spike First ...');
            end
        end

        % Button pushed function: TemplateClear
        function TemplateClear_Callback(app, event)
            % hObject    handle to TemplateClear (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            app.ThreshBox = [];   % reset to no thresh box
            
            plot_waveforms(app);
            plot_average_waveforms(app);
        end

        % Button pushed function: TemplateThresh
        function TemplateThresh_Callback(app, event)
            
            % set a box as a second threshold criterium in threshold func
            if (app.SnipCnt > 0)
                %axes(app.WavePlot);
%                 fig_handle_for_input = copyUIAxesToAxis(app,app.WavePlot);
%                 [xv,yv] = ginput(2);
%                 delete(fig_handle_for_input);
                setPointInput(app,app.WavePlot,'templatethresh',2);
%                 %******* go from plot coordinates to wave height in original voltage
%                 ominchan = app.SnipRange(1)-2;
%                 maxo = app.SnipMaxo;
%                 mino = app.SnipMino;
%                 y1 = (yv(1)-ominchan)*((maxo-mino)/2);
%                 y2 = (yv(2)-ominchan)*((maxo-mino)/2);
%                 x1 = xv(1);
%                 x2 = xv(2);
%                 %**************
%                 if (y2 < y1)
%                     tmp = y2;
%                     y2 = y1;
%                     y1 = tmp;
%                 end
%                 if (x2 < x1)
%                     tmp = x2;
%                     x2 = x1;
%                     x1 = tmp;
%                 end
%                 %****** sanity check the users range on threshold
%                 if (y1 < (2*(mino-maxo))) || (y2 < (2*(mino-maxo)) ) || ...
%                         (y1 > (2*(maxo-mino))) || (y2 > (2*(maxo-mino)) )
%                     app.ThreshBox = [];   % reset to no thresh box
%                 else
%                     app.ThreshBox = [x1 x2 y1 y2];
%                 end
%                 
%                 %********************
%                 
%                 plot_waveforms(app);
%                 plot_average_waveforms(app);
%                 %*********************
            else
                uialert(app.figure1,'message','Select Candidate Spike First ...');
            end
        end

        % Value changed function: TimeEdit
        function TimeEdit_Callback(app, event)
            % hObject    handle to TimeEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of TimeEdit as text
            %        str2double(get(hObject,'String')) returns contents of TimeEdit as a double
            
            app.TimeScale = str2double(app.TimeEdit.Value);
            
            plot_raw_spikes(app);
        end

        % Button pushed function: UndoClear
        function UndoClear_Callback(app, event)
            % hObject    handle to UndoClear (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %************
            
            if ~isempty(app.ClearThreshSnips)
                hdlg = uiprogressdlg(app.figure1,'message','Undoing clear ...');
                app.ThreshSnips = app.ClearThreshSnips;
                app.ThreshNull = app.ClearThreshNull;
                app.ClusterPts = app.ClearClusterPts;
                %***** release stored memory
                clear app.ClearThreshSnips;
                clear app.ClearThreshNull;
                clear app.ClearClusterPts;
                clear app.ClearList;
                %*********
                app.ClearCnt = 0;
                app.ClearList = cell(1,1);
                app.ClearThreshSnips = [];
                app.ClearThreshNull = [];
                app.ClearClusterPts = [];
                close(hdlg);
            end
            
            %***********
            if ~isempty(app.ClusterPts)  % recompute clusters after removal
                plot_cluster(app);
            end
            plot_snipets(app);
            plot_isi(app,[],[],true);
        end

        % Button pushed function: VertZoomIn
        function VertZoomIn_Callback(app, event)
            % hObject    handle to VertZoomIn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if ~isempty(app.SpikeRange)
                app.SpikeRange = app.SpikeRange * 0.65;
            end
            
            plot_raw_spikes(app);
        end

        % Button pushed function: VertZoomOut
        function VertZoomOut_Callback(app, event)
            % hObject    handle to VertZoomOut (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if ~isempty(app.SpikeRange)
                app.SpikeRange = app.SpikeRange * 1.5;
            end
            
            plot_raw_spikes(app);
        end

        % Window button down function: figure1
        function figure1WindowButtonDown(app, event)
            % First see if something of interest was clicked
            if ~isempty(event.Source.CurrentObject)
                % Which object was touched (if it has a tag)
                tagOfObject = event.Source.CurrentObject.Tag;
                disp(tagOfObject)
                disp(app.whichInput)
                % Figure out which one was drawn
                if startsWith(tagOfObject,'clusterplot')
                    whichAxis = app.ClusterPlot;
                elseif startsWith(tagOfObject,'rawplot')
                    whichAxis = app.RawPlot;
                elseif startsWith(tagOfObject,'snipetplot')
                    whichAxis = app.SnipetPlot;
                elseif startsWith(tagOfObject,'waveplot')
                    whichAxis = app.WavePlot;
                else
                    return
                end
                if app.axis_to_listen ~= whichAxis
                    disp('Wrong axis')
                    return;
                end
                if app.getInput % Should be looking for input
                    app.ClicksMade.Text = sprintf('%i / %i',app.currentPoint,app.needPoints);
                    pointClicked = whichAxis.CurrentPoint; % What is that point
                    app.pointHolder(app.currentPoint,1) = pointClicked(1,1); % Store it
                    app.pointHolder(app.currentPoint,2) = pointClicked(1,2);
                    app.currentPoint = app.currentPoint + 1;
                    if app.currentPoint > app.needPoints % Have gotten all you need
                        xv = app.pointHolder(:,1);
                        yv = app.pointHolder(:,2);
                        switch app.whichInput
                            case 'dropspike'
                                drop_spike(app,xv,yv);
                                plot_raw_spikes(app);
                                plot_waveforms(app);
                                plot_average_waveforms(app);
                            case 'grabunit'
                                hdlg = uiprogressdlg(app.figure1,'message','Searching for unit number ...','title','GrabUnit_Callback');
                                %**** search all sorted units to identify which unit matches the spike
                                %**** time and channels selected (if more than one, take first found)
                                foundunit = 0;
                                for k = 1:app.UnitTot
                                    hdlg.Value = k/app.UnitTot;
                                    set = app.UnitInfo{k}.ClusterSet;
                                    times = app.UnitInfo{k}.ThreshTimes(set) * (1/app.Fs);  % times
                                    zz = find( (times >= xv(1)) & (times <= xv(end)) );
                                    if isempty(zz)
                                        continue;
                                    else
                                        chans = mean(app.UnitInfo{k}.SnipRange);
                                        if ( (chans >= min(yv)) && (chans <= max(yv)) )
                                            foundunit = k;
                                            break;
                                        else
                                            continue;
                                        end
                                    end
                                end
                                close(hdlg);
                                %******* once found, call CurrentUnit with arg to set to that unit
                                if (foundunit)
                                    app.ChangeUnit = num2str(foundunit);
                                    
                                    CurrentUnit_Callback(app,[])
                                end
                            case 'selectspike'
                                if (app.ThreshCnt > 0)
                                    yv = app.SnipRange;  % cannot change channels included if already thresholded
                                end
                                snip = grab_spike(app,xv,yv);
                                if ~isempty(snip)
                                    app.SnipCnt = app.SnipCnt + 1;
                                    app.Snips{app.SnipCnt} = snip;
                                    maxo = max(snip.chans);
                                    mino = min(snip.chans);
                                    if isempty(app.SnipRange)
                                        app.SnipRange = [mino,maxo];
                                    else
                                        if (maxo > app.SnipRange(2))
                                            app.SnipRange(2) = maxo;
                                        end
                                        if (mino < app.SnipRange(1))
                                            app.SnipRange(1) = mino;
                                        end
                                    end
                                end
                                plot_raw_spikes(app);
                                plot_waveforms(app);
                                plot_average_waveforms(app);
                            case 'spikethreshold'
                                %******* go from plot coordinates to wave height in original voltage
                                ominchan = app.SnipRange(1)-2;
                                maxo = app.SnipMaxo;
                                mino = app.SnipMino;
                                app.Threshold = (yv(1)-ominchan)*((maxo-mino)/2);
                                %****** sanity check the users range on threshold
%                                 if (app.Threshold > ((mino-maxo)/8) )
%                                     app.Threshold = ((mino-maxo)/8);
%                                 end
%                                 if (app.Threshold < (mino-maxo))
%                                     app.Threshold = (mino-maxo);
%                                 end
                                %*********
                                
                                %****** compute threshold crossings and get snipets
                                threshold_spikes(app);
                                %********************
                                
                                plot_waveforms(app);
                                plot_average_waveforms(app);
                                plot_cluster(app);
                                plot_snipets(app);
                                %*********************
                            case 'clearartifacts'
                                xv = [xv ; xv(1)];
                                yv = [yv ; yv(1)];
                                hdlg = uiprogressdlg(app.figure1,'message','Removing waveforms','Indeterminate','on');
                                %********* find any snipets that intersect the line
                                %********* and set them to zero
                                zN = app.SnipRange(2) - app.SnipRange(1) + 1;
                                xt = (-app.SnipPre:app.SnipPost) * (1000/app.Fs);
                                mino = app.SnipMino;
                                maxo = app.SnipMaxo;
                                %****** store cleared structs in case you need to undo
                                if isempty(app.ClusterSet)
                                    app.ClearThreshSnips = app.ThreshSnips;
                                    app.ClearThreshNull = app.ThreshNull;
                                    app.ClearClusterPts = app.ClusterPts;
                                end
                                %******
                                upunit = 0;
                                for k = 1:(size(app.ThreshSnips,1)+size(app.ThreshNull,1))
                                    if (k <= size(app.ThreshSnips,1))
                                        znip = reshape( app.ThreshSnips(k,:), [app.SnipLen, zN])';
                                    else
                                        kk = k - size(app.ThreshSnips,1);
                                        znip = reshape( app.ThreshNull(kk,:), [app.SnipLen, zN])';
                                    end
                                    ixt = [];
                                    iyt = [];
                                    for zk = 1:zN
                                        ixt = [ixt ; xt'];
                                        iyt = [iyt ; (zk+(znip(zk,:)/(2*(maxo-mino))))'];
                                    end
                                    tag = inpolygon(ixt,iyt,xv,yv);
                                    if (sum(tag) > 0)
                                        if isempty(app.ClusterSet)
                                            %********* zero out identified waveforms
                                            if (k <= size(app.ThreshSnips,1))
                                                app.ThreshSnips(k,:) = 0;
                                                it = app.ThreshTimes(k);
                                            else
                                                kk = k - size(app.ThreshSnips,1);
                                                app.ThreshNull(kk,:) = 0;
                                                it = app.NullTimes(kk);
                                            end
                                            if ~isempty(app.ClusterPts)
                                                app.ClusterPts(k,:) = 0; % set to origin
                                            end
                                            %**** eliminate them from raw data record too?
                                            igo = it - app.SnipPre;
                                            ifi = it + app.SnipPost;
                                            chans = app.SnipRange(1):app.SnipRange(2);
                                            app.ClearCnt = app.ClearCnt + 1;
                                            app.ClearList{app.ClearCnt}.chans = chans;
                                            app.ClearList{app.ClearCnt}.times = igo:ifi;
                                            %***************
                                        else
                                            if (k <= size(app.ThreshSnips,1))
                                                z = find(app.ClusterSet == k);
                                                if ~isempty(z)
                                                    zz = [1:(z(1)-1),(z(1)+1):size(app.ClusterSet,1)];
                                                    app.ClusterSet = app.ClusterSet(zz);
                                                    upunit = 1;
                                                end
                                            end
                                            
                                        end
                                    end
                                end
                                %************
                                if (upunit)
                                    if (app.UnitNum > 0)
                                        
                                        Store_Update_Unit(app);
                                    end
                                end
                                
                                close(hdlg);
                                %***********
                                if ~isempty(app.ClusterPts)  % recompute clusters after removal
                                    plot_cluster(app);
                                end
                                plot_snipets(app);
                                plot_isi(app,[],[],true);
                            case 'clearcluster'
                                xv = [xv ; xv(1)];
                                yv = [yv ; yv(1)];
                                hdlg = uiprogressdlg(app.figure1,'Message','Removing points and their waveforms','Indeterminate','on');
                                %********* find points in cluster plot to remove
                                [ix,iy] = axes_from_cluster_state(app);
                                fxx = app.ClusterPts(:,ix);
                                fyy = app.ClusterPts(:,iy);
                                taggers = inpolygon(fxx,fyy,xv,yv);
                                remset = find( taggers == 1);
                                %****** store cleared structs in case you need to undo
                                if isempty(app.ClusterSet)
                                    app.ClearThreshSnips = app.ThreshSnips;
                                    app.ClearThreshNull = app.ThreshNull;
                                    app.ClearClusterPts = app.ClusterPts;
                                    %******
                                    for zk = 1:size(remset,1)
                                        k = remset(zk);
                                        %********* zero out identified waveforms
                                        if (k <= size(app.ThreshSnips,1))
                                            app.ThreshSnips(k,:) = 0;
                                            it = app.ThreshTimes(k);
                                        else
                                            kk = k - size(app.ThreshSnips,1);
                                            app.ThreshNull(kk,:) = 0;
                                            it = app.NullTimes(kk);
                                        end
                                        app.ClusterPts(k,:) = 0; % set to origin
                                        %**** eliminate them from raw data record too?
                                        igo = it - app.SnipPre;
                                        ifi = it + app.SnipPost;
                                        chans = app.SnipRange(1):app.SnipRange(2);
                                        app.ClearCnt = app.ClearCnt + 1;
                                        app.ClearList{app.ClearCnt}.chans = chans;
                                        app.ClearList{app.ClearCnt}.times = igo:ifi;
                                        %***************
                                    end
                                else   % otherwise just remove from cluster set
                                    upunit = 0;
                                    for zk = 1:size(remset,1)
                                        z = find(app.ClusterSet == remset(zk));
                                        if ~isempty(z)
                                            zz = [1:(z(1)-1),(z(1)+1):size(app.ClusterSet,1)];
                                            app.ClusterSet = app.ClusterSet(zz);
                                            upunit = 1;
                                        end
                                    end
                                    if (upunit)
                                        if (app.UnitNum > 0)
                                            
                                            Store_Update_Unit(app);
                                        end
                                    end
                                end
                                %************
                                
                                close(hdlg);
                                %***********
                                if ~isempty(app.ClusterPts)  % reset clusters
                                    plot_cluster(app);
                                end
                                plot_snipets(app);
                                plot_isi(app,[],[],true);
                            case 'selectpoints'
                                hdlg = uiprogressdlg(app.figure1,'message','Computing cluster points','title','SelectPoints_Callback','Indeterminate','off');
                                xv = [xv ; xv(1)];
                                yv = [yv ; yv(1)];
                                ClusterPoly = [xv' ; yv'];
                                [ix,iy] = axes_from_cluster_state(app);
                                fxx = app.ClusterPts(1:app.ThreshCnt,ix);
                                fyy = app.ClusterPts(1:app.ThreshCnt,iy);
                                taggers = inpolygon(fxx,fyy,ClusterPoly(1,:)',ClusterPoly(2,:)');
                                %***** force any used ones out of being tagged
                                if ~isempty(app.ThreshUsed)
                                    taggers( app.ThreshUsed(:,1) ) = 0;
                                end
                                %*********
                                if isempty(app.ClusterSet)
                                    app.ClusterSet = find( taggers == 1);
                                else
                                    z = find(taggers == 1);
                                    upunit = 0;
                                    for zk = 1:size(z,1)
                                        k = z(zk);  % unit
                                        if ~ismember(k,app.ClusterSet)
                                            app.ClusterSet = [app.ClusterSet ; k];
                                            upunit = 1;
                                        end
                                    end
                                    if (upunit)
                                        if (app.UnitNum > 0)
                                            
                                            Store_Update_Unit(app);
                                        end
                                    end
                                end
                                %*********
                                
                                close(hdlg);
                                plot_raw_spikes(app);
                                plot_waveforms(app);
                                plot_average_waveforms(app);
                                plot_cluster(app);
                                plot_isi(app,[],[],true);
                                plot_snipets(app);
                            case 'templatethresh'
                                %******* go from plot coordinates to wave height in original voltage
                                ominchan = app.SnipRange(1)-2;
                                maxo = app.SnipMaxo;
                                mino = app.SnipMino;
                                y1 = (yv(1)-ominchan)*((maxo-mino)/2);
                                y2 = (yv(2)-ominchan)*((maxo-mino)/2);
                                x1 = xv(1);
                                x2 = xv(2);
                                %**************
                                if (y2 < y1)
                                    tmp = y2;
                                    y2 = y1;
                                    y1 = tmp;
                                end
                                if (x2 < x1)
                                    tmp = x2;
                                    x2 = x1;
                                    x1 = tmp;
                                end
                                %****** sanity check the users range on threshold
                                if (y1 < (2*(mino-maxo))) || (y2 < (2*(mino-maxo)) ) || ...
                                        (y1 > (2*(maxo-mino))) || (y2 > (2*(maxo-mino)) )
                                    app.ThreshBox = [];   % reset to no thresh box
                                else
                                    app.ThreshBox = [x1 x2 y1 y2];
                                end
                                
                                %********************
                                
                                plot_waveforms(app);
                                plot_average_waveforms(app);
                                %*********************
                        end
                        % Clear the waiting for inputs
                        app.getInput = false;
                        app.needPoints = 0;
                        app.currentPoint = 0;
                        app.axis_to_listen = '';
                        app.ClicksMade.Text = 'No more clicks needed';
                    end
                end
            end
        end

        % Selection changed function: ThresholdingModeButtonGroup
        function ThresholdingModeButtonGroupSelectionChanged(app, event)
            %selectedButton = app.ThresholdingModeButtonGroup.SelectedObject;
            switch event.NewValue.Text
                case 'Troughs'
                    app.lookForPeaks = false;
                case 'Peaks'
                    app.lookForPeaks = true;
            end
        end

        % Value changed function: CommonModeRejectionCheckBox
        function CommonModeRejectionCheckBoxValueChanged(app, event)
            app.CommonMode = app.CommonModeRejectionCheckBox.Value;
        end

        % Button pushed function: NewChannelButton
        function NewChannelButtonPushed(app, event)
        % Open up the same file and go through another unit, just select
        % new unit.
            %disp(mypathname)
            %disp(app.FilePath)
            %disp(app.FileName)
            %disp(pwd)
            
            app.Isolation = 0;
            app.AutoIso = 0;
            app.IsoText.Value = num2str(app.Isolation);
            
            %********* now try to load in that data
            hold(app.RawPlot,'off');
            plot(app.RawPlot,0,0,'k.','Tag','rawplotempty');
            text(app.RawPlot,0,0,'Loading raw data .....'); 
            axis(app.RawPlot,'off');
            %********* if older memory, clear it and reset
            if ~isempty(app.FDataSample)
                clear app.DataSample;
                clear app.FDataSample;
                clear app.MAP;
                tpath = app.FilePath;
                tname = app.FileName;
                MarmoSpike_Init(app);
                app.FilePath = tpath;
                app.FileName = tname;
            end
            
            %*********
            load_and_filter_data_sample(app);  % will load from directory
            
            plot_raw_spikes(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'SpikeHunter4';
            app.figure1.WindowButtonDownFcn = createCallbackFcn(app, @figure1WindowButtonDown, true);
            app.RawPlot = uiaxes(app.figure1);
            app.WavePlot = uiaxes(app.figure1);
            app.SnipetPlot = uiaxes(app.figure1);
            app.ClusterPlot = uiaxes(app.figure1);
            app.ISIPlot = uiaxes(app.figure1);
            app.AverageWave = uiaxes(app.figure1);
            app.BackWard = uibutton(app.figure1, 'push');
            app.BackWard.ButtonPushedFcn = createCallbackFcn(app, @BackWard_Callback, true);
            app.BackWard.Text = '<';
            app.Forward = uibutton(app.figure1, 'push');
            app.Forward.ButtonPushedFcn = createCallbackFcn(app, @Forward_Callback, true);
            app.Forward.Text = '>';
            app.SelectSpike = uibutton(app.figure1, 'push');
            app.SelectSpike.ButtonPushedFcn = createCallbackFcn(app, @SelectSpike_Callback, true);
            app.SelectSpike.Text = 'Select Spike';
            app.ClusterSpike = uibutton(app.figure1, 'push');
            app.ClusterSpike.ButtonPushedFcn = createCallbackFcn(app, @ClusterSpike_Callback, true);
            app.ClusterSpike.Text = 'PCA Waves';
            app.text3 = uilabel(app.figure1);
            app.text3.Text = 'TimeScale (secs)';
            app.TimeEdit = uieditfield(app.figure1, 'text');
            app.TimeEdit.ValueChangedFcn = createCallbackFcn(app, @TimeEdit_Callback, true);
            app.TimeEdit.Value = '0.1';
            app.ResetSpike = uibutton(app.figure1, 'push');
            app.ResetSpike.ButtonPushedFcn = createCallbackFcn(app, @ResetSpike_Callback, true);
            app.ResetSpike.Text = 'Reset Spike';
            app.BigBackWard = uibutton(app.figure1, 'push');
            app.BigBackWard.ButtonPushedFcn = createCallbackFcn(app, @BigBackWard_Callback, true);
            app.BigBackWard.Text = '<<';
            app.BigForward = uibutton(app.figure1, 'push');
            app.BigForward.ButtonPushedFcn = createCallbackFcn(app, @BigForward_Callback, true);
            app.BigForward.Text = '>>';
            app.DropSpike = uibutton(app.figure1, 'push');
            app.DropSpike.ButtonPushedFcn = createCallbackFcn(app, @DropSpike_Callback, true);
            app.DropSpike.Text = 'Drop Spike';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'File Control';
            app.SaveSort = uibutton(app.uipanel3, 'push');
            app.SaveSort.ButtonPushedFcn = createCallbackFcn(app, @SaveSort_Callback, true);
            app.SaveSort.Text = 'Save';
            app.LoadSort = uibutton(app.uipanel3, 'push');
            app.LoadSort.ButtonPushedFcn = createCallbackFcn(app, @LoadSort_Callback, true);
            app.LoadSort.Text = 'Load';
            app.NewSort = uibutton(app.uipanel3, 'push');
            app.NewSort.ButtonPushedFcn = createCallbackFcn(app, @NewSort_Callback, true);
            app.NewSort.Text = 'New File';
            app.filelist = uilabel(app.uipanel3);
            app.filelist.Text = 'Raw File Name';
            app.CHLabel = uilabel(app.uipanel3);
            app.CHLabel.Text = 'CH: ';
            app.SpikeThreshold = uibutton(app.figure1, 'push');
            app.SpikeThreshold.ButtonPushedFcn = createCallbackFcn(app, @SpikeThreshold_Callback, true);
            app.SpikeThreshold.Text = 'Threshold';
            app.SelectPoints = uibutton(app.figure1, 'push');
            app.SelectPoints.ButtonPushedFcn = createCallbackFcn(app, @SelectPoints_Callback, true);
            app.SelectPoints.Text = 'Select Points';
            app.AddUnit = uibutton(app.figure1, 'push');
            app.AddUnit.ButtonPushedFcn = createCallbackFcn(app, @AddUnit_Callback, true);
            app.AddUnit.Text = 'Add Unit';
            app.ClusterAxes = uibuttongroup(app.figure1);
            app.ClusterAxes.SelectionChangedFcn = createCallbackFcn(app, @ClusterAxes_SelectionChangedFcn, true);
            app.ClusterAxes.Title = 'Cluster Axes';
            app.PCA_1_2 = uiradiobutton(app.ClusterAxes);
            app.PCA_1_2.Text = 'PCA1,2';
            app.PCA_1_2.Value = true;
            app.PCA_1_3 = uiradiobutton(app.ClusterAxes);
            app.PCA_1_3.Text = 'PCA1,3';
            app.PCA_2_3 = uiradiobutton(app.ClusterAxes);
            app.PCA_2_3.Text = 'PCA2,3';
            app.PCA_1_Time = uiradiobutton(app.ClusterAxes);
            app.PCA_1_Time.Text = 'PCA1,Time';
            app.PCA_2_Time = uiradiobutton(app.ClusterAxes);
            app.PCA_2_Time.Text = 'PCA2,Time';
            app.PCA_3_Time = uiradiobutton(app.ClusterAxes);
            app.PCA_3_Time.Text = 'PCA3,Time';
            app.ResetPoints = uibutton(app.figure1, 'push');
            app.ResetPoints.ButtonPushedFcn = createCallbackFcn(app, @ResetPoints_Callback, true);
            app.ResetPoints.Text = 'Reset Cluster';
            app.DropUnit = uibutton(app.figure1, 'push');
            app.DropUnit.ButtonPushedFcn = createCallbackFcn(app, @DropUnit_Callback, true);
            app.DropUnit.Text = 'Drop Unit';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'Current of Total Units';
            app.CurrentUnit = uieditfield(app.uipanel4, 'text');
            app.CurrentUnit.ValueChangedFcn = createCallbackFcn(app, @CurrentUnit_Callback, true);
            app.CurrentUnit.Value = '0';
            app.TotUnit = uilabel(app.uipanel4);
            app.TotUnit.Text = '0';
            app.text13 = uilabel(app.uipanel4);
            app.text13.Text = 'Isolation (0-5):';
            app.IsoText = uieditfield(app.uipanel4, 'text');
            app.IsoText.ValueChangedFcn = createCallbackFcn(app, @IsoText_Callback, true);
            app.IsoText.Value = '0';
            app.ProcessUnits = uibutton(app.figure1, 'push');
            app.ProcessUnits.ButtonPushedFcn = createCallbackFcn(app, @ProcessUnits_Callback, true);
            app.ProcessUnits.Text = 'Process Units';
            app.GrabUnit = uibutton(app.figure1, 'push');
            app.GrabUnit.ButtonPushedFcn = createCallbackFcn(app, @GrabUnit_Callback, true);
            app.GrabUnit.Text = 'Grab Unit';
            app.ClearArtifacts = uibutton(app.figure1, 'push');
            app.ClearArtifacts.ButtonPushedFcn = createCallbackFcn(app, @ClearArtifacts_Callback, true);
            app.ClearArtifacts.Text = 'Clear Waves';
            app.ClearCluster = uibutton(app.figure1, 'push');
            app.ClearCluster.ButtonPushedFcn = createCallbackFcn(app, @ClearCluster_Callback, true);
            app.ClearCluster.Text = 'Clear Points';
            app.text12 = uilabel(app.figure1);
            app.text12.Text = 'Zoom';
            app.VertZoomIn = uibutton(app.figure1, 'push');
            app.VertZoomIn.ButtonPushedFcn = createCallbackFcn(app, @VertZoomIn_Callback, true);
            app.VertZoomIn.Text = '+';
            app.VertZoomOut = uibutton(app.figure1, 'push');
            app.VertZoomOut.ButtonPushedFcn = createCallbackFcn(app, @VertZoomOut_Callback, true);
            app.VertZoomOut.Text = '-';
            app.ClusterTemplate = uibutton(app.figure1, 'push');
            app.ClusterTemplate.ButtonPushedFcn = createCallbackFcn(app, @ClusterTemplate_Callback, true);
            app.ClusterTemplate.Text = 'PCA Templ.';
            app.UndoClear = uibutton(app.figure1, 'push');
            app.UndoClear.ButtonPushedFcn = createCallbackFcn(app, @UndoClear_Callback, true);
            app.UndoClear.Text = 'Undo Clear';
            app.FinalizeClear = uibutton(app.figure1, 'push');
            app.FinalizeClear.ButtonPushedFcn = createCallbackFcn(app, @FinalizeClear_Callback, true);
            app.FinalizeClear.Text = 'Finalize ';
            app.TemplateThresh = uibutton(app.figure1, 'push');
            app.TemplateThresh.ButtonPushedFcn = createCallbackFcn(app, @TemplateThresh_Callback, true);
            app.TemplateThresh.Text = 'Box';
            app.TemplateClear = uibutton(app.figure1, 'push');
            app.TemplateClear.ButtonPushedFcn = createCallbackFcn(app, @TemplateClear_Callback, true);
            app.TemplateClear.Text = 'Box Clear';
            app.MergeUnit = uibutton(app.figure1, 'push');
            app.MergeUnit.ButtonPushedFcn = createCallbackFcn(app, @MergeUnit_Callback, true);
            app.MergeUnit.Text = 'Merge Unit';
            app.MergeUnitNum = uieditfield(app.figure1, 'text');
            app.MergeUnitNum.ValueChangedFcn = createCallbackFcn(app, @MergeUnitNum_Callback, true);
            app.MergeUnitNum.Value = '0';
            app.UnitStats = uibutton(app.figure1, 'push');
            app.UnitStats.Text = 'Plot Unit Stats';
            app.MergeAccept = uibutton(app.figure1, 'push');
            app.MergeAccept.ButtonPushedFcn = createCallbackFcn(app, @MergeAccept_Callback, true);
            app.MergeAccept.Text = 'Merge Accept';
            app.InputCheckerPanel = uipanel(app.figure1);
            app.InputCheckerPanel.Title = 'Input Tracker';
            app.ButtonPressed = uilabel(app.InputCheckerPanel);
            app.ButtonPressed.Text = 'ButtonPressed';
            app.ClicksMade = uilabel(app.InputCheckerPanel);
            app.ClicksMade.Text = '0';
            app.ClicksMadeLabel = uilabel(app.InputCheckerPanel);
            app.ClicksMadeLabel.Text = 'Clicks Made: ';
            app.ThresholdingModeButtonGroup = uibuttongroup(app.figure1);
            app.ThresholdingModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ThresholdingModeButtonGroupSelectionChanged, true);
            app.ThresholdingModeButtonGroup.Title = 'Thresholding Mode';
            app.TroughsButton = uiradiobutton(app.ThresholdingModeButtonGroup);
            app.TroughsButton.Text = 'Troughs';
            app.TroughsButton.Value = true;
            app.PeaksButton = uiradiobutton(app.ThresholdingModeButtonGroup);
            app.PeaksButton.Text = 'Peaks';
            app.CommonModeRejectionCheckBox = uicheckbox(app.figure1);
            app.CommonModeRejectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @CommonModeRejectionCheckBoxValueChanged, true);
            app.CommonModeRejectionCheckBox.Text = 'Common Mode Rejection';
            app.CommonModeRejectionCheckBox.Value = true;
            app.NewChannelButton = uibutton(app.figure1, 'push');
            app.NewChannelButton.ButtonPushedFcn = createCallbackFcn(app, @NewChannelButtonPushed, true);
            app.NewChannelButton.Text = 'New Channel';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpikeHunter4_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end